classdef TaskBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphicalTaskBuilderUIFigure    matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        WarningsPanel                   matlab.ui.container.Panel
        OverwritingTaskWarningLamp      matlab.ui.control.Lamp
        GridsizewarningLabel_2          matlab.ui.control.Label
        GridSizeWarningLamp             matlab.ui.control.Lamp
        GridsizewarningLabel            matlab.ui.control.Label
        ModelPanel                      matlab.ui.container.Panel
        InitialEquilibriumEnabledLamp   matlab.ui.control.Lamp
        GridsizewarningLabel_8          matlab.ui.control.Label
        EOSEnabledLamp                  matlab.ui.control.Lamp
        GridsizewarningLabel_7          matlab.ui.control.Label
        GravityEnabledLamp              matlab.ui.control.Lamp
        GridsizewarningLabel_6          matlab.ui.control.Label
        BCsEnabledLamp                  matlab.ui.control.Lamp
        GridsizewarningLabel_5          matlab.ui.control.Label
        LayerEnabledLamp                matlab.ui.control.Lamp
        GridsizewarningLabel_4          matlab.ui.control.Label
        TotelCellsEditField             matlab.ui.control.NumericEditField
        TotalcellsLabel                 matlab.ui.control.Label
        PreviewCheckBox                 matlab.ui.control.CheckBox
        ModelPreviewAxes                matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        InfoLabel                       matlab.ui.control.Label
        QuitButton                      matlab.ui.control.Button
        SaveandExitButton               matlab.ui.control.Button
        GlobalTabGroup                  matlab.ui.container.TabGroup
        GenericTab                      matlab.ui.container.Tab
        TaskKeepExistingCheckBox        matlab.ui.control.CheckBox
        TaskNotesTextArea               matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        TaskCreatedEditField            matlab.ui.control.EditField
        CreatedEditFieldLabel           matlab.ui.control.Label
        TaskNameEditField               matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        DomainTab                       matlab.ui.container.Tab
        GlobalcellsPanel                matlab.ui.container.Panel
        xLabel_3                        matlab.ui.control.Label
        GridNzEditField                 matlab.ui.control.NumericEditField
        xLabel_2                        matlab.ui.control.Label
        GridNyEditField                 matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        GridNxEditField                 matlab.ui.control.NumericEditField
        GridLayerPanel                  matlab.ui.container.Panel
        GridLayerPropertiesPanel        matlab.ui.container.Panel
        xLabel_17                       matlab.ui.control.Label
        xLabel_16                       matlab.ui.control.Label
        GridLayerThicknessEditField     matlab.ui.control.NumericEditField
        GridLayerPlanePointxEditField   matlab.ui.control.NumericEditField
        GridLayerPlanePointyEditField   matlab.ui.control.NumericEditField
        GridLayerPlanePointzEditField   matlab.ui.control.NumericEditField
        xLabel_15                       matlab.ui.control.Label
        xLabel_14                       matlab.ui.control.Label
        GridLayerRollEditField          matlab.ui.control.NumericEditField
        GridLayerPitchEditField         matlab.ui.control.NumericEditField
        GridLayerYawEditField           matlab.ui.control.NumericEditField
        PlanePointLabel                 matlab.ui.control.Label
        xLabel_13                       matlab.ui.control.Label
        xLabel_11                       matlab.ui.control.Label
        xLabel_12                       matlab.ui.control.Label
        xLabel_10                       matlab.ui.control.Label
        xLabel_9                        matlab.ui.control.Label
        xLabel_8                        matlab.ui.control.Label
        GridLayerActiveCheckBox         matlab.ui.control.CheckBox
        GlobalGridSizePanel             matlab.ui.container.Panel
        GridDepthEditField              matlab.ui.control.NumericEditField
        xLabel_7                        matlab.ui.control.Label
        GridSizezEditField              matlab.ui.control.NumericEditField
        xLabel_6                        matlab.ui.control.Label
        GridSizeyEditField              matlab.ui.control.NumericEditField
        xLabel_5                        matlab.ui.control.Label
        GridSizexEditField              matlab.ui.control.NumericEditField
        xLabel_4                        matlab.ui.control.Label
        GridDepthUnitDropDown           matlab.ui.control.DropDown
        GridSizeyUnitDropDown           matlab.ui.control.DropDown
        GridSizexUnitDropDown           matlab.ui.control.DropDown
        GridSizezUnitDropDown           matlab.ui.control.DropDown
        RockTab                         matlab.ui.container.Tab
        TotalPVEditField                matlab.ui.control.EditField
        PoreVolumeEditFieldLabel        matlab.ui.control.Label
        TotalPVUnitLabel                matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        PorosityTab                     matlab.ui.container.Tab
        GlobalPorosityTabGroup          matlab.ui.container.TabGroup
        GlobalPorosityTab               matlab.ui.container.Tab
        SingleCalculationCheckBox       matlab.ui.control.CheckBox
        GlobalPorosityGaussianStdEditField  matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        GlobalPorosityGaussianMinEditField  matlab.ui.control.NumericEditField
        PorosityminLabel                matlab.ui.control.Label
        GlobalPorosityGaussianMaxEditField  matlab.ui.control.NumericEditField
        PorositymaxLabel_2              matlab.ui.control.Label
        GlobalPorosityEditField         matlab.ui.control.NumericEditField
        PorosityLabel                   matlab.ui.control.Label
        GlobalPorosityButtonGroup       matlab.ui.container.ButtonGroup
        GlobalPorosityGaussianButton    matlab.ui.control.RadioButton
        GlobalPorosityUniformButton     matlab.ui.control.RadioButton
        LayerPorosityTab                matlab.ui.container.Tab
        SingleCalculationCheckBox_6     matlab.ui.control.CheckBox
        LayerPorosityGaussianStdEditField  matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel_2  matlab.ui.control.Label
        LayerPorosityGaussianMinEditField  matlab.ui.control.NumericEditField
        PorsityminLabel                 matlab.ui.control.Label
        LayerPorosityGaussianMaxEditField  matlab.ui.control.NumericEditField
        PorositymaxLabel                matlab.ui.control.Label
        LayerPorosityEditField          matlab.ui.control.NumericEditField
        phiEditField_2Label_2           matlab.ui.control.Label
        LayerPorosityButtonGroup        matlab.ui.container.ButtonGroup
        LayerPorosityGaussianButton     matlab.ui.control.RadioButton
        LayerPorosityUniformButton      matlab.ui.control.RadioButton
        PermeabilityTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        GlobalPermeabilityTab           matlab.ui.container.Tab
        GlobalPermDiryEditField         matlab.ui.control.NumericEditField
        phiEditField_2Label_42          matlab.ui.control.Label
        GlobalPermDiryUnitDropDown      matlab.ui.control.DropDown
        GlobalPermGaussianSingleCalculationCheckBox  matlab.ui.control.CheckBox
        GlobalPermDirzUnitDropDown      matlab.ui.control.DropDown
        GlobalPermDirxUnitDropDown      matlab.ui.control.DropDown
        GlobalPermGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        GlobalPermGaussianMinUnitDropDown  matlab.ui.control.DropDown
        GlobalPermUnitDropDown          matlab.ui.control.DropDown
        GlobalPermDirzEditField         matlab.ui.control.NumericEditField
        phiEditField_2Label_8           matlab.ui.control.Label
        GlobalPermDirxEditField         matlab.ui.control.NumericEditField
        phiEditField_2Label_7           matlab.ui.control.Label
        GlobalPermGaussianStdEditField  matlab.ui.control.NumericEditField
        phiEditField_2Label_6           matlab.ui.control.Label
        GlobalPermGaussianMaxEditField  matlab.ui.control.NumericEditField
        phiEditField_2Label_5           matlab.ui.control.Label
        GlobalPermGaussianMinEditField  matlab.ui.control.NumericEditField
        phiEditField_2Label_4           matlab.ui.control.Label
        GlobalPermEditField             matlab.ui.control.NumericEditField
        phiEditField_2Label_3           matlab.ui.control.Label
        GlobalPermButtonGroup           matlab.ui.container.ButtonGroup
        GlobalPermDirectionalButton     matlab.ui.control.RadioButton
        GlobalPermGaussianButton        matlab.ui.control.RadioButton
        GlobalPermUniformButton         matlab.ui.control.RadioButton
        LayerPermeabilityTab            matlab.ui.container.Tab
        LayerPermDiryEditField          matlab.ui.control.NumericEditField
        phiEditField_2Label_43          matlab.ui.control.Label
        LayerPermDiryUnitDropDown       matlab.ui.control.DropDown
        LayerPermGaussianSingleCalculationCheckBox  matlab.ui.control.CheckBox
        LayerPermDirzUnitDropDown       matlab.ui.control.DropDown
        LayerPermDirxUnitDropDown       matlab.ui.control.DropDown
        LayerPermGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        LayerPermGaussianMinUnitDropDown  matlab.ui.control.DropDown
        LayerPermUnitDropDown           matlab.ui.control.DropDown
        LayerPermDirzEditField          matlab.ui.control.NumericEditField
        phiEditField_2Label_36          matlab.ui.control.Label
        LayerPermDirxEditField          matlab.ui.control.NumericEditField
        phiEditField_2Label_35          matlab.ui.control.Label
        LayerPermGaussianStdEditField   matlab.ui.control.NumericEditField
        phiEditField_2Label_34          matlab.ui.control.Label
        LayerPermGaussianMaxEditField   matlab.ui.control.NumericEditField
        phiEditField_2Label_33          matlab.ui.control.Label
        LayerPermGaussianMinEditField   matlab.ui.control.NumericEditField
        phiEditField_2Label_32          matlab.ui.control.Label
        LayerPermEditField              matlab.ui.control.NumericEditField
        phiEditField_2Label_31          matlab.ui.control.Label
        LayerPermButtonGroup            matlab.ui.container.ButtonGroup
        LayerPermDirectionalButton      matlab.ui.control.RadioButton
        LayerPermGaussianButton         matlab.ui.control.RadioButton
        LayerPermUniformButton          matlab.ui.control.RadioButton
        DensityTab                      matlab.ui.container.Tab
        RockDensityTabGroup             matlab.ui.container.TabGroup
        GlobalDensityTab                matlab.ui.container.Tab
        SingleCalculationCheckBox_2     matlab.ui.control.CheckBox
        GlobalDensityGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        GlobalDensityGaussianMinUnitDropDown  matlab.ui.control.DropDown
        GlobalDensityUnitDropDown       matlab.ui.control.DropDown
        GlobalDensityGaussianStdEditField  matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel_3  matlab.ui.control.Label
        GlobalDensityGaussianMinEditField  matlab.ui.control.NumericEditField
        PorosityminLabel_2              matlab.ui.control.Label
        GlobalDensityGaussianMaxEditField  matlab.ui.control.NumericEditField
        PorositymaxLabel_3              matlab.ui.control.Label
        GlobalDensityEditField          matlab.ui.control.NumericEditField
        PorosityLabel_2                 matlab.ui.control.Label
        GlobalDensityButtonGroup        matlab.ui.container.ButtonGroup
        GlobalDensityGaussianButton     matlab.ui.control.RadioButton
        GlobalDensityUniformButton      matlab.ui.control.RadioButton
        LayerDensityTab                 matlab.ui.container.Tab
        SingleCalculationCheckBox_10    matlab.ui.control.CheckBox
        LayerDensityGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        LayerDensityGaussianMinUnitDropDown  matlab.ui.control.DropDown
        LayerDensityUnitDropDown        matlab.ui.control.DropDown
        LayerDensityGaussianStdEditField  matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel_4  matlab.ui.control.Label
        LayerDensityGaussianMinEditField  matlab.ui.control.NumericEditField
        PorsityminLabel_2               matlab.ui.control.Label
        LayerDensityGaussianMaxEditField  matlab.ui.control.NumericEditField
        PorositymaxLabel_4              matlab.ui.control.Label
        LayerDensityEditField           matlab.ui.control.NumericEditField
        phiEditField_2Label_39          matlab.ui.control.Label
        LayerDensityButtonGroup         matlab.ui.container.ButtonGroup
        LayerDensityGaussianButton      matlab.ui.control.RadioButton
        LayerDensityUniformButton       matlab.ui.control.RadioButton
        ThermalConductivityTab          matlab.ui.container.Tab
        RockCondTabGroup                matlab.ui.container.TabGroup
        GlobalCondTab                   matlab.ui.container.Tab
        SingleCalculationCheckBox_3     matlab.ui.control.CheckBox
        GlobalCondGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        GlobalCondGaussianMinUnitDropDown  matlab.ui.control.DropDown
        GlobalCondUnitDropDown          matlab.ui.control.DropDown
        GlobalCondGaussianStdEditField  matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel_5  matlab.ui.control.Label
        GlobalCondGaussianMinEditField  matlab.ui.control.NumericEditField
        PorosityminLabel_3              matlab.ui.control.Label
        GlobalCondGaussianMaxEditField  matlab.ui.control.NumericEditField
        PorositymaxLabel_5              matlab.ui.control.Label
        GlobalCondEditField             matlab.ui.control.NumericEditField
        PorosityLabel_3                 matlab.ui.control.Label
        GlobalCondButtonGroup           matlab.ui.container.ButtonGroup
        GlobalCondGaussianButton        matlab.ui.control.RadioButton
        GlobalCondUniformButton         matlab.ui.control.RadioButton
        LayerCondTab                    matlab.ui.container.Tab
        SingleCalculationCheckBox_9     matlab.ui.control.CheckBox
        LayerCondGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        LayerCondGaussianMinUnitDropDown  matlab.ui.control.DropDown
        LayerCondUnitDropDown           matlab.ui.control.DropDown
        LayerCondGaussianStdEditField   matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel_6  matlab.ui.control.Label
        LayerCondGaussianMinEditField   matlab.ui.control.NumericEditField
        PorsityminLabel_3               matlab.ui.control.Label
        LayerCondGaussianMaxEditField   matlab.ui.control.NumericEditField
        PorositymaxLabel_6              matlab.ui.control.Label
        LayerCondEditField              matlab.ui.control.NumericEditField
        phiEditField_2Label_40          matlab.ui.control.Label
        LayerCondButtonGroup            matlab.ui.container.ButtonGroup
        LayerCondGaussianButton         matlab.ui.control.RadioButton
        LayerCondUniformButton          matlab.ui.control.RadioButton
        SpecificHeatCapacityTab         matlab.ui.container.Tab
        RockHeatTabGroup                matlab.ui.container.TabGroup
        GlobalHeatTab                   matlab.ui.container.Tab
        SingleCalculationCheckBox_4     matlab.ui.control.CheckBox
        GlobalHeatGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        GlobalHeatGaussianMinUnitDropDown  matlab.ui.control.DropDown
        GlobalHeatUnitDropDown          matlab.ui.control.DropDown
        GlobalHeatGaussianStdEditField  matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel_7  matlab.ui.control.Label
        GlobalHeatGaussianMinEditField  matlab.ui.control.NumericEditField
        PorosityminLabel_4              matlab.ui.control.Label
        GlobalHeatGaussianMaxEditField  matlab.ui.control.NumericEditField
        PorositymaxLabel_7              matlab.ui.control.Label
        GlobalHeatEditField             matlab.ui.control.NumericEditField
        PorosityLabel_4                 matlab.ui.control.Label
        GlobalHeatButtonGroup           matlab.ui.container.ButtonGroup
        GlobalHeatGaussianButton        matlab.ui.control.RadioButton
        GlobalHeatUniformButton         matlab.ui.control.RadioButton
        LayerHeatTab                    matlab.ui.container.Tab
        SingleCalculationCheckBox_8     matlab.ui.control.CheckBox
        LayerHeatGaussianMaxUnitDropDown  matlab.ui.control.DropDown
        LayerHeatGaussianMinUnitDropDown  matlab.ui.control.DropDown
        LayerHeatUnitDropDown           matlab.ui.control.DropDown
        LayerHeatGaussianStdEditField   matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel_8  matlab.ui.control.Label
        LayerHeatGaussianMinEditField   matlab.ui.control.NumericEditField
        PorsityminLabel_4               matlab.ui.control.Label
        LayerHeatGaussianMaxEditField   matlab.ui.control.NumericEditField
        PorositymaxLabel_8              matlab.ui.control.Label
        LayerHeatEditField              matlab.ui.control.NumericEditField
        phiEditField_2Label_41          matlab.ui.control.Label
        LayerHeatButtonGroup            matlab.ui.container.ButtonGroup
        LayerHeatGaussianButton         matlab.ui.control.RadioButton
        LayerHeatUniformButton          matlab.ui.control.RadioButton
        VisualizationPanel              matlab.ui.container.Panel
        VisualizeLayerOnlyCheckBox      matlab.ui.control.CheckBox
        RockVisualizationButtonGroup    matlab.ui.container.ButtonGroup
        RockVisualizationHeatButton     matlab.ui.control.RadioButton
        RockVisualizationCondButton     matlab.ui.control.RadioButton
        RockVisualizationDensityButton  matlab.ui.control.RadioButton
        RockVisualizationPermButton     matlab.ui.control.RadioButton
        RockVisualizationPorosityButton  matlab.ui.control.RadioButton
        FluidTab                        matlab.ui.container.Tab
        GeneralPropertiesPanel          matlab.ui.container.Panel
        FluidEOSCheckBox                matlab.ui.control.CheckBox
        FluidViscosityEditField         matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel         matlab.ui.control.Label
        FluidDensityEditField           matlab.ui.control.NumericEditField
        DensityEditFieldLabel           matlab.ui.control.Label
        FluidViscosityUnitDropDown      matlab.ui.control.DropDown
        FluidDensityUnitDropDown        matlab.ui.control.DropDown
        ThermalPropertiesPanel          matlab.ui.container.Panel
        FluidCondEditField              matlab.ui.control.NumericEditField
        ThermalconductivityEditFieldLabel  matlab.ui.control.Label
        FluidHeatEditField              matlab.ui.control.NumericEditField
        HeatcapacityEditFieldLabel      matlab.ui.control.Label
        FluidCondUnitDropDown           matlab.ui.control.DropDown
        FluidHeatUnitDropDown           matlab.ui.control.DropDown
        TypePanel                       matlab.ui.container.Panel
        FluidTypeButtonGroup            matlab.ui.container.ButtonGroup
        FluidTypeCO2Button              matlab.ui.control.RadioButton
        FluidTypeBrineButton            matlab.ui.control.RadioButton
        FluidTypeWaterButton            matlab.ui.control.RadioButton
        GravityPanel                    matlab.ui.container.Panel
        FluidGravityzUnitDropDown       matlab.ui.control.DropDown
        FluidGravityyUnitDropDown       matlab.ui.control.DropDown
        FluidGravityxUnitDropDown       matlab.ui.control.DropDown
        FluidGravityCheckBox            matlab.ui.control.CheckBox
        xLabel_26                       matlab.ui.control.Label
        FluidGravityzEditField          matlab.ui.control.NumericEditField
        xLabel_25                       matlab.ui.control.Label
        FluidGravityyEditField          matlab.ui.control.NumericEditField
        xLabel_24                       matlab.ui.control.Label
        FluidGravityxEditField          matlab.ui.control.NumericEditField
        BCsTab                          matlab.ui.container.Tab
        BCThermalBCPanel                matlab.ui.container.Panel
        BCThermalButtonGroup            matlab.ui.container.ButtonGroup
        BCThermalHeatFluxButton         matlab.ui.control.RadioButton
        BCThermalTempButton             matlab.ui.control.RadioButton
        BCzMaxHeatFluxLabel             matlab.ui.control.Label
        BCzMaxTempLabel                 matlab.ui.control.Label
        BCzMinHeatFluxLabel             matlab.ui.control.Label
        BCzMinTempLabel                 matlab.ui.control.Label
        BCyMaxHeatFluxLabel             matlab.ui.control.Label
        BCyMaxTempLabel                 matlab.ui.control.Label
        BCyMinHeatFluxLabel             matlab.ui.control.Label
        BCyMinTempLabel                 matlab.ui.control.Label
        BCxMaxHeatFluxLabel             matlab.ui.control.Label
        BCxMaxTempLabel                 matlab.ui.control.Label
        BCxMinHeatFluxLabel             matlab.ui.control.Label
        BCxMinTempLabel                 matlab.ui.control.Label
        BCVisualizationPanel            matlab.ui.container.Panel
        BCVisualizationButtonGroup      matlab.ui.container.ButtonGroup
        BCVisualizationThermButton      matlab.ui.control.RadioButton
        BCVisualizationHydraulicButton  matlab.ui.control.RadioButton
        BCxMinHeatFluxUnitDropDown      matlab.ui.control.DropDown
        BCxMinHeatFluxEditField         matlab.ui.control.NumericEditField
        BCxMinTempUnitDropDown          matlab.ui.control.DropDown
        BCxMinTempEditField             matlab.ui.control.NumericEditField
        BCxMaxHeatFluxUnitDropDown      matlab.ui.control.DropDown
        BCxMaxHeatFluxEditField         matlab.ui.control.NumericEditField
        BCxMaxTempUnitDropDown          matlab.ui.control.DropDown
        BCxMaxTempEditField             matlab.ui.control.NumericEditField
        BCyMinHeatFluxUnitDropDown      matlab.ui.control.DropDown
        BCyMinHeatFluxEditField         matlab.ui.control.NumericEditField
        BCyMinTempUnitDropDown          matlab.ui.control.DropDown
        BCyMinTempEditField             matlab.ui.control.NumericEditField
        BCyMaxTempUnitDropDown          matlab.ui.control.DropDown
        BCyMaxTempEditField             matlab.ui.control.NumericEditField
        BCyMaxHeatFluxUnitDropDown      matlab.ui.control.DropDown
        BCyMaxHeatFluxEditField         matlab.ui.control.NumericEditField
        BCzMinHeatFluxUnitDropDown      matlab.ui.control.DropDown
        BCzMinHeatFluxEditField         matlab.ui.control.NumericEditField
        BCzMinTempUnitDropDown          matlab.ui.control.DropDown
        BCzMinTempEditField             matlab.ui.control.NumericEditField
        BCzMaxTempUnitDropDown          matlab.ui.control.DropDown
        BCzMaxTempEditField             matlab.ui.control.NumericEditField
        BCzMaxHeatFluxUnitDropDown      matlab.ui.control.DropDown
        BCzMaxHeatFluxEditField         matlab.ui.control.NumericEditField
        BCxMinFluxUnitDropDown          matlab.ui.control.DropDown
        BCxMinFluxEditField             matlab.ui.control.NumericEditField
        BCxMinPressureUnitDropDown      matlab.ui.control.DropDown
        BCxMinPressureEditField         matlab.ui.control.NumericEditField
        BCxMaxFluxUnitDropDown          matlab.ui.control.DropDown
        BCxMaxFluxEditField             matlab.ui.control.NumericEditField
        BCxMaxPressureUnitDropDown      matlab.ui.control.DropDown
        BCxMaxPressureEditField         matlab.ui.control.NumericEditField
        BCyMinFluxUnitDropDown          matlab.ui.control.DropDown
        BCyMinFluxEditField             matlab.ui.control.NumericEditField
        BCyMinPressureUnitDropDown      matlab.ui.control.DropDown
        BCyMinPressureEditField         matlab.ui.control.NumericEditField
        BCyMaxPressureUnitDropDown      matlab.ui.control.DropDown
        BCyMaxPressureEditField         matlab.ui.control.NumericEditField
        BCyMaxFluxUnitDropDown          matlab.ui.control.DropDown
        BCyMaxFluxEditField             matlab.ui.control.NumericEditField
        BCzMinFluxUnitDropDown          matlab.ui.control.DropDown
        BCzMinFluxEditField             matlab.ui.control.NumericEditField
        BCzMinPressureUnitDropDown      matlab.ui.control.DropDown
        BCzMinPressureEditField         matlab.ui.control.NumericEditField
        BCxMinCheckBox                  matlab.ui.control.CheckBox
        BCxMaxCheckBox                  matlab.ui.control.CheckBox
        BCyMaxCheckBox                  matlab.ui.control.CheckBox
        BCyMinCheckBox                  matlab.ui.control.CheckBox
        BCzMinCheckBox                  matlab.ui.control.CheckBox
        BCzMaxCheckBox                  matlab.ui.control.CheckBox
        BCzMaxPressureUnitDropDown      matlab.ui.control.DropDown
        BCzMaxPressureEditField         matlab.ui.control.NumericEditField
        BCzMaxFluxUnitDropDown          matlab.ui.control.DropDown
        BCzMaxFluxEditField             matlab.ui.control.NumericEditField
        BCzMaxButtonGroup               matlab.ui.container.ButtonGroup
        BCzMaxFluxButton                matlab.ui.control.RadioButton
        BCzMaxPressureButton            matlab.ui.control.RadioButton
        BCzMinButtonGroup               matlab.ui.container.ButtonGroup
        BCzMinFluxButton                matlab.ui.control.RadioButton
        BCzMinPressureButton            matlab.ui.control.RadioButton
        BCyMaxButtonGroup               matlab.ui.container.ButtonGroup
        BCyMaxFluxButton                matlab.ui.control.RadioButton
        BCyMaxPressureButton            matlab.ui.control.RadioButton
        BCyMinButtonGroup               matlab.ui.container.ButtonGroup
        BCyMinFluxButton                matlab.ui.control.RadioButton
        BCyMinPressureButton            matlab.ui.control.RadioButton
        BCxMaxButtonGroup               matlab.ui.container.ButtonGroup
        BCxMaxPressureButton            matlab.ui.control.RadioButton
        BCxMaxFluxButton                matlab.ui.control.RadioButton
        BCxMinButtonGroup               matlab.ui.container.ButtonGroup
        BCxMinFluxButton                matlab.ui.control.RadioButton
        BCxMinPressureButton            matlab.ui.control.RadioButton
        ReservoirTab                    matlab.ui.container.Tab
        InitialConditionsPanel          matlab.ui.container.Panel
        EqualizationTimeUnitDropDown    matlab.ui.control.DropDown
        EqualizationTimeEditField       matlab.ui.control.NumericEditField
        EqualizationtimeLabel           matlab.ui.control.Label
        InitializeWithEquilibriumCheckBox  matlab.ui.control.CheckBox
        ReservoirInitTempEditField      matlab.ui.control.NumericEditField
        ReservoirTemperatureEditFieldLabel  matlab.ui.control.Label
        ReservoirInitPressureEditField  matlab.ui.control.NumericEditField
        ReservoirPressureEditFieldLabel  matlab.ui.control.Label
        ReservoirInitTempUnitDropDown   matlab.ui.control.DropDown
        ReservoirInitPressureUnitDropDown  matlab.ui.control.DropDown
        WellsTab                        matlab.ui.container.Tab
        WellsProducerPanel              matlab.ui.container.Panel
        ControlTypeLabel_2              matlab.ui.control.Label
        ProdWellPointyEditField         matlab.ui.control.NumericEditField
        xLabel_23                       matlab.ui.control.Label
        ProdWellPointzEditField         matlab.ui.control.NumericEditField
        xLabel_22                       matlab.ui.control.Label
        ProdWellPointxEditField         matlab.ui.control.NumericEditField
        xLabel_21                       matlab.ui.control.Label
        up                              matlab.ui.control.Label
        ProdWellBHPEditField            matlab.ui.control.NumericEditField
        ProdWellRateEditField           matlab.ui.control.NumericEditField
        ProdWellRadiusEditField         matlab.ui.control.NumericEditField
        RadiusEditField_2Label          matlab.ui.control.Label
        ProdWellNameEditField           matlab.ui.control.EditField
        WellNameEditField_2Label        matlab.ui.control.Label
        ProdWellBHPUnitDropDown         matlab.ui.control.DropDown
        ProdWellRateUnitDropDown        matlab.ui.control.DropDown
        ProdWellControlButtonGroup      matlab.ui.container.ButtonGroup
        ProdWellConstantBHPButton       matlab.ui.control.RadioButton
        ProdWellConstantRateButton      matlab.ui.control.RadioButton
        ProdWellRadiusUnitDropDown      matlab.ui.control.DropDown
        WellsInjectorPanel              matlab.ui.container.Panel
        ControlTypeLabel                matlab.ui.control.Label
        InjWellTempEditField            matlab.ui.control.NumericEditField
        FluidTemperatureLabel           matlab.ui.control.Label
        InjWellTempUnitDropDown         matlab.ui.control.DropDown
        InjWellPointyEditField          matlab.ui.control.NumericEditField
        xLabel_20                       matlab.ui.control.Label
        InjWellPointzEditField          matlab.ui.control.NumericEditField
        xLabel_19                       matlab.ui.control.Label
        InjWellPointxEditField          matlab.ui.control.NumericEditField
        xLabel_18                       matlab.ui.control.Label
        BottomHolePointLabel            matlab.ui.control.Label
        InjWellBHPUnitDropDown          matlab.ui.control.DropDown
        InjWellRateUnitDropDown         matlab.ui.control.DropDown
        InjWellBHPEditField             matlab.ui.control.NumericEditField
        InjWellRateEditField            matlab.ui.control.NumericEditField
        InjWellControlButtonGroup       matlab.ui.container.ButtonGroup
        InjWellConstantBHPButton        matlab.ui.control.RadioButton
        InjWellConstantRateButton       matlab.ui.control.RadioButton
        InjWellRadiusUnitDropDown       matlab.ui.control.DropDown
        InjWellRadiusEditField          matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        InjWellNameEditField            matlab.ui.control.EditField
        WellNameEditFieldLabel          matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        RampupStepsEditField            matlab.ui.control.NumericEditField
        RampupStepsLabel                matlab.ui.control.Label
        TimestepsPanel                  matlab.ui.container.Panel
        TimestepUnitDropDown            matlab.ui.control.DropDown
        EndTimeUnitDropDown             matlab.ui.control.DropDown
        TimestepEditField               matlab.ui.control.NumericEditField
        RampupStepsLabel_3              matlab.ui.control.Label
        EndTimeEditField                matlab.ui.control.NumericEditField
        RampupStepsLabel_2              matlab.ui.control.Label
        TimestepTypeButtonGroup         matlab.ui.container.ButtonGroup
        GeometricRampupButton           matlab.ui.control.RadioButton
        FixedTimestepsButton            matlab.ui.control.RadioButton
        PlausibilityLimitsPanel         matlab.ui.container.Panel
        MaxReservoirTempEditField       matlab.ui.control.NumericEditField
        MaxReservoirTemperatureEditFieldLabel  matlab.ui.control.Label
        MaxReservoirTempUnitDropDown    matlab.ui.control.DropDown
        MaxReservoirPressureEditField   matlab.ui.control.NumericEditField
        MaxReservoirPressureEditField_2Label  matlab.ui.control.Label
        MaxReservoirPressureUnitDropDown  matlab.ui.control.DropDown
        MinReservoirTempEditField       matlab.ui.control.NumericEditField
        MinReservoirTemperatureEditFieldLabel  matlab.ui.control.Label
        MinReservoirPressureEditField   matlab.ui.control.NumericEditField
        MinReservoirPressureEditFieldLabel  matlab.ui.control.Label
        MinReservoirTempUnitDropDown    matlab.ui.control.DropDown
        MinReservoirPressureUnitDropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        task Task % Task object
        callingApp % Description
        globalVars % Description
        globalConsts
        oldTaskName
    end

    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%                     General App Methods                     %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Methods needed for app control.       

        function closeAppPrompt(app)
            decision = quitWithoutSavingDialog(app.GraphicalTaskBuilderUIFigure);
            switch decision
                case 'Save'
                    app.saveTask();
                    app.closeApp();
                case 'Continue'
                    app.closeApp();
            end
        end

        function closeApp(app)
            app.callingApp.showApp();
            app.delete();
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%                    Task Control Methods                     %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function updateTaskParams(app)
            % Task meta 
            app.task.generic.name = app.TaskNameEditField.Value;
            app.task.generic.notes = cells2str(app.TaskNotesTextArea.Value);

            if app.TaskKeepExistingCheckBox.Value
                app.task.generic.created = datetime;
                app.task = app.task.updateParentDirName(app.globalVars.DEFAULT_TASK_STORAGE_DATETIME_FORMAT);
            end

            % General
            params.general.builderPreview = app.PreviewCheckBox.Value;

            % Grid
            params.grid.global.size = app.getGridSizeVector();
            params.grid.global.depth = app.GridDepthEditField.Value * mapUnitToMRST(app.GridDepthUnitDropDown.Value);
            params.grid.global.dims = app.getGridDimsVector();

            params.grid.layer.enable = app.GridLayerActiveCheckBox.Value;
            params.grid.layer.point = app.getLayerPoint();
            params.grid.layer.thickness = app.GridLayerThicknessEditField.Value;
            params.grid.layer.angles = app.getLayerAngles();

            % Rock
            params.rock.visualization.property = app.getRockVisualizationButtonConfig();
            params.rock.visualization.layerOnly = app.VisualizeLayerOnlyCheckBox.Value;
            
            params.rock.global.porosity.type = app.getGlobalPorosityButtonConfig();
            params.rock.global.porosity.uniform = app.GlobalPorosityEditField.Value;
            params.rock.global.porosity.gaussian.min = app.GlobalPorosityGaussianMinEditField.Value;
            params.rock.global.porosity.gaussian.max = app.GlobalPorosityGaussianMaxEditField.Value;
            params.rock.global.porosity.gaussian.std = app.GlobalPorosityGaussianStdEditField.Value;
            params.rock.layer.porosity.type = app.getLayerPorosityButtonConfig();
            params.rock.layer.porosity.uniform = app.LayerPorosityEditField.Value;
            params.rock.layer.porosity.gaussian.min = app.LayerPorosityGaussianMinEditField.Value;
            params.rock.layer.porosity.gaussian.max = app.LayerPorosityGaussianMaxEditField.Value;
            params.rock.layer.porosity.gaussian.std = app.LayerPorosityGaussianStdEditField.Value;
            
            params.rock.global.perm.type = app.getGlobalPermButtonConfig();
            params.rock.global.perm.uniform = app.GlobalPermEditField.Value * mapUnitToMRST(app.GlobalPermUnitDropDown.Value);
            params.rock.global.perm.gaussian.min = app.GlobalPermGaussianMinEditField.Value * mapUnitToMRST(app.GlobalPermUnitDropDown.Value);
            params.rock.global.perm.gaussian.max = app.GlobalPermGaussianMaxEditField.Value * mapUnitToMRST(app.GlobalPermUnitDropDown.Value);
            params.rock.global.perm.gaussian.std = app.GlobalPermGaussianStdEditField.Value;
            params.rock.global.perm.directional = app.getGlobalDirPerm();
            params.rock.layer.perm.type = app.getLayerPermButtonConfig();
            params.rock.layer.perm.uniform = app.LayerPermEditField.Value * mapUnitToMRST(app.LayerPermUnitDropDown.Value);
            params.rock.layer.perm.gaussian.min = app.LayerPermGaussianMinEditField.Value * mapUnitToMRST(app.LayerPermUnitDropDown.Value);
            params.rock.layer.perm.gaussian.max = app.LayerPermGaussianMaxEditField.Value * mapUnitToMRST(app.LayerPermUnitDropDown.Value);
            params.rock.layer.perm.gaussian.std = app.LayerPermGaussianStdEditField.Value;
            params.rock.layer.perm.directional = app.getLayerDirPerm();
            
            params.rock.global.density.type = app.getGlobalDensityButtonConfig();
            params.rock.global.density.uniform = app.GlobalDensityEditField.Value * mapUnitToMRST(app.GlobalDensityUnitDropDown.Value);        
            params.rock.global.density.gaussian.min = app.GlobalDensityGaussianMinEditField.Value * mapUnitToMRST(app.GlobalDensityGaussianMinUnitDropDown.Value);
            params.rock.global.density.gaussian.max = app.GlobalDensityGaussianMaxEditField.Value * mapUnitToMRST(app.GlobalDensityGaussianMaxUnitDropDown.Value);
            params.rock.global.density.gaussian.std = app.GlobalDensityGaussianStdEditField.Value;
            params.rock.layer.density.type = app.getLayerDensityButtonConfig();
            params.rock.layer.density.uniform = app.LayerDensityEditField.Value * mapUnitToMRST(app.LayerDensityUnitDropDown.Value); 
            params.rock.layer.density.gaussian.min = app.LayerDensityGaussianMinEditField.Value * mapUnitToMRST(app.LayerDensityGaussianMinUnitDropDown.Value);
            params.rock.layer.density.gaussian.max = app.LayerDensityGaussianMaxEditField.Value * mapUnitToMRST(app.LayerDensityGaussianMaxUnitDropDown.Value);
            params.rock.layer.density.gaussian.std = app.LayerDensityGaussianStdEditField.Value;
            
            params.rock.global.thermalCond.type = app.getGlobalCondButtonConfig();
            params.rock.global.thermalCond.uniform = app.GlobalCondEditField.Value * mapUnitToMRST(app.GlobalCondUnitDropDown.Value);        
            params.rock.global.thermalCond.gaussian.min = app.GlobalCondGaussianMinEditField.Value * mapUnitToMRST(app.GlobalCondGaussianMinUnitDropDown.Value);
            params.rock.global.thermalCond.gaussian.max = app.GlobalCondGaussianMaxEditField.Value * mapUnitToMRST(app.GlobalCondGaussianMaxUnitDropDown.Value);
            params.rock.global.thermalCond.gaussian.std = app.GlobalCondGaussianStdEditField.Value;
            params.rock.layer.thermalCond.type = app.getLayerCondButtonConfig();
            params.rock.layer.thermalCond.uniform = app.LayerCondEditField.Value * mapUnitToMRST(app.LayerCondUnitDropDown.Value); 
            params.rock.layer.thermalCond.gaussian.min = app.LayerCondGaussianMinEditField.Value * mapUnitToMRST(app.LayerCondGaussianMinUnitDropDown.Value);
            params.rock.layer.thermalCond.gaussian.max = app.LayerCondGaussianMaxEditField.Value * mapUnitToMRST(app.LayerCondGaussianMaxUnitDropDown.Value);
            params.rock.layer.thermalCond.gaussian.std = app.LayerCondGaussianStdEditField.Value;
            
            params.rock.global.heatCapacity.type = app.getGlobalHeatButtonConfig();
            params.rock.global.heatCapacity.uniform = app.GlobalHeatEditField.Value * mapUnitToMRST(app.GlobalHeatUnitDropDown.Value);        
            params.rock.global.heatCapacity.gaussian.min = app.GlobalHeatGaussianMinEditField.Value * mapUnitToMRST(app.GlobalHeatGaussianMinUnitDropDown.Value);
            params.rock.global.heatCapacity.gaussian.max = app.GlobalHeatGaussianMaxEditField.Value * mapUnitToMRST(app.GlobalHeatGaussianMaxUnitDropDown.Value);
            params.rock.global.heatCapacity.gaussian.std = app.GlobalHeatGaussianStdEditField.Value;
            params.rock.layer.heatCapacity.type = app.getLayerHeatButtonConfig();
            params.rock.layer.heatCapacity.uniform = app.LayerHeatEditField.Value * mapUnitToMRST(app.LayerHeatUnitDropDown.Value); 
            params.rock.layer.heatCapacity.gaussian.min = app.LayerHeatGaussianMinEditField.Value * mapUnitToMRST(app.LayerHeatGaussianMinUnitDropDown.Value);
            params.rock.layer.heatCapacity.gaussian.max = app.LayerHeatGaussianMaxEditField.Value * mapUnitToMRST(app.LayerHeatGaussianMaxUnitDropDown.Value);
            params.rock.layer.heatCapacity.gaussian.std = app.LayerHeatGaussianStdEditField.Value;

            % Fluid
            params.fluid.useEOS = app.FluidEOSCheckBox.Value;
            params.fluid.density = app.FluidDensityEditField.Value * mapUnitToMRST(app.FluidDensityUnitDropDown.Value);
            params.fluid.viscosity = app.FluidViscosityEditField.Value * mapUnitToMRST(app.FluidViscosityUnitDropDown.Value);
            params.fluid.gravity.enabled = app.FluidGravityCheckBox.Value;
            params.fluid.gravity.vector = app.getGravityVector();
            params.fluid.heatCapacity = app.FluidHeatEditField.Value * mapUnitToMRST(app.FluidHeatUnitDropDown.Value);
            params.fluid.thermalCond = app.FluidCondEditField.Value * mapUnitToMRST(app.FluidCondUnitDropDown.Value);

            % BC
            params.bc.thermalType = app.getBCThermalButtonConfig();
            params.bc.visualization = app.getBCVisualizationButtonConfig();
            params.bc.enable = app.getActiveBCs();
            params.bc.type = app.getBCTypeButtonConfig();
            params.bc.pressure = app.getBCsPressure();
            params.bc.flux = app.getBCsFlux();
            params.bc.temperature = app.getBCsTemperature();
            params.bc.heatFlux = app.getBCsHeatFlux();

            % Reservoir
            params.reservoir.pressure = app.ReservoirInitPressureEditField.Value * mapUnitToMRST(app.ReservoirInitPressureUnitDropDown.Value);
            params.reservoir.temperature = temperature2kelvin(app.ReservoirInitTempEditField.Value, app.ReservoirInitTempUnitDropDown.Value);

            % Wells
            params.wells.inj.name = app.InjWellNameEditField.Value;
            params.wells.inj.radius = app.InjWellRadiusEditField.Value * mapUnitToMRST(app.InjWellRadiusUnitDropDown.Value);
            params.wells.inj.control.type = app.getInjWellOperatingCondButtonConfig();
            params.wells.inj.control.rate = app.InjWellRateEditField.Value * mapUnitToMRST(app.InjWellRateUnitDropDown.Value);
            params.wells.inj.control.pressure = app.InjWellBHPEditField.Value * mapUnitToMRST(app.InjWellBHPUnitDropDown.Value);
            params.wells.inj.temperature = temperature2kelvin(app.InjWellTempEditField.Value, app.InjWellTempUnitDropDown.Value);
            params.wells.inj.point = app.getInjWellBottomPoint();
            
            params.wells.prod.name = app.ProdWellNameEditField.Value;
            params.wells.prod.radius = app.ProdWellRadiusEditField.Value * mapUnitToMRST(app.ProdWellRadiusUnitDropDown.Value);
            params.wells.prod.control.type = app.getProdWellOperatingCondButtonConfig();
            params.wells.prod.control.rate = app.ProdWellRateEditField.Value * mapUnitToMRST(app.ProdWellRateUnitDropDown.Value);
            params.wells.prod.control.pressure = app.ProdWellBHPEditField.Value * mapUnitToMRST(app.ProdWellBHPUnitDropDown.Value);
            params.wells.prod.point = app.getProdWellBottomPoint();

            % Simulation
            params.simulation.endTime = app.EndTimeEditField.Value * mapUnitToMRST(app.EndTimeUnitDropDown.Value);
            params.simulation.timestep = app.TimestepEditField.Value * mapUnitToMRST(app.TimestepUnitDropDown.Value);
            params.simulation.timestepType = app.getTimestepTypeConfig();
            params.simulation.timeRampupSteps = app.RampupStepsEditField.Value;
            params.simulation.pressureLimits = app.getPressureLimitsConfig();
            params.simulation.temperatureLimits = app.getTempLimitsConfig();
            params.simulation.initializeWithEquilibrium = app.InitializeWithEquilibriumCheckBox.Value;
            params.simulation.equilibriumTime = app.EqualizationTimeEditField.Value * mapUnitToMRST(app.EqualizationTimeUnitDropDown.Value);
            
            app.task.params = params;
        end

        function loadTaskParams(app)
            
            % Task meta 
            app.TaskNameEditField.Value = app.task.generic.name;
            app.TaskNotesTextArea.Value = str2cells(app.task.generic.notes);
            app.TaskCreatedEditField.Value = datestr(app.task.generic.created, app.globalVars.DISPLAY_DATETIME_FORMAT);

            params = app.task.params;
            
            % General
            app.PreviewCheckBox.Value = params.general.builderPreview;

            % Grid
            app.setGridSizeVector(params.grid.global.size);
            app.GridDepthEditField.Value = params.grid.global.depth;
            app.setGridDimsVector(params.grid.global.dims);

            app.GridLayerActiveCheckBox.Value = params.grid.layer.enable;
            app.setLayerPoint(params.grid.layer.point);
            app.GridLayerThicknessEditField.Value = params.grid.layer.thickness;
            app.setLayerAngles(params.grid.layer.angles);

            % Rock
            app.setRockVisualizationButtonConfig(params.rock.visualization.property);
            app.VisualizeLayerOnlyCheckBox.Value = params.rock.visualization.layerOnly;
            
            app.setGlobalPorosityButtonConfig(params.rock.global.porosity.type);
            app.GlobalPorosityEditField.Value = params.rock.global.porosity.uniform;
            app.GlobalPorosityGaussianMinEditField.Value = params.rock.global.porosity.gaussian.min;
            app.GlobalPorosityGaussianMaxEditField.Value = params.rock.global.porosity.gaussian.max;
            app.GlobalPorosityGaussianStdEditField.Value = params.rock.global.porosity.gaussian.std;
            app.setLayerPorosityButtonConfig(params.rock.layer.porosity.type);
            app.LayerPorosityEditField.Value = params.rock.layer.porosity.uniform;
            app.LayerPorosityGaussianMinEditField.Value = params.rock.layer.porosity.gaussian.min;
            app.LayerPorosityGaussianMaxEditField.Value = params.rock.layer.porosity.gaussian.max;
            app.LayerPorosityGaussianStdEditField.Value = params.rock.layer.porosity.gaussian.std;
            
            app.setGlobalPermButtonConfig(params.rock.global.perm.type);
            app.GlobalPermEditField.Value = params.rock.global.perm.uniform * mapUnitToMRSTReverse(app.GlobalPermUnitDropDown.Value);
            app.GlobalPermGaussianMinEditField.Value = params.rock.global.perm.gaussian.min * mapUnitToMRSTReverse(app.GlobalPermGaussianMinUnitDropDown.Value);
            app.GlobalPermGaussianMaxEditField.Value = params.rock.global.perm.gaussian.max * mapUnitToMRSTReverse(app.GlobalPermGaussianMaxUnitDropDown.Value);
            app.GlobalPermGaussianStdEditField.Value = params.rock.global.perm.gaussian.std;
            app.setGlobalDirPerm(params.rock.global.perm.directional);
            app.setLayerPermButtonConfig(params.rock.layer.perm.type);
            app.LayerPermEditField.Value = params.rock.layer.perm.uniform * mapUnitToMRSTReverse(app.LayerPermUnitDropDown.Value);
            app.LayerPermGaussianMinEditField.Value = params.rock.layer.perm.gaussian.min * mapUnitToMRSTReverse(app.LayerPermGaussianMinUnitDropDown.Value);
            app.LayerPermGaussianMaxEditField.Value = params.rock.layer.perm.gaussian.max * mapUnitToMRSTReverse(app.LayerPermGaussianMaxUnitDropDown.Value);
            app.LayerPermGaussianStdEditField.Value = params.rock.layer.perm.gaussian.std;
            app.setLayerDirPerm(params.rock.layer.perm.directional);
            
            app.setGlobalDensityButtonConfig(params.rock.global.density.type);
            app.GlobalDensityEditField.Value = params.rock.global.density.uniform * mapUnitToMRSTReverse(app.GlobalDensityUnitDropDown.Value);        
            app.GlobalDensityGaussianMinEditField.Value = params.rock.global.density.gaussian.min * mapUnitToMRSTReverse(app.GlobalDensityGaussianMinUnitDropDown.Value);
            app.GlobalDensityGaussianMaxEditField.Value = params.rock.global.density.gaussian.max * mapUnitToMRSTReverse(app.GlobalDensityGaussianMaxUnitDropDown.Value);
            app.GlobalDensityGaussianStdEditField.Value = params.rock.global.density.gaussian.std;
            app.setLayerDensityButtonConfig(params.rock.layer.density.type);
            app.LayerDensityEditField.Value = params.rock.layer.density.uniform * mapUnitToMRSTReverse(app.LayerDensityUnitDropDown.Value); 
            app.LayerDensityGaussianMinEditField.Value = params.rock.layer.density.gaussian.min * mapUnitToMRSTReverse(app.LayerDensityGaussianMinUnitDropDown.Value);
            app.LayerDensityGaussianMaxEditField.Value = params.rock.layer.density.gaussian.max * mapUnitToMRSTReverse(app.LayerDensityGaussianMaxUnitDropDown.Value);
            app.LayerDensityGaussianStdEditField.Value = params.rock.layer.density.gaussian.std;
            
            app.setGlobalCondButtonConfig(params.rock.global.thermalCond.type);
            app.GlobalCondEditField.Value = params.rock.global.thermalCond.uniform * mapUnitToMRSTReverse(app.GlobalCondUnitDropDown.Value);        
            app.GlobalCondGaussianMinEditField.Value = params.rock.global.thermalCond.gaussian.min * mapUnitToMRSTReverse(app.GlobalCondGaussianMinUnitDropDown.Value);
            app.GlobalCondGaussianMaxEditField.Value = params.rock.global.thermalCond.gaussian.max * mapUnitToMRSTReverse(app.GlobalCondGaussianMaxUnitDropDown.Value);
            app.GlobalCondGaussianStdEditField.Value = params.rock.global.thermalCond.gaussian.std;
            app.setLayerCondButtonConfig(params.rock.layer.thermalCond.type);
            app.LayerCondEditField.Value = params.rock.layer.thermalCond.uniform * mapUnitToMRSTReverse(app.LayerCondUnitDropDown.Value); 
            app.LayerCondGaussianMinEditField.Value = params.rock.layer.thermalCond.gaussian.min * mapUnitToMRSTReverse(app.LayerCondGaussianMinUnitDropDown.Value);
            app.LayerCondGaussianMaxEditField.Value = params.rock.layer.thermalCond.gaussian.max * mapUnitToMRSTReverse(app.LayerCondGaussianMaxUnitDropDown.Value);
            app.LayerCondGaussianStdEditField.Value = params.rock.layer.thermalCond.gaussian.std;
            
            app.setGlobalHeatButtonConfig(params.rock.global.heatCapacity.type);
            app.GlobalHeatEditField.Value = params.rock.global.heatCapacity.uniform * mapUnitToMRSTReverse(app.GlobalHeatUnitDropDown.Value);        
            app.GlobalHeatGaussianMinEditField.Value = params.rock.global.heatCapacity.gaussian.min * mapUnitToMRSTReverse(app.GlobalHeatGaussianMinUnitDropDown.Value);
            app.GlobalHeatGaussianMaxEditField.Value = params.rock.global.heatCapacity.gaussian.max * mapUnitToMRSTReverse(app.GlobalHeatGaussianMaxUnitDropDown.Value);
            app.GlobalHeatGaussianStdEditField.Value = params.rock.global.heatCapacity.gaussian.std;
            app.setLayerHeatButtonConfig(params.rock.layer.heatCapacity.type);
            app.LayerHeatEditField.Value = params.rock.layer.heatCapacity.uniform * mapUnitToMRSTReverse(app.LayerHeatUnitDropDown.Value); 
            app.LayerHeatGaussianMinEditField.Value = params.rock.layer.heatCapacity.gaussian.min * mapUnitToMRSTReverse(app.LayerHeatGaussianMinUnitDropDown.Value);
            app.LayerHeatGaussianMaxEditField.Value = params.rock.layer.heatCapacity.gaussian.max * mapUnitToMRSTReverse(app.LayerHeatGaussianMaxUnitDropDown.Value);
            app.LayerHeatGaussianStdEditField.Value = params.rock.layer.heatCapacity.gaussian.std;

            % Fluid
            app.FluidEOSCheckBox.Value = params.fluid.useEOS;
            app.FluidDensityEditField.Value = params.fluid.density * mapUnitToMRSTReverse(app.FluidDensityUnitDropDown.Value);
            app.FluidViscosityEditField.Value = params.fluid.viscosity * mapUnitToMRSTReverse(app.FluidViscosityUnitDropDown.Value);
            app.FluidGravityCheckBox.Value = params.fluid.gravity.enabled;
            app.setGravityVector(params.fluid.gravity.vector);
            app.FluidHeatEditField.Value = params.fluid.heatCapacity * mapUnitToMRSTReverse(app.FluidHeatUnitDropDown.Value);
            app.FluidCondEditField.Value = params.fluid.thermalCond * mapUnitToMRSTReverse(app.FluidCondUnitDropDown.Value);

            % BC
            app.setBCThermalButtonConfig(params.bc.thermalType);
            app.setBCVisualizationButtonConfig(params.bc.visualization);
            app.setActiveBCs(params.bc.enable);
            app.setBCTypeButtonConfig(params.bc.type);
            app.setBCsPressure(params.bc.pressure);
            app.setBCsFlux(params.bc.flux);
            app.setBCsTemperature(params.bc.temperature);
            app.setBCsHeatFlux(params.bc.heatFlux);

            % Reservoir
            app.ReservoirInitPressureEditField.Value = params.reservoir.pressure * mapUnitToMRSTReverse(app.ReservoirInitPressureUnitDropDown.Value);
            app.ReservoirInitTempEditField.Value = kelvin2temperature(params.reservoir.temperature, app.ReservoirInitTempUnitDropDown.Value);

            % Wells
            app.InjWellNameEditField.Value = params.wells.inj.name;
            app.InjWellRadiusEditField.Value = params.wells.inj.radius * mapUnitToMRSTReverse(app.InjWellRadiusUnitDropDown.Value);
            app.setInjWellOperatingCondButtonConfig(params.wells.inj.control.type);
            app.InjWellRateEditField.Value = params.wells.inj.control.rate * mapUnitToMRSTReverse(app.InjWellRateUnitDropDown.Value);
            app.InjWellBHPEditField.Value = params.wells.inj.control.pressure * mapUnitToMRSTReverse(app.InjWellBHPUnitDropDown.Value);
            app.InjWellTempEditField.Value = kelvin2temperature(params.wells.inj.temperature, app.InjWellTempUnitDropDown.Value);
            app.setInjWellBottomPoint(params.wells.inj.point);
            
            app.ProdWellNameEditField.Value = params.wells.prod.name;
            app.ProdWellRadiusEditField.Value = params.wells.prod.radius * mapUnitToMRSTReverse(app.ProdWellRadiusUnitDropDown.Value);
            app.setProdWellOperatingCondButtonConfig(params.wells.prod.control.type);
            app.ProdWellRateEditField.Value = params.wells.prod.control.rate * mapUnitToMRSTReverse(app.ProdWellRateUnitDropDown.Value);
            app.ProdWellBHPEditField.Value = params.wells.prod.control.pressure * mapUnitToMRSTReverse(app.ProdWellBHPUnitDropDown.Value);
            app.setProdWellBottomPoint(params.wells.prod.point);

            % Simulation
            app.EndTimeEditField.Value = params.simulation.endTime * mapUnitToMRSTReverse(app.EndTimeUnitDropDown.Value);
            app.TimestepEditField.Value = params.simulation.timestep * mapUnitToMRSTReverse(app.TimestepUnitDropDown.Value);
            app.setTimestepTypeConfig(params.simulation.timestepType);
            app.RampupStepsEditField.Value = params.simulation.timeRampupSteps;
            app.setPressureLimitsConfig(params.simulation.pressureLimits);
            app.setTempLimitsConfig(params.simulation.temperatureLimits);
            app.InitializeWithEquilibriumCheckBox.Value = params.simulation.initializeWithEquilibrium;
            app.EqualizationTimeEditField.Value = params.simulation.equilibriumTime * mapUnitToMRSTReverse(app.EqualizationTimeUnitDropDown.Value);
        end

        function saveTask(app)            
            app.updateTaskParams();
            storeTask(app.task);
        end

        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%               GUI Visibility Control Methods                %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Methods for disabling/enabling GUI features, showing warnings,...
        
        function updateGUIVisibility(app)
            app.updateTaskTabVisibility();
            app.updateDomainTabVisibility();
            app.updateRockTabVisibility();
            app.updateFluidTabVisibility();
            app.updateBCsTabVisibility();
            app.updateWellsTabVisibility();
        end

        % Task Tab
        function updateTaskTabVisibility(app)
            if strcmp(app.oldTaskName, app.TaskNameEditField.Value)
                app.TaskKeepExistingCheckBox.Enable = "on";
            else
                app.TaskKeepExistingCheckBox.Enable = "off";
            end
        end

        % Domain Tab
        function updateDomainTabVisibility(app)
            if app.GridLayerActiveCheckBox.Value
                app.GridLayerPropertiesPanel.Enable = "on";
            else
                app.GridLayerPropertiesPanel.Enable = "off";
            end
        end

        % Rock Tab
        function updateRockTabVisibility(app)
            if app.GridLayerActiveCheckBox.Value
                app.VisualizeLayerOnlyCheckBox.Enable = "on";
                app.enableRockPorosityLayerInputs();
                app.enableRockPermLayerInputs();
                app.enableRockDensityLayerInputs();
                app.enableRockCondLayerInputs();
                app.enableRockHeatLayerInputs();
            else
                app.VisualizeLayerOnlyCheckBox.Enable = "off";
                app.disableRockPorosityLayerInputs();
                app.disableRockPermLayerInputs();
                app.disableRockDensityLayerInputs();
                app.disableRockCondLayerInputs();
                app.disableRockHeatLayerInputs();
            end
        end

        % Fluid Tab
        function updateFluidTabVisibility(app)
            if app.FluidGravityCheckBox.Value
                app.enableGravityInputs();
            else
                app.disableGravityInputs();
            end
        end

        % BCs Tab
        function updateBCsTabVisibility(app)
            if app.BCxMinCheckBox.Value
                app.enablexMinBCInputs();
            else
                app.disablexMinBCInputs();
            end
            if app.BCxMaxCheckBox.Value
                app.enablexMaxBCInputs();
            else
                app.disablexMaxBCInputs();
            end
            if app.BCyMinCheckBox.Value
                app.enableyMinBCInputs();
            else
                app.disableyMinBCInputs();
            end
            if app.BCyMaxCheckBox.Value
                app.enableyMaxBCInputs();
            else
                app.disableyMaxBCInputs();
            end
            if app.BCzMinCheckBox.Value
                app.enablezMinBCInputs();
            else
                app.disablezMinBCInputs();
            end
            if app.BCzMaxCheckBox.Value
                app.enablezMaxBCInputs();
            else
                app.disablezMaxBCInputs();
            end   
        end

        % Wells Tab
        function updateWellsTabVisibility(app)
            switch app.InjWellControlButtonGroup.SelectedObject
                case app.InjWellConstantRateButton
                    app.enableInjWellRateInputs();
                    app.disableInjWellBHPInputs();
                case app.InjWellConstantBHPButton
                    app.disableInjWellRateInputs();
                    app.enableInjWellBHPInputs();
            end
            switch app.ProdWellControlButtonGroup.SelectedObject
                case app.ProdWellConstantRateButton
                    app.enableProdWellRateInputs();
                    app.disableProdWellBHPInputs();
                case app.ProdWellConstantBHPButton
                    app.disableProdWellRateInputs();
                    app.enableProdWellBHPInputs();
            end
        end

        % Rock Layer properties
        function enableRockPorosityLayerInputs(app)
            app.LayerPorosityUniformButton.Enable = "on";
            app.LayerPorosityGaussianButton.Enable = "on";
            app.LayerPorosityEditField.Enable = "on";
            app.LayerPorosityGaussianMinEditField.Enable = "on";
            app.LayerPorosityGaussianMaxEditField.Enable = "on";
            app.LayerPorosityGaussianStdEditField.Enable = "on";
        end
        function disableRockPorosityLayerInputs(app)
            app.LayerPorosityUniformButton.Enable = "off";
            app.LayerPorosityGaussianButton.Enable = "off";
            app.LayerPorosityEditField.Enable = "off";
            app.LayerPorosityGaussianMinEditField.Enable = "off";
            app.LayerPorosityGaussianMaxEditField.Enable = "off";
            app.LayerPorosityGaussianStdEditField.Enable = "off";
        end
        function enableRockPermLayerInputs(app)
            app.LayerPermUniformButton.Enable = "on";
            app.LayerPermGaussianButton.Enable = "on";
            app.LayerPermDirectionalButton.Enable = "on";
            app.LayerPermEditField.Enable = "on";
            app.LayerPermUnitDropDown.Enable = "on";
            app.LayerPermGaussianMinEditField.Enable = "on";
            app.LayerPermGaussianMinUnitDropDown.Enable = "on";
            app.LayerPermGaussianMaxEditField.Enable = "on";
            app.LayerPermGaussianMaxUnitDropDown.Enable = "on";
            app.LayerPermGaussianStdEditField.Enable = "on";
            app.LayerPermDirxEditField.Enable = "on";
            app.LayerPermDirxUnitDropDown.Enable = "on";
            app.LayerPermDiryEditField.Enable = "on";
            app.LayerPermDiryUnitDropDown.Enable = "on";
            app.LayerPermDirzEditField.Enable = "on";
            app.LayerPermDirzUnitDropDown.Enable = "on";
        end
        function disableRockPermLayerInputs(app)
            app.LayerPermUniformButton.Enable = "off";
            app.LayerPermGaussianButton.Enable = "off";
            app.LayerPermDirectionalButton.Enable = "off";
            app.LayerPermEditField.Enable = "off";
            app.LayerPermUnitDropDown.Enable = "off";
            app.LayerPermGaussianMinEditField.Enable = "off";
            app.LayerPermGaussianMinUnitDropDown.Enable = "off";
            app.LayerPermGaussianMaxEditField.Enable = "off";
            app.LayerPermGaussianMaxUnitDropDown.Enable = "off";
            app.LayerPermGaussianStdEditField.Enable = "off";
            app.LayerPermDirxEditField.Enable = "off";
            app.LayerPermDirxUnitDropDown.Enable = "off";
            app.LayerPermDiryEditField.Enable = "off";
            app.LayerPermDiryUnitDropDown.Enable = "off";
            app.LayerPermDirzEditField.Enable = "off";
            app.LayerPermDirzUnitDropDown.Enable = "off";
        end
        function enableRockDensityLayerInputs(app)
            app.LayerDensityUniformButton.Enable = "on";
            app.LayerDensityGaussianButton.Enable = "on";
            app.LayerDensityEditField.Enable = "on";
            app.LayerDensityUnitDropDown.Enable = "on";
            app.LayerDensityGaussianMinEditField.Enable = "on";
            app.LayerDensityGaussianMinUnitDropDown.Enable = "on";
            app.LayerDensityGaussianMaxEditField.Enable = "on";
            app.LayerDensityGaussianMaxUnitDropDown.Enable = "on";
            app.LayerDensityGaussianStdEditField.Enable = "on";
        end
        function disableRockDensityLayerInputs(app)
            app.LayerDensityUniformButton.Enable = "off";
            app.LayerDensityGaussianButton.Enable = "off";
            app.LayerDensityEditField.Enable = "off";
            app.LayerDensityUnitDropDown.Enable = "off";
            app.LayerDensityGaussianMinEditField.Enable = "off";
            app.LayerDensityGaussianMinUnitDropDown.Enable = "off";
            app.LayerDensityGaussianMaxEditField.Enable = "off";
            app.LayerDensityGaussianMaxUnitDropDown.Enable = "off";
            app.LayerDensityGaussianStdEditField.Enable = "off";
        end
        function enableRockCondLayerInputs(app)
            app.LayerCondUniformButton.Enable = "on";
            app.LayerCondGaussianButton.Enable = "on";
            app.LayerCondEditField.Enable = "on";
            app.LayerCondUnitDropDown.Enable = "on";
            app.LayerCondGaussianMinEditField.Enable = "on";
            app.LayerCondGaussianMinUnitDropDown.Enable = "on";
            app.LayerCondGaussianMaxEditField.Enable = "on";
            app.LayerCondGaussianMaxUnitDropDown.Enable = "on";
            app.LayerCondGaussianStdEditField.Enable = "on";
        end
        function disableRockCondLayerInputs(app)
            app.LayerCondUniformButton.Enable = "off";
            app.LayerCondGaussianButton.Enable = "off";
            app.LayerCondEditField.Enable = "off";
            app.LayerCondUnitDropDown.Enable = "off";
            app.LayerCondGaussianMinEditField.Enable = "off";
            app.LayerCondGaussianMinUnitDropDown.Enable = "off";
            app.LayerCondGaussianMaxEditField.Enable = "off";
            app.LayerCondGaussianMaxUnitDropDown.Enable = "off";
            app.LayerCondGaussianStdEditField.Enable = "off";
        end
        function enableRockHeatLayerInputs(app)
            app.LayerHeatUniformButton.Enable = "on";
            app.LayerHeatGaussianButton.Enable = "on";
            app.LayerHeatEditField.Enable = "on";
            app.LayerHeatUnitDropDown.Enable = "on";
            app.LayerHeatGaussianMinEditField.Enable = "on";
            app.LayerHeatGaussianMinUnitDropDown.Enable = "on";
            app.LayerHeatGaussianMaxEditField.Enable = "on";
            app.LayerHeatGaussianMaxUnitDropDown.Enable = "on";
            app.LayerHeatGaussianStdEditField.Enable = "on";
        end
        function disableRockHeatLayerInputs(app)
            app.LayerHeatUniformButton.Enable = "off";
            app.LayerHeatGaussianButton.Enable = "off";
            app.LayerHeatEditField.Enable = "off";
            app.LayerHeatUnitDropDown.Enable = "off";
            app.LayerHeatGaussianMinEditField.Enable = "off";
            app.LayerHeatGaussianMinUnitDropDown.Enable = "off";
            app.LayerHeatGaussianMaxEditField.Enable = "off";
            app.LayerHeatGaussianMaxUnitDropDown.Enable = "off";
            app.LayerHeatGaussianStdEditField.Enable = "off";
        end

        % Gravity properties
        function enableGravityInputs(app)
            app.FluidGravityxEditField.Enable = "on";
            app.FluidGravityxUnitDropDown.Enable = "on";
            app.FluidGravityyEditField.Enable = "on";
            app.FluidGravityyUnitDropDown.Enable = "on";
            app.FluidGravityzEditField.Enable = "on";
            app.FluidGravityzUnitDropDown.Enable = "on";
        end
        function disableGravityInputs(app)
            app.FluidGravityxEditField.Enable = "off";
            app.FluidGravityxUnitDropDown.Enable = "off";
            app.FluidGravityyEditField.Enable = "off";
            app.FluidGravityyUnitDropDown.Enable = "off";
            app.FluidGravityzEditField.Enable = "off";
            app.FluidGravityzUnitDropDown.Enable = "off";
        end

        % Lamps
        function turnLampOff(app, lamp)
            lamp.Color = app.globalConsts.LAMP_OFF_COLOR;
        end
        function turnLampOnOK(app, lamp)
            lamp.Color = app.globalConsts.LAMP_OK_COLOR;
        end
        function turnLampOnWarning(app, lamp)
            lamp.Color = app.globalConsts.LAMP_WARNING_COLOR;
        end
                
        % Well properties
        function enableInjWellRateInputs(app)
            app.InjWellRateEditField.Enable = true;
            app.InjWellRateUnitDropDown.Enable = true;
        end
        function disableInjWellRateInputs(app)
            app.InjWellRateEditField.Enable = false;
            app.InjWellRateUnitDropDown.Enable = false;
        end
        function enableInjWellBHPInputs(app)
            app.InjWellBHPEditField.Enable = true;
            app.InjWellBHPUnitDropDown.Enable = true;
        end        
        function disableInjWellBHPInputs(app)
            app.InjWellBHPEditField.Enable = false;
            app.InjWellBHPUnitDropDown.Enable = false;
        end
        
        % Production well
        function enableProdWellRateInputs(app)
            app.ProdWellRateEditField.Enable = true;
            app.ProdWellRateUnitDropDown.Enable = true;
        end
        function disableProdWellRateInputs(app)
            app.ProdWellRateEditField.Enable = false;
            app.ProdWellRateUnitDropDown.Enable = false;
        end
        function enableProdWellBHPInputs(app)
            app.ProdWellBHPEditField.Enable = true;
            app.ProdWellBHPUnitDropDown.Enable = true;
        end
        function disableProdWellBHPInputs(app)
            app.ProdWellBHPEditField.Enable = false;
            app.ProdWellBHPUnitDropDown.Enable = false;
        end
        
        % BCs
        function enablexMinBCInputs(app)
            app.BCxMinPressureButton.Enable = true;
            app.BCxMinPressureEditField.Enable = true;
            app.BCxMinPressureUnitDropDown.Enable = true;
            app.BCxMinFluxButton.Enable = true;
            app.BCxMinFluxEditField.Enable = true;
            app.BCxMinFluxUnitDropDown.Enable = true;
            app.BCxMinTempLabel.Enable = true;
            app.BCxMinTempEditField.Enable = true;
            app.BCxMinTempUnitDropDown.Enable = true;
            app.BCxMinHeatFluxLabel.Enable = true;
            app.BCxMinHeatFluxEditField.Enable = true;
            app.BCxMinHeatFluxUnitDropDown.Enable = true;
        end
        function disablexMinBCInputs(app)
            app.BCxMinPressureButton.Enable = false;
            app.BCxMinPressureEditField.Enable = false;
            app.BCxMinPressureUnitDropDown.Enable = false;
            app.BCxMinFluxButton.Enable = false;
            app.BCxMinFluxEditField.Enable = false;
            app.BCxMinFluxUnitDropDown.Enable = false;
            app.BCxMinTempLabel.Enable = false;
            app.BCxMinTempEditField.Enable = false;
            app.BCxMinTempUnitDropDown.Enable = false;
            app.BCxMinHeatFluxLabel.Enable = false;
            app.BCxMinHeatFluxEditField.Enable = false;
            app.BCxMinHeatFluxUnitDropDown.Enable = false;
        end
        function enablexMaxBCInputs(app)
            app.BCxMaxPressureButton.Enable = true;
            app.BCxMaxPressureEditField.Enable = true;
            app.BCxMaxPressureUnitDropDown.Enable = true;
            app.BCxMaxFluxButton.Enable = true;
            app.BCxMaxFluxEditField.Enable = true;
            app.BCxMaxFluxUnitDropDown.Enable = true;
            app.BCxMaxTempLabel.Enable = true;
            app.BCxMaxTempEditField.Enable = true;
            app.BCxMaxTempUnitDropDown.Enable = true;
            app.BCxMaxHeatFluxLabel.Enable = true;
            app.BCxMaxHeatFluxEditField.Enable = true;
            app.BCxMaxHeatFluxUnitDropDown.Enable = true;
        end
        function disablexMaxBCInputs(app)
            app.BCxMaxPressureButton.Enable = false;
            app.BCxMaxPressureEditField.Enable = false;
            app.BCxMaxPressureUnitDropDown.Enable = false;
            app.BCxMaxFluxButton.Enable = false;
            app.BCxMaxFluxEditField.Enable = false;
            app.BCxMaxFluxUnitDropDown.Enable = false;
            app.BCxMaxTempLabel.Enable = false;
            app.BCxMaxTempEditField.Enable = false;
            app.BCxMaxTempUnitDropDown.Enable = false;
            app.BCxMaxHeatFluxLabel.Enable = false;
            app.BCxMaxHeatFluxEditField.Enable = false;
            app.BCxMaxHeatFluxUnitDropDown.Enable = false;
        end
        function enableyMinBCInputs(app)
            app.BCyMinPressureButton.Enable = true;
            app.BCyMinPressureEditField.Enable = true;
            app.BCyMinPressureUnitDropDown.Enable = true;
            app.BCyMinFluxButton.Enable = true;
            app.BCyMinFluxEditField.Enable = true;
            app.BCyMinFluxUnitDropDown.Enable = true;
            app.BCyMinTempLabel.Enable = true;
            app.BCyMinTempEditField.Enable = true;
            app.BCyMinTempUnitDropDown.Enable = true;
            app.BCyMinHeatFluxLabel.Enable = true;
            app.BCyMinHeatFluxEditField.Enable = true;
            app.BCyMinHeatFluxUnitDropDown.Enable = true;
        end
        function disableyMinBCInputs(app)
            app.BCyMinPressureButton.Enable = false;
            app.BCyMinPressureEditField.Enable = false;
            app.BCyMinPressureUnitDropDown.Enable = false;
            app.BCyMinFluxButton.Enable = false;
            app.BCyMinFluxEditField.Enable = false;
            app.BCyMinFluxUnitDropDown.Enable = false;
            app.BCyMinTempLabel.Enable = false;
            app.BCyMinTempEditField.Enable = false;
            app.BCyMinTempUnitDropDown.Enable = false;
            app.BCyMinHeatFluxLabel.Enable = false;
            app.BCyMinHeatFluxEditField.Enable = false;
            app.BCyMinHeatFluxUnitDropDown.Enable = false;
        end
        function enableyMaxBCInputs(app)
            app.BCyMaxPressureButton.Enable = true;
            app.BCyMaxPressureEditField.Enable = true;
            app.BCyMaxPressureUnitDropDown.Enable = true;
            app.BCyMaxFluxButton.Enable = true;
            app.BCyMaxFluxEditField.Enable = true;
            app.BCyMaxFluxUnitDropDown.Enable = true;
            app.BCyMaxTempLabel.Enable = true;
            app.BCyMaxTempEditField.Enable = true;
            app.BCyMaxTempUnitDropDown.Enable = true;
            app.BCyMaxHeatFluxLabel.Enable = true;
            app.BCyMaxHeatFluxEditField.Enable = true;
            app.BCyMaxHeatFluxUnitDropDown.Enable = true;
        end
        function disableyMaxBCInputs(app)
            app.BCyMaxPressureButton.Enable = false;
            app.BCyMaxPressureEditField.Enable = false;
            app.BCyMaxPressureUnitDropDown.Enable = false;
            app.BCyMaxFluxButton.Enable = false;
            app.BCyMaxFluxEditField.Enable = false;
            app.BCyMaxFluxUnitDropDown.Enable = false;
            app.BCyMaxTempLabel.Enable = false;
            app.BCyMaxTempEditField.Enable = false;
            app.BCyMaxTempUnitDropDown.Enable = false;
            app.BCyMaxHeatFluxLabel.Enable = false;
            app.BCyMaxHeatFluxEditField.Enable = false;
            app.BCyMaxHeatFluxUnitDropDown.Enable = false;
        end
        function enablezMinBCInputs(app)
            app.BCzMinPressureButton.Enable = true;
            app.BCzMinPressureEditField.Enable = true;
            app.BCzMinPressureUnitDropDown.Enable = true;
            app.BCzMinFluxButton.Enable = true;
            app.BCzMinFluxEditField.Enable = true;
            app.BCzMinFluxUnitDropDown.Enable = true;
            app.BCzMinTempLabel.Enable = true;
            app.BCzMinTempEditField.Enable = true;
            app.BCzMinTempUnitDropDown.Enable = true;
            app.BCzMinHeatFluxLabel.Enable = true;
            app.BCzMinHeatFluxEditField.Enable = true;
            app.BCzMinHeatFluxUnitDropDown.Enable = true;
        end
        function disablezMinBCInputs(app)
            app.BCzMinPressureButton.Enable = false;
            app.BCzMinPressureEditField.Enable = false;
            app.BCzMinPressureUnitDropDown.Enable = false;
            app.BCzMinFluxButton.Enable = false;
            app.BCzMinFluxEditField.Enable = false;
            app.BCzMinFluxUnitDropDown.Enable = false;
            app.BCzMinTempLabel.Enable = false;
            app.BCzMinTempEditField.Enable = false;
            app.BCzMinTempUnitDropDown.Enable = false;
            app.BCzMinHeatFluxLabel.Enable = false;
            app.BCzMinHeatFluxEditField.Enable = false;
            app.BCzMinHeatFluxUnitDropDown.Enable = false;
        end
        function enablezMaxBCInputs(app)
            app.BCzMaxPressureButton.Enable = true;
            app.BCzMaxPressureEditField.Enable = true;
            app.BCzMaxPressureUnitDropDown.Enable = true;
            app.BCzMaxFluxButton.Enable = true;
            app.BCzMaxFluxEditField.Enable = true;
            app.BCzMaxFluxUnitDropDown.Enable = true;
            app.BCzMaxTempLabel.Enable = true;
            app.BCzMaxTempEditField.Enable = true;
            app.BCzMaxTempUnitDropDown.Enable = true;
            app.BCzMaxHeatFluxLabel.Enable = true;
            app.BCzMaxHeatFluxEditField.Enable = true;
            app.BCzMaxHeatFluxUnitDropDown.Enable = true;
        end
        function disablezMaxBCInputs(app)
            app.BCzMaxPressureButton.Enable = false;
            app.BCzMaxPressureEditField.Enable = false;
            app.BCzMaxPressureUnitDropDown.Enable = false;
            app.BCzMaxFluxButton.Enable = false;
            app.BCzMaxFluxEditField.Enable = false;
            app.BCzMaxFluxUnitDropDown.Enable = false;
            app.BCzMaxTempLabel.Enable = false;
            app.BCzMaxTempEditField.Enable = false;
            app.BCzMaxTempUnitDropDown.Enable = false;
            app.BCzMaxHeatFluxLabel.Enable = false;
            app.BCzMaxHeatFluxEditField.Enable = false;
            app.BCzMaxHeatFluxUnitDropDown.Enable = false;
        end

        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%                  GUI Input Control Methods                  %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Methods controlling the use of intrinsic element user input
        % limitations (e.g. max/min values).

        function updateGridLayerLimits(app)
            app.GridLayerThicknessEditField.Limits = [1 app.GridNzEditField.Value];
            app.GridLayerPlanePointxEditField.Limits = [0 app.GridNxEditField.Value];
            app.GridLayerPlanePointyEditField.Limits = [0 app.GridNyEditField.Value];
            app.GridLayerPlanePointzEditField.Limits = [0 app.GridNzEditField.Value];
        end

        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%                     GUI Update Methods                      %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Methods used to live update GUI components
        
        % Preview
        function updateModelPreviewAxes(app)
            if ~app.PreviewCheckBox.Value
                app.showPreviewDisabledNotice();
                return;
            end
            switch app.GlobalTabGroup.SelectedTab
                case app.DomainTab
                    app.updateGrid();
                case app.RockTab
                    app.updateRock();
                case app.BCsTab
                    app.updateBCs();
                case app.WellsTab
                    app.updateWells();
                otherwise
                    app.clearPreview();
            end
        end

        function clearPreview(app)
            cla(app.ModelPreviewAxes, 'reset');
            text(app.ModelPreviewAxes, .3, .5, 'Preview not available.', 'FontSize', 20);
            axis(app.ModelPreviewAxes, "off");
        end

        function showPreviewDisabledNotice(app)
            cla(app.ModelPreviewAxes, 'reset');
            text(app.ModelPreviewAxes, .3, .5, 'Preview disabled.', 'FontSize', 20);
            axis(app.ModelPreviewAxes, "off");
        end
        
        % Model panel
        function updateModelPanel(app)
            % Number of cells
            app.TotelCellsEditField.Value = app.task.getNumberOfCells();

            % Layer enabled
            if app.task.params.grid.layer.enable
                app.turnLampOnOK(app.LayerEnabledLamp);
            else
                app.turnLampOff(app.LayerEnabledLamp);
            end
            % Gravity
            if app.task.params.fluid.gravity.enabled
                app.turnLampOnOK(app.GravityEnabledLamp);
            else
                app.turnLampOff(app.GravityEnabledLamp);
            end
            % EOS
            if app.task.params.fluid.useEOS
                app.turnLampOnOK(app.EOSEnabledLamp);
            else
                app.turnLampOff(app.EOSEnabledLamp);
            end
            % BCs
            if any(app.task.params.bc.enable)
                app.turnLampOff(app.BCsEnabledLamp);
            else
                app.turnLampOnOK(app.BCsEnabledLamp);
            end
            % Initial equilibrium
            if app.task.params.simulation.initializeWithEquilibrium
                app.turnLampOnOK(app.InitialEquilibriumEnabledLamp);
            else
                app.turnLampOff(app.InitialEquilibriumEnabledLamp);
            end
        end

        % Warnings panel
        function updateWarningsPanel(app)
            if app.task.getNumberOfCells > app.globalVars.GRID_SIZE_WARNING_CELL_NUMBER_THRESHOLD
                app.turnLampOnWarning(app.GridSizeWarningLamp)
            else
                app.turnLampOff(app.GridSizeWarningLamp)
            end
            if strcmp(app.oldTaskName, app.TaskNameEditField.Value) && ~app.TaskKeepExistingCheckBox.Value
                app.turnLampOnWarning(app.OverwritingTaskWarningLamp)
            else
                app.turnLampOff(app.OverwritingTaskWarningLamp)
            end
        end

        % Total PV
        function updateTotalPVField(app)
            if app.PreviewCheckBox.Value && ~isempty(app.task.model.grid) && ~isempty(app.task.model.rock)
                app.TotalPVEditField.Value = num2str(app.task.model.grid.getTotalPoreVolume(app.task.model.rock), '%.3e');
            else
                app.TotalPVEditField.Value = "NaN";
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%                  GUI Data Exchange Methods                  %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Methods used to bulk exchange data from GUI elements.

        function size = getGridSizeVector(app)
            x = app.GridSizexEditField.Value * mapUnitToMRST(app.GridSizexUnitDropDown.Value);
            y = app.GridSizeyEditField.Value * mapUnitToMRST(app.GridSizeyUnitDropDown.Value);
            z = app.GridSizezEditField.Value * mapUnitToMRST(app.GridSizezUnitDropDown.Value);
            size = [x y z];
        end

        function setGridSizeVector(app, vector)
            app.GridSizexEditField.Value = vector(1) * mapUnitToMRSTReverse(app.GridSizexUnitDropDown.Value);
            app.GridSizeyEditField.Value = vector(2) * mapUnitToMRSTReverse(app.GridSizeyUnitDropDown.Value);
            app.GridSizezEditField.Value = vector(3) * mapUnitToMRSTReverse(app.GridSizezUnitDropDown.Value);
        end

        function dims = getGridDimsVector(app)
            nx = app.GridNxEditField.Value; 
            ny = app.GridNyEditField.Value; 
            nz = app.GridNzEditField.Value;
            dims = [nx ny nz];
        end

        function setGridDimsVector(app, vector)
            app.GridNxEditField.Value = vector(1); 
            app.GridNyEditField.Value = vector(2); 
            app.GridNzEditField.Value = vector(3);
        end

        function point = getLayerPoint(app)
            x = app.GridLayerPlanePointxEditField.Value;
            y = app.GridLayerPlanePointyEditField.Value;
            z = app.GridLayerPlanePointzEditField.Value;
            point = [x y z];
        end

        function setLayerPoint(app, point)
            app.GridLayerPlanePointxEditField.Value = point(1);
            app.GridLayerPlanePointyEditField.Value = point(2);
            app.GridLayerPlanePointzEditField.Value = point(3);
        end

        function angles = getLayerAngles(app)
            roll = app.GridLayerRollEditField.Value;
            pitch = app.GridLayerPitchEditField.Value;
            yaw = app.GridLayerYawEditField.Value;
            angles = [roll pitch yaw];
        end

        function setLayerAngles(app, angles)
            app.GridLayerRollEditField.Value = angles(1);
            app.GridLayerPitchEditField.Value = angles(2);
            app.GridLayerYawEditField.Value = angles(3);
        end

        function vector = getGravityVector(app)
            gx = app.FluidGravityxEditField.Value * mapUnitToMRST(app.FluidGravityxUnitDropDown.Value);
            gy = app.FluidGravityyEditField.Value * mapUnitToMRST(app.FluidGravityyUnitDropDown.Value);
            gz = app.FluidGravityzEditField.Value * mapUnitToMRST(app.FluidGravityzUnitDropDown.Value);
            vector = [gx gy gz];
        end

        function setGravityVector(app, vector)
            app.FluidGravityxEditField.Value = vector(1) * mapUnitToMRSTReverse(app.FluidGravityxUnitDropDown.Value);
            app.FluidGravityyEditField.Value = vector(2) * mapUnitToMRSTReverse(app.FluidGravityyUnitDropDown.Value);
            app.FluidGravityzEditField.Value = vector(3) * mapUnitToMRSTReverse(app.FluidGravityzUnitDropDown.Value);
        end

        function active = getActiveBCs(app)
            active(1) = app.BCxMinCheckBox.Value;
            active(2) = app.BCxMaxCheckBox.Value;
            active(3) = app.BCyMinCheckBox.Value;
            active(4) = app.BCyMaxCheckBox.Value;
            active(5) = app.BCzMinCheckBox.Value;
            active(6) = app.BCzMaxCheckBox.Value;
        end

        function setActiveBCs(app, active)
            app.BCxMinCheckBox.Value = active(1);
            app.BCxMaxCheckBox.Value = active(2);
            app.BCyMinCheckBox.Value = active(3);
            app.BCyMaxCheckBox.Value = active(4);
            app.BCzMinCheckBox.Value = active(5);
            app.BCzMaxCheckBox.Value = active(6);
        end
        
        function pressure = getBCsPressure(app)
            pressure(1) = app.BCxMinPressureEditField.Value * mapUnitToMRST(app.BCxMinPressureUnitDropDown.Value);
            pressure(2) = app.BCxMaxPressureEditField.Value * mapUnitToMRST(app.BCxMaxPressureUnitDropDown.Value);
            pressure(3) = app.BCyMinPressureEditField.Value * mapUnitToMRST(app.BCyMinPressureUnitDropDown.Value);
            pressure(4) = app.BCyMaxPressureEditField.Value * mapUnitToMRST(app.BCyMaxPressureUnitDropDown.Value);
            pressure(5) = app.BCzMinPressureEditField.Value * mapUnitToMRST(app.BCzMinPressureUnitDropDown.Value);
            pressure(6) = app.BCzMaxPressureEditField.Value * mapUnitToMRST(app.BCzMaxPressureUnitDropDown.Value);
        end

        function setBCsPressure(app, pressure)
            app.BCxMinPressureEditField.Value = pressure(1) * mapUnitToMRSTReverse(app.BCxMinPressureUnitDropDown.Value);
            app.BCxMaxPressureEditField.Value = pressure(2) * mapUnitToMRSTReverse(app.BCxMaxPressureUnitDropDown.Value);
            app.BCyMinPressureEditField.Value = pressure(3) * mapUnitToMRSTReverse(app.BCyMinPressureUnitDropDown.Value);
            app.BCyMaxPressureEditField.Value = pressure(4) * mapUnitToMRSTReverse(app.BCyMaxPressureUnitDropDown.Value);
            app.BCzMinPressureEditField.Value = pressure(5) * mapUnitToMRSTReverse(app.BCzMinPressureUnitDropDown.Value);
            app.BCzMaxPressureEditField.Value = pressure(6) * mapUnitToMRSTReverse(app.BCzMaxPressureUnitDropDown.Value);
        end

        function flux = getBCsFlux(app)
            flux(1) = app.BCxMinFluxEditField.Value * mapUnitToMRST(app.BCxMinFluxUnitDropDown.Value);
            flux(2) = app.BCxMaxFluxEditField.Value * mapUnitToMRST(app.BCxMaxFluxUnitDropDown.Value);
            flux(3) = app.BCyMinFluxEditField.Value * mapUnitToMRST(app.BCyMinFluxUnitDropDown.Value);
            flux(4) = app.BCyMaxFluxEditField.Value * mapUnitToMRST(app.BCyMaxFluxUnitDropDown.Value);
            flux(5) = app.BCzMinFluxEditField.Value * mapUnitToMRST(app.BCzMinFluxUnitDropDown.Value);
            flux(6) = app.BCzMaxFluxEditField.Value * mapUnitToMRST(app.BCzMaxFluxUnitDropDown.Value);
        end

        function setBCsFlux(app, flux)
            app.BCxMinFluxEditField.Value = flux(1) * mapUnitToMRSTReverse(app.BCxMinFluxUnitDropDown.Value);
            app.BCxMaxFluxEditField.Value = flux(2) * mapUnitToMRSTReverse(app.BCxMaxFluxUnitDropDown.Value);
            app.BCyMinFluxEditField.Value = flux(3) * mapUnitToMRSTReverse(app.BCyMinFluxUnitDropDown.Value);
            app.BCyMaxFluxEditField.Value = flux(4) * mapUnitToMRSTReverse(app.BCyMaxFluxUnitDropDown.Value);
            app.BCzMinFluxEditField.Value = flux(5) * mapUnitToMRSTReverse(app.BCzMinFluxUnitDropDown.Value);
            app.BCzMaxFluxEditField.Value = flux(6) * mapUnitToMRSTReverse(app.BCzMaxFluxUnitDropDown.Value);
        end

        function temperature = getBCsTemperature(app)
            temperature(1) = temperature2kelvin(app.BCxMinTempEditField.Value, app.BCxMinTempUnitDropDown.Value);
            temperature(2) = temperature2kelvin(app.BCxMaxTempEditField.Value, app.BCxMaxTempUnitDropDown.Value);
            temperature(3) = temperature2kelvin(app.BCyMinTempEditField.Value, app.BCyMinTempUnitDropDown.Value);
            temperature(4) = temperature2kelvin(app.BCyMaxTempEditField.Value, app.BCyMaxTempUnitDropDown.Value);
            temperature(5) = temperature2kelvin(app.BCzMinTempEditField.Value, app.BCzMinTempUnitDropDown.Value);
            temperature(6) = temperature2kelvin(app.BCzMaxTempEditField.Value, app.BCzMaxTempUnitDropDown.Value);
        end

        function setBCsTemperature(app, temperature)
            app.BCxMinTempEditField.Value = kelvin2temperature(temperature(1), app.BCxMinTempUnitDropDown.Value);
            app.BCxMaxTempEditField.Value = kelvin2temperature(temperature(2), app.BCxMaxTempUnitDropDown.Value);
            app.BCyMinTempEditField.Value = kelvin2temperature(temperature(3), app.BCyMinTempUnitDropDown.Value);
            app.BCyMaxTempEditField.Value = kelvin2temperature(temperature(4), app.BCyMaxTempUnitDropDown.Value);
            app.BCzMinTempEditField.Value = kelvin2temperature(temperature(5), app.BCzMinTempUnitDropDown.Value);
            app.BCzMaxTempEditField.Value = kelvin2temperature(temperature(6), app.BCzMaxTempUnitDropDown.Value);
        end

        function hflux = getBCsHeatFlux(app)
            hflux(1) = app.BCxMinHeatFluxEditField.Value * mapUnitToMRST(app.BCxMinHeatFluxUnitDropDown.Value);
            hflux(2) = app.BCxMaxHeatFluxEditField.Value * mapUnitToMRST(app.BCxMaxHeatFluxUnitDropDown.Value);
            hflux(3) = app.BCyMinHeatFluxEditField.Value * mapUnitToMRST(app.BCyMinHeatFluxUnitDropDown.Value);
            hflux(4) = app.BCyMaxHeatFluxEditField.Value * mapUnitToMRST(app.BCyMaxHeatFluxUnitDropDown.Value);
            hflux(5) = app.BCzMinHeatFluxEditField.Value * mapUnitToMRST(app.BCzMinHeatFluxUnitDropDown.Value);
            hflux(6) = app.BCzMaxHeatFluxEditField.Value * mapUnitToMRST(app.BCzMaxHeatFluxUnitDropDown.Value);
        end

        function setBCsHeatFlux(app, hflux)
            app.BCxMinHeatFluxEditField.Value = hflux(1) * mapUnitToMRSTReverse(app.BCxMinHeatFluxUnitDropDown.Value);
            app.BCxMaxHeatFluxEditField.Value = hflux(2) * mapUnitToMRSTReverse(app.BCxMaxHeatFluxUnitDropDown.Value);
            app.BCyMinHeatFluxEditField.Value = hflux(3) * mapUnitToMRSTReverse(app.BCyMinHeatFluxUnitDropDown.Value);
            app.BCyMaxHeatFluxEditField.Value = hflux(4) * mapUnitToMRSTReverse(app.BCyMaxHeatFluxUnitDropDown.Value);
            app.BCzMinHeatFluxEditField.Value = hflux(5) * mapUnitToMRSTReverse(app.BCzMinHeatFluxUnitDropDown.Value);
            app.BCzMaxHeatFluxEditField.Value = hflux(6) * mapUnitToMRSTReverse(app.BCzMaxHeatFluxUnitDropDown.Value);
        end

        function point = getInjWellBottomPoint(app)
            point(1) = app.InjWellPointxEditField.Value;
            point(2) = app.InjWellPointyEditField.Value;
            point(3) = app.InjWellPointzEditField.Value;
        end

        function setInjWellBottomPoint(app, point)
            app.InjWellPointxEditField.Value = point(1);
            app.InjWellPointyEditField.Value = point(2);
            app.InjWellPointzEditField.Value = point(3);
        end

        function point = getProdWellBottomPoint(app)
            point(1) = app.ProdWellPointxEditField.Value;
            point(2) = app.ProdWellPointyEditField.Value;
            point(3) = app.ProdWellPointzEditField.Value;
        end

        function setProdWellBottomPoint(app, point)
            app.ProdWellPointxEditField.Value = point(1);
            app.ProdWellPointyEditField.Value = point(2);
            app.ProdWellPointzEditField.Value = point(3);
        end

        function config = getGlobalPorosityButtonConfig(app)
            switch(app.GlobalPorosityButtonGroup.SelectedObject)
                case app.GlobalPorosityUniformButton
                    config = 'uniform';
                case app.GlobalPorosityGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setGlobalPorosityButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.GlobalPorosityButtonGroup.SelectedObject = app.GlobalPorosityUniformButton;                    
                case 'gaussian'
                    app.GlobalPorosityButtonGroup.SelectedObject = app.GlobalPorosityGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
       
        function config = getLayerPorosityButtonConfig(app)
            switch(app.LayerPorosityButtonGroup.SelectedObject)
                case app.LayerPorosityUniformButton
                    config = 'uniform';
                case app.LayerPorosityGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setLayerPorosityButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.LayerPorosityButtonGroup.SelectedObject = app.LayerPorosityUniformButton;                    
                case 'gaussian'
                    app.LayerPorosityButtonGroup.SelectedObject = app.LayerPorosityGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
        
        function config = getGlobalPermButtonConfig(app)
            switch(app.GlobalPermButtonGroup.SelectedObject)
                case app.GlobalPermUniformButton
                    config = 'uniform';
                case app.GlobalPermGaussianButton
                    config = 'gaussian';
                case app.GlobalPermDirectionalButton
                    config = 'directional';
                otherwise
                    config = 'error';
            end
        end

        function setGlobalPermButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.GlobalPermButtonGroup.SelectedObject = app.GlobalPermUniformButton;                    
                case 'gaussian'
                    app.GlobalPermButtonGroup.SelectedObject = app.GlobalPermGaussianButton;
                case 'directional'
                    app.GlobalPermButtonGroup.SelectedObject = app.GlobalPermDirectionalButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
       
        function config = getLayerPermButtonConfig(app)
            switch(app.LayerPermButtonGroup.SelectedObject)
                case app.LayerPermUniformButton
                    config = 'uniform';
                case app.LayerPermGaussianButton
                    config = 'gaussian';
                case app.LayerPermDirectionalButton
                    config = 'directional';                  
                otherwise
                    config = 'error';
            end
        end

        function setLayerPermButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.LayerPermButtonGroup.SelectedObject = app.LayerPermUniformButton;                    
                case 'gaussian'
                    app.LayerPermButtonGroup.SelectedObject = app.LayerPermGaussianButton;
                case 'directional'
                    app.LayerPermButtonGroup.SelectedObject = app.LayerPermDirectionalButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
        
        function perm = getGlobalDirPerm(app)
            kx = app.GlobalPermDirxEditField.Value * mapUnitToMRST(app.GlobalPermDirxUnitDropDown.Value);
            ky = app.GlobalPermDiryEditField.Value * mapUnitToMRST(app.GlobalPermDiryUnitDropDown.Value);
            kz = app.GlobalPermDirzEditField.Value * mapUnitToMRST(app.GlobalPermDirzUnitDropDown.Value);
            perm = [kx ky kz];
        end

        function setGlobalDirPerm(app, perm)
            app.GlobalPermDirxEditField.Value = perm(1) * mapUnitToMRSTReverse(app.GlobalPermDirxUnitDropDown.Value);
            app.GlobalPermDiryEditField.Value = perm(2) * mapUnitToMRSTReverse(app.GlobalPermDiryUnitDropDown.Value);
            app.GlobalPermDirzEditField.Value = perm(3) * mapUnitToMRSTReverse(app.GlobalPermDirzUnitDropDown.Value);
        end

        function perm = getLayerDirPerm(app)
            kx = app.LayerPermDirxEditField.Value * mapUnitToMRST(app.LayerPermDirxUnitDropDown.Value);
            ky = app.LayerPermDiryEditField.Value * mapUnitToMRST(app.LayerPermDiryUnitDropDown.Value);
            kz = app.LayerPermDirzEditField.Value * mapUnitToMRST(app.LayerPermDirzUnitDropDown.Value);
            perm = [kx ky kz];
        end

        function setLayerDirPerm(app, perm)
            app.LayerPermDirxEditField.Value = perm(1) * mapUnitToMRSTReverse(app.LayerPermDirxUnitDropDown.Value);
            app.LayerPermDiryEditField.Value = perm(2) * mapUnitToMRSTReverse(app.LayerPermDiryUnitDropDown.Value);
            app.LayerPermDirzEditField.Value = perm(3) * mapUnitToMRSTReverse(app.LayerPermDirzUnitDropDown.Value);
        end
        

        function config = getGlobalDensityButtonConfig(app)
            switch(app.GlobalDensityButtonGroup.SelectedObject)
                case app.GlobalDensityUniformButton
                    config = 'uniform';
                case app.GlobalDensityGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setGlobalDensityButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.GlobalDensityButtonGroup.SelectedObject = app.GlobalDensityUniformButton;                    
                case 'gaussian'
                    app.GlobalDensityButtonGroup.SelectedObject = app.GlobalDensityGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
       
        function config = getLayerDensityButtonConfig(app)
            switch(app.LayerDensityButtonGroup.SelectedObject)
                case app.LayerDensityUniformButton
                    config = 'uniform';
                case app.LayerDensityGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setLayerDensityButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.LayerDensityButtonGroup.SelectedObject = app.LayerDensityUniformButton;                    
                case 'gaussian'
                    app.LayerDensityButtonGroup.SelectedObject = app.LayerDensityGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end

        function config = getGlobalCondButtonConfig(app)
            switch(app.GlobalCondButtonGroup.SelectedObject)
                case app.GlobalCondUniformButton
                    config = 'uniform';
                case app.GlobalCondGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setGlobalCondButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.GlobalCondButtonGroup.SelectedObject = app.GlobalCondUniformButton;                    
                case 'gaussian'
                    app.GlobalCondButtonGroup.SelectedObject = app.GlobalCondGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
       
        function config = getLayerCondButtonConfig(app)
            switch(app.LayerCondButtonGroup.SelectedObject)
                case app.LayerCondUniformButton
                    config = 'uniform';
                case app.LayerCondGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setLayerCondButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.LayerCondButtonGroup.SelectedObject = app.LayerCondUniformButton;                    
                case 'gaussian'
                    app.LayerCondButtonGroup.SelectedObject = app.LayerCondGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
        
        function config = getGlobalHeatButtonConfig(app)
            switch(app.GlobalHeatButtonGroup.SelectedObject)
                case app.GlobalHeatUniformButton
                    config = 'uniform';
                case app.GlobalHeatGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setGlobalHeatButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.GlobalHeatButtonGroup.SelectedObject = app.GlobalHeatUniformButton;                    
                case 'gaussian'
                    app.GlobalHeatButtonGroup.SelectedObject = app.GlobalHeatGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
       
        function config = getLayerHeatButtonConfig(app)
            switch(app.LayerHeatButtonGroup.SelectedObject)
                case app.LayerHeatUniformButton
                    config = 'uniform';
                case app.LayerHeatGaussianButton
                    config = 'gaussian';
                otherwise
                    config = 'error';
            end
        end

        function setLayerHeatButtonConfig(app, config)
            switch(config)
                case 'uniform' 
                    app.LayerHeatButtonGroup.SelectedObject = app.LayerHeatUniformButton;                    
                case 'gaussian'
                    app.LayerHeatButtonGroup.SelectedObject = app.LayerHeatGaussianButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
        
        function config = getRockVisualizationButtonConfig(app)
            switch app.RockVisualizationButtonGroup.SelectedObject
                case app.RockVisualizationPorosityButton
                    config = 'porosity';
                case app.RockVisualizationPermButton
                    config = 'permeability';
                case app.RockVisualizationDensityButton
                    config = 'density';    
                case app.RockVisualizationCondButton
                    config = 'thermal_cond';   
                case app.RockVisualizationHeatButton
                    config = 'heat_capacity'; 
                otherwise
                    config = 'error';
            end
        end

        function setRockVisualizationButtonConfig(app, config)
            switch config
                case 'porosity'
                    app.RockVisualizationButtonGroup.SelectedObject = app.RockVisualizationPorosityButton;
                case 'permeability'
                    app.RockVisualizationButtonGroup.SelectedObject = app.RockVisualizationPermButton;
                case 'density'
                    app.RockVisualizationButtonGroup.SelectedObject = app.RockVisualizationDensityButton;
                case 'thermal_cond'
                    app.RockVisualizationButtonGroup.SelectedObject = app.RockVisualizationCondButton;
                case 'heat_capacity'
                    app.RockVisualizationButtonGroup.SelectedObject = app.RockVisualizationHeatButton;
                otherwise
                    error('Invalid button group setting.');
            end
         end

        function config = getBCThermalButtonConfig(app)
            switch app.BCThermalButtonGroup.SelectedObject
                case app.BCThermalHeatFluxButton
                    config = 'heat_flux';
                case app.BCThermalTempButton
                    config = 'temperature';
                otherwise
                    config = 'error';
            end
        end

        function setBCThermalButtonConfig(app, config)
            switch config
                case 'heat_flux'
                    app.BCThermalButtonGroup.SelectedObject = app.BCThermalHeatFluxButton;
                case 'temperature'
                    app.BCThermalButtonGroup.SelectedObject = app.BCThermalTempButton;
                otherwise
                    error('Invalid button group setting.');
            end
         end

        function config = getBCVisualizationButtonConfig(app)
            switch app.BCVisualizationButtonGroup.SelectedObject
                case app.BCVisualizationHydraulicButton
                    config = 'hydraulic';
                case app.BCVisualizationThermButton
                    config = 'thermal';
                otherwise
                    config = 'error';
            end
        end

        function setBCVisualizationButtonConfig(app, config)
            switch config
                case 'hydraulic'
                    app.BCVisualizationButtonGroup.SelectedObject = app.BCVisualizationHydraulicButton;
                case 'thermal'
                    app.BCVisualizationButtonGroup.SelectedObject = app.BCVisualizationThermButton;
                otherwise
                    error('Invalid button group setting.');
            end
         end

        function config = getBCTypeButtonConfig(app)
            bgs = [app.BCxMinButtonGroup
                  app.BCxMaxButtonGroup
                  app.BCyMinButtonGroup
                  app.BCyMaxButtonGroup
                  app.BCzMinButtonGroup
                  app.BCzMaxButtonGroup
                  ];
            buttons = [app.BCxMinPressureButton app.BCxMinFluxButton
                       app.BCxMaxPressureButton app.BCxMaxFluxButton
                       app.BCyMinPressureButton app.BCyMinFluxButton
                       app.BCyMaxPressureButton app.BCyMaxFluxButton
                       app.BCzMinPressureButton app.BCzMinFluxButton
                       app.BCzMaxPressureButton app.BCzMaxFluxButton
                       ];
            config = cell(1,6);
            for i = 1:6
                switch bgs(i).SelectedObject
                    case buttons(i,1)
                        config(i) = {'pressure'};
                    case buttons(i,2)
                        config(i) = {'flux'};
                    otherwise
                        config(i) = {'error'};
                end
            end
        end

        function setBCTypeButtonConfig(app, config)
            bgs = [app.BCxMinButtonGroup
                  app.BCxMaxButtonGroup
                  app.BCyMinButtonGroup
                  app.BCyMaxButtonGroup
                  app.BCzMinButtonGroup
                  app.BCzMaxButtonGroup
                  ];
            buttons = [app.BCxMinPressureButton app.BCxMinFluxButton
                       app.BCxMaxPressureButton app.BCxMaxFluxButton
                       app.BCyMinPressureButton app.BCyMinFluxButton
                       app.BCyMaxPressureButton app.BCyMaxFluxButton
                       app.BCzMinPressureButton app.BCzMinFluxButton
                       app.BCzMaxPressureButton app.BCzMaxFluxButton
                       ];
            for i = 1:6
                switch config{i}
                    case 'pressure'
                        bgs(i).SelectedObject = buttons(i,1);
                    case 'flux'
                        bgs(i).SelectedObject = buttons(i,2);
                    otherwise
                        error('Invalid button group setting.');
                end
            end
        end
        
        function config = getInjWellOperatingCondButtonConfig(app)
            switch app.InjWellControlButtonGroup.SelectedObject
                case app.InjWellConstantBHPButton
                    config = 'bhp';
                case app.InjWellConstantRateButton
                    config = 'rate';
                otherwise
                    config = 'error';
            end   
        end

        function setInjWellOperatingCondButtonConfig(app, config)
            switch config
                case 'bhp'
                    app.InjWellControlButtonGroup.SelectedObject = app.InjWellConstantBHPButton;
                case 'rate'
                    app.InjWellControlButtonGroup.SelectedObject = app.InjWellConstantRateButton;
                otherwise
                    error('Invalid button group setting.');
            end  
        end

        function config = getProdWellOperatingCondButtonConfig(app)
            switch app.ProdWellControlButtonGroup.SelectedObject
                case app.ProdWellConstantBHPButton
                    config = 'bhp';
                case app.ProdWellConstantRateButton
                    config = 'rate';
                otherwise
                    config = 'error';
            end   
        end

        function setProdWellOperatingCondButtonConfig(app, config)
            switch config
                case 'bhp'
                    app.ProdWellControlButtonGroup.SelectedObject = app.ProdWellConstantBHPButton;
                case 'rate'
                    app.ProdWellControlButtonGroup.SelectedObject = app.ProdWellConstantRateButton;
                otherwise
                    error('Invalid button group setting.');
            end  
        end

        function config = getTimestepTypeConfig(app)
            switch app.TimestepTypeButtonGroup.SelectedObject
                case app.FixedTimestepsButton
                    config = 'fixed';
                case app.GeometricRampupButton
                    config = 'geometric_rampup';
                otherwise
                    config = 'error';
            end
        end

        function setTimestepTypeConfig(app, config)
            switch config
                case 'fixed'
                    app.TimestepTypeButtonGroup.SelectedObject = app.FixedTimestepsButton;
                case 'geometric_rampup'
                    app.TimestepTypeButtonGroup.SelectedObject = app.GeometricRampupButton;
                otherwise
                    error('Invalid button group setting.');
            end
        end
        
        function config = getPressureLimitsConfig(app)
            min = app.MinReservoirPressureEditField.Value*mapUnitToMRST(app.MinReservoirPressureUnitDropDown.Value);
            max = app.MaxReservoirPressureEditField.Value*mapUnitToMRST(app.MaxReservoirPressureUnitDropDown.Value);
            config = [min max];
        end

        function setPressureLimitsConfig(app, config)
            app.MinReservoirPressureEditField.Value = config(1)*mapUnitToMRSTReverse(app.MinReservoirPressureUnitDropDown.Value);
            app.MaxReservoirPressureEditField.Value = config(2)*mapUnitToMRSTReverse(app.MaxReservoirPressureUnitDropDown.Value);
        end

        function config = getTempLimitsConfig(app)
            min = temperature2kelvin(app.MinReservoirTempEditField.Value, app.MinReservoirTempUnitDropDown.Value);
            max = temperature2kelvin(app.MaxReservoirTempEditField.Value, app.MaxReservoirTempUnitDropDown.Value);
            config = [min max];
        end

        function setTempLimitsConfig(app, config)
            app.MinReservoirTempEditField.Value = kelvin2temperature(config(1), app.MinReservoirTempUnitDropDown.Value);
            app.MaxReservoirTempEditField.Value = kelvin2temperature(config(2), app.MaxReservoirTempUnitDropDown.Value);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%                    Model Update Methods                     %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Methods used for updating parts of the the model for the purpose
        % of generating previews. 
        
        function updateGrid(app)
            app.updateTaskParams();
            app.updateWarningsPanel();
            app.updateModelPanel();
            if ~app.PreviewCheckBox.Value
                return
            end            
            totalSteps = 3;

            % Build grid
            waitDialog = uiprogressdlg(app.GraphicalTaskBuilderUIFigure, 'Title', 'Please wait', 'Message', 'Building grid...');
            app.task = app.task.buildGrid();
            waitDialog.Value = 1/totalSteps;
            
            % Build layer
            waitDialog.Message = "Building layer...";
            app.task = app.task.buildLayer();
            waitDialog.Value = 2/totalSteps;

            % Visualize grid
            waitDialog.Message = "Visualizing grid...";
            app.task.visualizeGrid(app.ModelPreviewAxes);      
            app.updateModelPanel();
            waitDialog.Value = 3/totalSteps;

            waitDialog.Message = "Done.";
            close(waitDialog);            
        end

        function updateRock(app)
            app.updateTaskParams();
            if ~app.PreviewCheckBox.Value
                return
            end
            
            totalSteps = 4;

            % Build grid
            waitDialog = uiprogressdlg(app.GraphicalTaskBuilderUIFigure, 'Title', 'Please wait', 'Message', 'Building grid...');
            app.task = app.task.buildGrid();
            waitDialog.Value = 1/totalSteps;

            % Build layer
            waitDialog.Message = "Building layer...";
            app.task = app.task.buildLayer();
            waitDialog.Value = 2/totalSteps;

            % Build rock
            waitDialog.Message = "Building rock...";
            app.task = app.task.buildRock();
            waitDialog.Value = 3/totalSteps;

            % Visualizing rock properties
            waitDialog.Message = "Drawing visualization...";
            app.task.visualizeRock(app.ModelPreviewAxes);
            waitDialog.Value = 4/totalSteps;
            
            waitDialog.Message = "Done.";
            close(waitDialog);

            app.updateTotalPVField(); % Update total PV field
        end

        function updateWells(app)
            app.updateTaskParams();
            if ~app.PreviewCheckBox.Value
                return
            end
            
            totalSteps = 5;

            % Build grid
            waitDialog = uiprogressdlg(app.GraphicalTaskBuilderUIFigure, 'Title', 'Please wait', 'Message', 'Building grid...');
            app.task = app.task.buildGrid();
            waitDialog.Value = 1/totalSteps;
            
            % Build layer
            waitDialog.Message = "Building layer...";
            app.task = app.task.buildLayer();
            waitDialog.Value = 2/totalSteps;

            % Build rock
            waitDialog.Message = "Building rock...";
            app.task = app.task.buildRock();
            waitDialog.Value = 3/totalSteps;

            % Build wells
            waitDialog.Message = "Building wells...";
            app.task = app.task.buildWells();
            waitDialog.Value = 4/totalSteps;

            % Visualize wells
            waitDialog.Message = "Visualizing wells...";
            app.task.visualizeWells(app.ModelPreviewAxes);
            waitDialog.Value = 5/totalSteps;
            
            waitDialog.Message = "Done.";
            close(waitDialog);

        end

        function updateBCs(app)
            app.updateTaskParams();
            app.updateModelPanel();
            if ~app.PreviewCheckBox.Value
                return
            end
            
            totalSteps = 3;
            
            % Build grid
            waitDialog = uiprogressdlg(app.GraphicalTaskBuilderUIFigure, 'Title', 'Please wait', 'Message', 'Building grid...');
            app.task = app.task.buildGrid();
            waitDialog.Value = 1/totalSteps;


            % Build BCs
            waitDialog.Message = "Building BCs...";
            app.task = app.task.buildBCs();
            waitDialog.Value = 2/totalSteps;

            % Visualize BCs
            waitDialog.Message = "Visualizing BCs...";
            app.task.visualizeBCs(app.ModelPreviewAxes);            
            waitDialog.Value = 3/totalSteps;
            
            waitDialog.Message = "Done.";
            close(waitDialog);
        end

        function updateFluid(app)
            app.updateTaskParams();
            app.updateModelPanel();
        end

        function updateReservoir(app)
            app.updateTaskParams();
            app.updateModelPanel();
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, task)
            arguments
                app TaskBuilder
                callingApp DeepGeoDoublet
                task = [];
            end

            % Calling app 
            app.callingApp = callingApp;
            app.callingApp.hideApp();

            % Load global veriables and constants
            app.globalVars = callingApp.globalVars;
            app.globalConsts = callingApp.globalConsts;
            
            % Load task
            if isempty(task)
                app.task = Task(app.globalVars);
                app.oldTaskName = '';
            else
                app.task = task;
                app.oldTaskName = app.task.generic.name;
            end
            app.loadTaskParams();

            % Set version and copyright notice
            labelStr = sprintf("Version %s\nCopyright (C) %s %s", ...
                app.globalConsts.VERSION, ...
                app.globalConsts.YEAR, ...
                app.globalConsts.AUTHOR_LASTNAME);
            app.InfoLabel.Text = labelStr;
            
            % Initialize model preview
            app.updateModelPreviewAxes();

            % Initialize model and warnings panel
            app.updateModelPanel();
            app.updateWarningsPanel();

            % Update GUI visibility
            app.updateGUIVisibility();
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.closeAppPrompt();
        end

        % Close request function: GraphicalTaskBuilderUIFigure
        function GraphicalTaskBuilderUIFigureCloseRequest(app, event)
            app.closeAppPrompt();            
        end

        % Value changed function: GridNxEditField
        function GridNxEditFieldValueChanged(app, event)
            app.updateGridLayerLimits();
            app.updateGrid();
        end

        % Value changed function: GridNyEditField
        function GridNyEditFieldValueChanged(app, event)
            app.updateGridLayerLimits();
            app.updateGrid();
        end

        % Value changed function: GridNzEditField
        function GridNzEditFieldValueChanged(app, event)
            app.updateGridLayerLimits();
            app.updateGrid();
        end

        % Value changed function: GridSizezEditField
        function GridSizezEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridSizeyEditField
        function GridSizeyEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridSizexEditField
        function GridSizexEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Callback function
        function InfoButtonPushed(app, event)
            license = fileread('LICENSE');
            app.infoDialog = InfoDialog(license);
        end

        % Value changed function: GridDepthEditField
        function GridDepthEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridSizexUnitDropDown
        function GridSizexUnitDropDownValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridSizeyUnitDropDown
        function GridSizeyUnitDropDownValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridSizezUnitDropDown
        function GridSizezUnitDropDownValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridDepthUnitDropDown
        function GridDepthUnitDropDownValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridLayerActiveCheckBox
        function GridLayerActiveCheckBoxValueChanged(app, event)
            app.updateDomainTabVisibility();
            app.updateGrid();
        end

        % Value changed function: PreviewCheckBox
        function PreviewCheckBoxValueChanged(app, event)
            app.updateModelPreviewAxes();
            app.updateTotalPVField();
        end

        % Value changed function: GridLayerPlanePointxEditField
        function GridLayerPlanePointxEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridLayerPlanePointyEditField
        function GridLayerPlanePointyEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridLayerPlanePointzEditField
        function GridLayerPlanePointzEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridLayerThicknessEditField
        function GridLayerThicknessEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridLayerRollEditField
        function GridLayerRollEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: GridLayerPitchEditField
        function GridLayerPitchEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Selection change function: GlobalTabGroup
        function GlobalTabGroupSelectionChanged(app, event)
            app.updateGUIVisibility();
            app.updateModelPreviewAxes();
        end

        % Value changed function: GlobalPorosityEditField
        function GlobalPorosityEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: GlobalPorosityButtonGroup
        function GlobalPorosityButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPorosityGaussianMaxEditField
        function GlobalPorosityGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPorosityGaussianMinEditField
        function GlobalPorosityGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPorosityGaussianStdEditField
        function GlobalPorosityGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: LayerPorosityButtonGroup
        function LayerPorosityButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GridLayerYawEditField
        function GridLayerYawEditFieldValueChanged(app, event)
            app.updateGrid();
        end

        % Value changed function: LayerPorosityEditField
        function LayerPorosityEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPorosityGaussianMinEditField
        function LayerPorosityGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPorosityGaussianMaxEditField
        function LayerPorosityGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPorosityGaussianStdEditField
        function LayerPorosityGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: VisualizeLayerOnlyCheckBox
        function VisualizeLayerOnlyCheckBoxValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: RockVisualizationButtonGroup
        function RockVisualizationButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: GlobalPermButtonGroup
        function GlobalPermButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermEditField
        function GlobalPermEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermGaussianMinEditField
        function GlobalPermGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermGaussianMaxEditField
        function GlobalPermGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermGaussianStdEditField
        function GlobalPermGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermDirxEditField
        function GlobalPermDirxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermDirzEditField
        function GlobalPermDirzEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Callback function
        function GlobalPermCKRadiusEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Callback function
        function GlobalPermCKTortuosityEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: LayerPermButtonGroup
        function LayerPermButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermEditField
        function LayerPermEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermGaussianMinEditField
        function LayerPermGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermGaussianMaxEditField
        function LayerPermGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermDirxEditField
        function LayerPermDirxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermGaussianStdEditField
        function LayerPermGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermDirzEditField
        function LayerPermDirzEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Callback function
        function LayerPermCKRadiusEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Callback function
        function LayerPermCKTortuosityEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: InjWellNameEditField
        function InjWellNameEditFieldValueChanged(app, event)
            app.updateWells();            
        end

        % Selection changed function: InjWellControlButtonGroup
        function InjWellControlButtonGroupSelectionChanged(app, event)
            app.updateWellsTabVisibility();
        end

        % Selection changed function: ProdWellControlButtonGroup
        function ProdWellControlButtonGroupSelectionChanged(app, event)
            app.updateWellsTabVisibility();
        end

        % Value changed function: InjWellPointxEditField
        function InjWellPointxEditFieldValueChanged(app, event)
            app.updateWells();
        end

        % Value changed function: InjWellPointyEditField
        function InjWellPointyEditFieldValueChanged(app, event)
            app.updateWells();
        end

        % Value changed function: InjWellPointzEditField
        function InjWellPointzEditFieldValueChanged(app, event)
            app.updateWells();
        end

        % Value changed function: ProdWellNameEditField
        function ProdWellNameEditFieldValueChanged(app, event)
            app.updateWells();
        end

        % Value changed function: ProdWellPointxEditField
        function ProdWellPointxEditFieldValueChanged(app, event)
            app.updateWells();
        end

        % Value changed function: ProdWellPointyEditField
        function ProdWellPointyEditFieldValueChanged(app, event)
            app.updateWells();
        end

        % Value changed function: ProdWellPointzEditField
        function ProdWellPointzEditFieldValueChanged(app, event)
            app.updateWells();
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            app.saveTask();
            app.closeApp();
        end

        % Value changed function: BCxMinCheckBox
        function BCxMinCheckBoxValueChanged(app, event)
            app.updateBCsTabVisibility();
            app.updateBCs();
        end

        % Value changed function: BCxMaxCheckBox
        function BCxMaxCheckBoxValueChanged(app, event)
            app.updateBCsTabVisibility();
            app.updateBCs();
        end

        % Value changed function: BCyMinCheckBox
        function BCyMinCheckBoxValueChanged(app, event)
            app.updateBCsTabVisibility();
            app.updateBCs();
        end

        % Value changed function: BCyMaxCheckBox
        function BCyMaxCheckBoxValueChanged(app, event)
            app.updateBCsTabVisibility();
            app.updateBCs();
        end

        % Value changed function: BCzMinCheckBox
        function BCzMinCheckBoxValueChanged(app, event)
            app.updateBCsTabVisibility();
            app.updateBCs();
        end

        % Value changed function: BCzMaxCheckBox
        function BCzMaxCheckBoxValueChanged(app, event)
            app.updateBCsTabVisibility();
            app.updateBCs();
        end

        % Selection changed function: BCxMinButtonGroup
        function BCxMinButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: BCxMaxButtonGroup
        function BCxMaxButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: BCyMinButtonGroup
        function BCyMinButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: BCyMaxButtonGroup
        function BCyMaxButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: BCzMinButtonGroup
        function BCzMinButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: BCzMaxButtonGroup
        function BCzMaxButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Callback function
        function BCThermButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Callback function
        function BCxMaxThermButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Callback function
        function BCyMinThermButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Callback function
        function BCyMaxThermButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Callback function
        function BCzMinThermButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Callback function
        function BCzMaxThermButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: BCThermalButtonGroup
        function BCThermalButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: BCVisualizationButtonGroup
        function BCVisualizationButtonGroupSelectionChanged(app, event)
            app.updateBCs();
        end

        % Selection changed function: GlobalDensityButtonGroup
        function GlobalDensityButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalDensityEditField
        function GlobalDensityEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalDensityUnitDropDown
        function GlobalDensityUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalDensityGaussianMinEditField
        function GlobalDensityGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalDensityGaussianMinUnitDropDown
        function GlobalDensityGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalDensityGaussianMaxEditField
        function GlobalDensityGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalDensityGaussianMaxUnitDropDown
        function GlobalDensityGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalDensityGaussianStdEditField
        function GlobalDensityGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: LayerDensityButtonGroup
        function LayerDensityButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerDensityEditField
        function LayerDensityEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerDensityUnitDropDown
        function LayerDensityUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerDensityGaussianMinEditField
        function LayerDensityGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerDensityGaussianMinUnitDropDown
        function LayerDensityGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerDensityGaussianMaxEditField
        function LayerDensityGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerDensityGaussianMaxUnitDropDown
        function LayerDensityGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerDensityGaussianStdEditField
        function LayerDensityGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: GlobalCondButtonGroup
        function GlobalCondButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalCondEditField
        function GlobalCondEditFieldValueChanged(app, event)
            app.updateRock(); 
        end

        % Value changed function: GlobalCondUnitDropDown
        function GlobalCondUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalCondGaussianMinEditField
        function GlobalCondGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalCondGaussianMinUnitDropDown
        function GlobalCondGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalCondGaussianMaxEditField
        function GlobalCondGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalCondGaussianMaxUnitDropDown
        function GlobalCondGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalCondGaussianStdEditField
        function GlobalCondGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: LayerCondButtonGroup
        function LayerCondButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerCondEditField
        function LayerCondEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerCondUnitDropDown
        function LayerCondUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerCondGaussianMinEditField
        function LayerCondGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerCondGaussianMinUnitDropDown
        function LayerCondGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerCondGaussianMaxEditField
        function LayerCondGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerCondGaussianMaxUnitDropDown
        function LayerCondGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerCondGaussianStdEditField
        function LayerCondGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: GlobalHeatButtonGroup
        function GlobalHeatButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalHeatEditField
        function GlobalHeatEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalHeatUnitDropDown
        function GlobalHeatUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalHeatGaussianMinEditField
        function GlobalHeatGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalHeatGaussianMinUnitDropDown
        function GlobalHeatGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalHeatGaussianMaxEditField
        function GlobalHeatGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalHeatGaussianMaxUnitDropDown
        function GlobalHeatGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalHeatGaussianStdEditField
        function GlobalHeatGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Selection changed function: LayerHeatButtonGroup
        function LayerHeatButtonGroupSelectionChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerHeatEditField
        function LayerHeatEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerHeatUnitDropDown
        function LayerHeatUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerHeatGaussianMinEditField
        function LayerHeatGaussianMinEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerHeatGaussianMinUnitDropDown
        function LayerHeatGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerHeatGaussianMaxEditField
        function LayerHeatGaussianMaxEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerHeatGaussianMaxUnitDropDown
        function LayerHeatGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerHeatGaussianStdEditField
        function LayerHeatGaussianStdEditFieldValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermUnitDropDown
        function GlobalPermUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermGaussianMinUnitDropDown
        function GlobalPermGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermGaussianMaxUnitDropDown
        function GlobalPermGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermDirxUnitDropDown
        function GlobalPermDirxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: GlobalPermDirzUnitDropDown
        function GlobalPermDirzUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Callback function
        function GlobalPermCKRadiusUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermUnitDropDown
        function LayerPermUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermGaussianMinUnitDropDown
        function LayerPermGaussianMinUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermGaussianMaxUnitDropDown
        function LayerPermGaussianMaxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermDirxUnitDropDown
        function LayerPermDirxUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: LayerPermDirzUnitDropDown
        function LayerPermDirzUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Callback function
        function LayerPermCKRadiusUnitDropDownValueChanged(app, event)
            app.updateRock();
        end

        % Value changed function: TaskNameEditField
        function TaskNameEditFieldValueChanged(app, event)
            app.TaskNameEditField.Value = regexprep(app.TaskNameEditField.Value ,'[^a-zA-Z0-9]',''); % Remove non-alphanumeric characters
            if isempty(app.TaskNameEditField.Value)
                app.TaskNameEditField.Value = app.globalVars.DEFAULT_TASK_NAME;
            end
            app.updateWarningsPanel();
            app.updateTaskTabVisibility();
        end

        % Value changed function: FluidGravityCheckBox
        function FluidGravityCheckBoxValueChanged(app, event)
            app.updateFluidTabVisibility();
            app.updateFluid();
        end

        % Value changed function: FluidEOSCheckBox
        function FluidEOSCheckBoxValueChanged(app, event)
            app.updateFluid();     
        end

        % Value changed function: InitializeWithEquilibriumCheckBox
        function InitializeWithEquilibriumCheckBoxValueChanged(app, event)
            app.updateReservoir();
        end

        % Value changed function: TaskKeepExistingCheckBox
        function TaskKeepExistingCheckBoxValueChanged(app, event)
            app.updateWarningsPanel();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphicalTaskBuilderUIFigure = uifigure('Visible', 'off');
            app.GraphicalTaskBuilderUIFigure.Name = 'Graphical Task Builder';
            app.GraphicalTaskBuilderUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraphicalTaskBuilderUIFigureCloseRequest, true);
            app.GlobalTabGroup = uitabgroup(app.GraphicalTaskBuilderUIFigure);
            app.GlobalTabGroup.SelectionChangedFcn = createCallbackFcn(app, @GlobalTabGroupSelectionChanged, true);
            app.GenericTab = uitab(app.GlobalTabGroup);
            app.GenericTab.Title = 'Generic';
            app.NameEditFieldLabel = uilabel(app.GenericTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.TaskNameEditField = uieditfield(app.GenericTab, 'text');
            app.TaskNameEditField.ValueChangedFcn = createCallbackFcn(app, @TaskNameEditFieldValueChanged, true);
            app.TaskNameEditField.Value = 'Task';
            app.CreatedEditFieldLabel = uilabel(app.GenericTab);
            app.CreatedEditFieldLabel.Text = 'Created';
            app.TaskCreatedEditField = uieditfield(app.GenericTab, 'text');
            app.TaskCreatedEditField.Editable = 'off';
            app.NotesTextAreaLabel = uilabel(app.GenericTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.TaskNotesTextArea = uitextarea(app.GenericTab);
            app.TaskKeepExistingCheckBox = uicheckbox(app.GenericTab);
            app.TaskKeepExistingCheckBox.ValueChangedFcn = createCallbackFcn(app, @TaskKeepExistingCheckBoxValueChanged, true);
            app.TaskKeepExistingCheckBox.Text = 'Keep existing Task';
            app.DomainTab = uitab(app.GlobalTabGroup);
            app.DomainTab.Title = 'Domain';
            app.GlobalGridSizePanel = uipanel(app.DomainTab);
            app.GlobalGridSizePanel.Title = 'Global Grid Size';
            app.GridSizezUnitDropDown = uidropdown(app.GlobalGridSizePanel);
            app.GridSizezUnitDropDown.Items = {'m', 'km', 'ft', 'mi'};
            app.GridSizezUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GridSizezUnitDropDownValueChanged, true);
            app.GridSizezUnitDropDown.Value = 'm';
            app.GridSizexUnitDropDown = uidropdown(app.GlobalGridSizePanel);
            app.GridSizexUnitDropDown.Items = {'m', 'km', 'ft', 'mi'};
            app.GridSizexUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GridSizexUnitDropDownValueChanged, true);
            app.GridSizexUnitDropDown.Value = 'm';
            app.GridSizeyUnitDropDown = uidropdown(app.GlobalGridSizePanel);
            app.GridSizeyUnitDropDown.Items = {'m', 'km', 'ft', 'mi'};
            app.GridSizeyUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GridSizeyUnitDropDownValueChanged, true);
            app.GridSizeyUnitDropDown.Value = 'm';
            app.GridDepthUnitDropDown = uidropdown(app.GlobalGridSizePanel);
            app.GridDepthUnitDropDown.Items = {'m', 'km', 'ft', 'mi'};
            app.GridDepthUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GridDepthUnitDropDownValueChanged, true);
            app.GridDepthUnitDropDown.Value = 'm';
            app.xLabel_4 = uilabel(app.GlobalGridSizePanel);
            app.xLabel_4.Text = 'x';
            app.GridSizexEditField = uieditfield(app.GlobalGridSizePanel, 'numeric');
            app.GridSizexEditField.Limits = [0 Inf];
            app.GridSizexEditField.RoundFractionalValues = 'on';
            app.GridSizexEditField.ValueChangedFcn = createCallbackFcn(app, @GridSizexEditFieldValueChanged, true);
            app.GridSizexEditField.Value = 100;
            app.xLabel_5 = uilabel(app.GlobalGridSizePanel);
            app.xLabel_5.Text = 'y';
            app.GridSizeyEditField = uieditfield(app.GlobalGridSizePanel, 'numeric');
            app.GridSizeyEditField.Limits = [0 Inf];
            app.GridSizeyEditField.RoundFractionalValues = 'on';
            app.GridSizeyEditField.ValueChangedFcn = createCallbackFcn(app, @GridSizeyEditFieldValueChanged, true);
            app.GridSizeyEditField.Value = 100;
            app.xLabel_6 = uilabel(app.GlobalGridSizePanel);
            app.xLabel_6.Text = 'z';
            app.GridSizezEditField = uieditfield(app.GlobalGridSizePanel, 'numeric');
            app.GridSizezEditField.Limits = [0 Inf];
            app.GridSizezEditField.RoundFractionalValues = 'on';
            app.GridSizezEditField.ValueChangedFcn = createCallbackFcn(app, @GridSizezEditFieldValueChanged, true);
            app.GridSizezEditField.Value = 100;
            app.xLabel_7 = uilabel(app.GlobalGridSizePanel);
            app.xLabel_7.Text = 'Depth';
            app.GridDepthEditField = uieditfield(app.GlobalGridSizePanel, 'numeric');
            app.GridDepthEditField.Limits = [0 Inf];
            app.GridDepthEditField.RoundFractionalValues = 'on';
            app.GridDepthEditField.ValueChangedFcn = createCallbackFcn(app, @GridDepthEditFieldValueChanged, true);
            app.GridDepthEditField.Tooltip = {'Depth of uppermost grid layer'};
            app.GridLayerPanel = uipanel(app.DomainTab);
            app.GridLayerPanel.Title = 'Layer';
            app.GridLayerActiveCheckBox = uicheckbox(app.GridLayerPanel);
            app.GridLayerActiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridLayerActiveCheckBoxValueChanged, true);
            app.GridLayerActiveCheckBox.Text = 'Active';
            app.GridLayerPropertiesPanel = uipanel(app.GridLayerPanel);
            app.xLabel_8 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_8.Text = 'Thickness (cells)';
            app.xLabel_9 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_9.Tooltip = {'Arbitrary point within domain used to specify base plane. The coordinates have to be Cartesian cell coordinates relative to the first node at (0,0,0).'};
            app.xLabel_9.Text = 'x';
            app.xLabel_10 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_10.Text = 'Roll (x)';
            app.xLabel_12 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_12.Text = '°';
            app.xLabel_11 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_11.Text = 'Pitch (y)';
            app.xLabel_13 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_13.Text = '°';
            app.PlanePointLabel = uilabel(app.GridLayerPropertiesPanel);
            app.PlanePointLabel.Tooltip = {'Arbitrary point within domain used to specify base plane. The coordinates have to be Cartesian cell coordinates relative to the first node at (0,0,0).'};
            app.PlanePointLabel.Text = 'Plane Point';
            app.GridLayerYawEditField = uieditfield(app.GridLayerPropertiesPanel, 'numeric');
            app.GridLayerYawEditField.RoundFractionalValues = 'on';
            app.GridLayerYawEditField.ValueChangedFcn = createCallbackFcn(app, @GridLayerYawEditFieldValueChanged, true);
            app.GridLayerYawEditField.Tooltip = {''};
            app.GridLayerPitchEditField = uieditfield(app.GridLayerPropertiesPanel, 'numeric');
            app.GridLayerPitchEditField.RoundFractionalValues = 'on';
            app.GridLayerPitchEditField.ValueChangedFcn = createCallbackFcn(app, @GridLayerPitchEditFieldValueChanged, true);
            app.GridLayerPitchEditField.Tooltip = {''};
            app.GridLayerRollEditField = uieditfield(app.GridLayerPropertiesPanel, 'numeric');
            app.GridLayerRollEditField.RoundFractionalValues = 'on';
            app.GridLayerRollEditField.ValueChangedFcn = createCallbackFcn(app, @GridLayerRollEditFieldValueChanged, true);
            app.GridLayerRollEditField.Tooltip = {''};
            app.xLabel_14 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_14.Tooltip = {'Arbitrary point within domain used to specify base plane. The coordinates have to be Cartesian cell coordinates relative to the first node at (0,0,0).'};
            app.xLabel_14.Text = 'y';
            app.xLabel_15 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_15.Tooltip = {'Arbitrary point within domain used to specify base plane. The coordinates have to be Cartesian cell coordinates relative to the first node at (0,0,0).'};
            app.xLabel_15.Text = 'z';
            app.GridLayerPlanePointzEditField = uieditfield(app.GridLayerPropertiesPanel, 'numeric');
            app.GridLayerPlanePointzEditField.Limits = [0 Inf];
            app.GridLayerPlanePointzEditField.ValueChangedFcn = createCallbackFcn(app, @GridLayerPlanePointzEditFieldValueChanged, true);
            app.GridLayerPlanePointzEditField.Tooltip = {'Arbitrary point within domain used to specify base plane. The coordinates have to be Cartesian cell coordinates relative to the first node at (0,0,0).'};
            app.GridLayerPlanePointyEditField = uieditfield(app.GridLayerPropertiesPanel, 'numeric');
            app.GridLayerPlanePointyEditField.Limits = [0 Inf];
            app.GridLayerPlanePointyEditField.ValueChangedFcn = createCallbackFcn(app, @GridLayerPlanePointyEditFieldValueChanged, true);
            app.GridLayerPlanePointyEditField.Tooltip = {'Arbitrary point within domain used to specify base plane. The coordinates have to be Cartesian cell coordinates relative to the first node at (0,0,0).'};
            app.GridLayerPlanePointxEditField = uieditfield(app.GridLayerPropertiesPanel, 'numeric');
            app.GridLayerPlanePointxEditField.Limits = [0 Inf];
            app.GridLayerPlanePointxEditField.ValueChangedFcn = createCallbackFcn(app, @GridLayerPlanePointxEditFieldValueChanged, true);
            app.GridLayerPlanePointxEditField.Tooltip = {'Arbitrary point within domain used to specify base plane. The coordinates have to be Cartesian cell coordinates relative to the first node at (0,0,0).'};
            app.GridLayerThicknessEditField = uieditfield(app.GridLayerPropertiesPanel, 'numeric');
            app.GridLayerThicknessEditField.Limits = [1 Inf];
            app.GridLayerThicknessEditField.RoundFractionalValues = 'on';
            app.GridLayerThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @GridLayerThicknessEditFieldValueChanged, true);
            app.GridLayerThicknessEditField.Tooltip = {''};
            app.GridLayerThicknessEditField.Value = 1;
            app.xLabel_16 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_16.Text = '°';
            app.xLabel_17 = uilabel(app.GridLayerPropertiesPanel);
            app.xLabel_17.Text = 'Yaw (z)';
            app.GlobalcellsPanel = uipanel(app.DomainTab);
            app.GlobalcellsPanel.Title = 'Global cells';
            app.GridNxEditField = uieditfield(app.GlobalcellsPanel, 'numeric');
            app.GridNxEditField.Limits = [10 Inf];
            app.GridNxEditField.RoundFractionalValues = 'on';
            app.GridNxEditField.ValueChangedFcn = createCallbackFcn(app, @GridNxEditFieldValueChanged, true);
            app.GridNxEditField.Value = 10;
            app.xLabel = uilabel(app.GlobalcellsPanel);
            app.xLabel.Text = 'x';
            app.GridNyEditField = uieditfield(app.GlobalcellsPanel, 'numeric');
            app.GridNyEditField.Limits = [10 Inf];
            app.GridNyEditField.RoundFractionalValues = 'on';
            app.GridNyEditField.ValueChangedFcn = createCallbackFcn(app, @GridNyEditFieldValueChanged, true);
            app.GridNyEditField.Value = 10;
            app.xLabel_2 = uilabel(app.GlobalcellsPanel);
            app.xLabel_2.Text = 'y';
            app.GridNzEditField = uieditfield(app.GlobalcellsPanel, 'numeric');
            app.GridNzEditField.Limits = [1 Inf];
            app.GridNzEditField.RoundFractionalValues = 'on';
            app.GridNzEditField.ValueChangedFcn = createCallbackFcn(app, @GridNzEditFieldValueChanged, true);
            app.GridNzEditField.Value = 10;
            app.xLabel_3 = uilabel(app.GlobalcellsPanel);
            app.xLabel_3.Text = 'z';
            app.RockTab = uitab(app.GlobalTabGroup);
            app.RockTab.Title = 'Rock';
            app.VisualizationPanel = uipanel(app.RockTab);
            app.VisualizationPanel.Title = 'Visualization';
            app.RockVisualizationButtonGroup = uibuttongroup(app.VisualizationPanel);
            app.RockVisualizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RockVisualizationButtonGroupSelectionChanged, true);
            app.RockVisualizationPorosityButton = uiradiobutton(app.RockVisualizationButtonGroup);
            app.RockVisualizationPorosityButton.Text = 'Porosity';
            app.RockVisualizationPorosityButton.Value = true;
            app.RockVisualizationPermButton = uiradiobutton(app.RockVisualizationButtonGroup);
            app.RockVisualizationPermButton.Text = 'Permeability';
            app.RockVisualizationDensityButton = uiradiobutton(app.RockVisualizationButtonGroup);
            app.RockVisualizationDensityButton.Text = 'Density';
            app.RockVisualizationCondButton = uiradiobutton(app.RockVisualizationButtonGroup);
            app.RockVisualizationCondButton.Text = 'Thermal Conductivity';
            app.RockVisualizationHeatButton = uiradiobutton(app.RockVisualizationButtonGroup);
            app.RockVisualizationHeatButton.Text = 'Specific Heat Capacity';
            app.VisualizeLayerOnlyCheckBox = uicheckbox(app.VisualizationPanel);
            app.VisualizeLayerOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeLayerOnlyCheckBoxValueChanged, true);
            app.VisualizeLayerOnlyCheckBox.Text = 'Show layer only';
            app.TabGroup4 = uitabgroup(app.RockTab);
            app.PorosityTab = uitab(app.TabGroup4);
            app.PorosityTab.Title = 'Porosity';
            app.GlobalPorosityTabGroup = uitabgroup(app.PorosityTab);
            app.GlobalPorosityTab = uitab(app.GlobalPorosityTabGroup);
            app.GlobalPorosityTab.Title = 'Global';
            app.GlobalPorosityButtonGroup = uibuttongroup(app.GlobalPorosityTab);
            app.GlobalPorosityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GlobalPorosityButtonGroupSelectionChanged, true);
            app.GlobalPorosityUniformButton = uiradiobutton(app.GlobalPorosityButtonGroup);
            app.GlobalPorosityUniformButton.Text = 'Uniform';
            app.GlobalPorosityUniformButton.Value = true;
            app.GlobalPorosityGaussianButton = uiradiobutton(app.GlobalPorosityButtonGroup);
            app.GlobalPorosityGaussianButton.Text = 'Random Gaussian';
            app.PorosityLabel = uilabel(app.GlobalPorosityTab);
            app.PorosityLabel.Text = 'Porosity';
            app.GlobalPorosityEditField = uieditfield(app.GlobalPorosityTab, 'numeric');
            app.GlobalPorosityEditField.Limits = [0 1];
            app.GlobalPorosityEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPorosityEditFieldValueChanged, true);
            app.GlobalPorosityEditField.Value = 0.2;
            app.PorositymaxLabel_2 = uilabel(app.GlobalPorosityTab);
            app.PorositymaxLabel_2.Text = 'Porosity (max)';
            app.GlobalPorosityGaussianMaxEditField = uieditfield(app.GlobalPorosityTab, 'numeric');
            app.GlobalPorosityGaussianMaxEditField.Limits = [0 1];
            app.GlobalPorosityGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPorosityGaussianMaxEditFieldValueChanged, true);
            app.GlobalPorosityGaussianMaxEditField.Value = 0.2;
            app.PorosityminLabel = uilabel(app.GlobalPorosityTab);
            app.PorosityminLabel.Text = 'Porosity (min)';
            app.GlobalPorosityGaussianMinEditField = uieditfield(app.GlobalPorosityTab, 'numeric');
            app.GlobalPorosityGaussianMinEditField.Limits = [0 1];
            app.GlobalPorosityGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPorosityGaussianMinEditFieldValueChanged, true);
            app.GlobalPorosityGaussianMinEditField.Value = 0.1;
            app.StandardDeviationEditFieldLabel = uilabel(app.GlobalPorosityTab);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.GlobalPorosityGaussianStdEditField = uieditfield(app.GlobalPorosityTab, 'numeric');
            app.GlobalPorosityGaussianStdEditField.Limits = [0 Inf];
            app.GlobalPorosityGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPorosityGaussianStdEditFieldValueChanged, true);
            app.GlobalPorosityGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.GlobalPorosityGaussianStdEditField.Value = 0.5;
            app.SingleCalculationCheckBox = uicheckbox(app.GlobalPorosityTab);
            app.SingleCalculationCheckBox.Enable = 'off';
            app.SingleCalculationCheckBox.Text = 'Single Calculation';
            app.LayerPorosityTab = uitab(app.GlobalPorosityTabGroup);
            app.LayerPorosityTab.Title = 'Layer';
            app.LayerPorosityButtonGroup = uibuttongroup(app.LayerPorosityTab);
            app.LayerPorosityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LayerPorosityButtonGroupSelectionChanged, true);
            app.LayerPorosityUniformButton = uiradiobutton(app.LayerPorosityButtonGroup);
            app.LayerPorosityUniformButton.Text = 'Uniform';
            app.LayerPorosityUniformButton.Value = true;
            app.LayerPorosityGaussianButton = uiradiobutton(app.LayerPorosityButtonGroup);
            app.LayerPorosityGaussianButton.Text = 'Random Gaussian';
            app.phiEditField_2Label_2 = uilabel(app.LayerPorosityTab);
            app.phiEditField_2Label_2.Text = 'Porosity';
            app.LayerPorosityEditField = uieditfield(app.LayerPorosityTab, 'numeric');
            app.LayerPorosityEditField.Limits = [0 1];
            app.LayerPorosityEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPorosityEditFieldValueChanged, true);
            app.LayerPorosityEditField.Value = 0.2;
            app.PorositymaxLabel = uilabel(app.LayerPorosityTab);
            app.PorositymaxLabel.Text = 'Porosity (max)';
            app.LayerPorosityGaussianMaxEditField = uieditfield(app.LayerPorosityTab, 'numeric');
            app.LayerPorosityGaussianMaxEditField.Limits = [0 1];
            app.LayerPorosityGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPorosityGaussianMaxEditFieldValueChanged, true);
            app.LayerPorosityGaussianMaxEditField.Value = 0.2;
            app.PorsityminLabel = uilabel(app.LayerPorosityTab);
            app.PorsityminLabel.Text = 'Porosity (min)';
            app.LayerPorosityGaussianMinEditField = uieditfield(app.LayerPorosityTab, 'numeric');
            app.LayerPorosityGaussianMinEditField.Limits = [0 1];
            app.LayerPorosityGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPorosityGaussianMinEditFieldValueChanged, true);
            app.LayerPorosityGaussianMinEditField.Value = 0.1;
            app.StandardDeviationEditFieldLabel_2 = uilabel(app.LayerPorosityTab);
            app.StandardDeviationEditFieldLabel_2.Text = 'Standard Deviation';
            app.LayerPorosityGaussianStdEditField = uieditfield(app.LayerPorosityTab, 'numeric');
            app.LayerPorosityGaussianStdEditField.Limits = [0 Inf];
            app.LayerPorosityGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPorosityGaussianStdEditFieldValueChanged, true);
            app.LayerPorosityGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.LayerPorosityGaussianStdEditField.Value = 0.5;
            app.SingleCalculationCheckBox_6 = uicheckbox(app.LayerPorosityTab);
            app.SingleCalculationCheckBox_6.Enable = 'off';
            app.SingleCalculationCheckBox_6.Text = 'Single Calculation';
            app.PermeabilityTab = uitab(app.TabGroup4);
            app.PermeabilityTab.Title = 'Permeability';
            app.TabGroup3 = uitabgroup(app.PermeabilityTab);
            app.GlobalPermeabilityTab = uitab(app.TabGroup3);
            app.GlobalPermeabilityTab.Title = 'Global';
            app.GlobalPermButtonGroup = uibuttongroup(app.GlobalPermeabilityTab);
            app.GlobalPermButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GlobalPermButtonGroupSelectionChanged, true);
            app.GlobalPermUniformButton = uiradiobutton(app.GlobalPermButtonGroup);
            app.GlobalPermUniformButton.Text = 'Uniform';
            app.GlobalPermUniformButton.Value = true;
            app.GlobalPermGaussianButton = uiradiobutton(app.GlobalPermButtonGroup);
            app.GlobalPermGaussianButton.Text = 'Gaussian';
            app.GlobalPermDirectionalButton = uiradiobutton(app.GlobalPermButtonGroup);
            app.GlobalPermDirectionalButton.Text = 'Directional';
            app.phiEditField_2Label_3 = uilabel(app.GlobalPermeabilityTab);
            app.phiEditField_2Label_3.Text = 'K';
            app.GlobalPermEditField = uieditfield(app.GlobalPermeabilityTab, 'numeric');
            app.GlobalPermEditField.Limits = [0 Inf];
            app.GlobalPermEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPermEditFieldValueChanged, true);
            app.GlobalPermEditField.Value = 1;
            app.phiEditField_2Label_4 = uilabel(app.GlobalPermeabilityTab);
            app.phiEditField_2Label_4.Text = 'K_min';
            app.GlobalPermGaussianMinEditField = uieditfield(app.GlobalPermeabilityTab, 'numeric');
            app.GlobalPermGaussianMinEditField.Limits = [0 Inf];
            app.GlobalPermGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPermGaussianMinEditFieldValueChanged, true);
            app.GlobalPermGaussianMinEditField.Value = 1;
            app.phiEditField_2Label_5 = uilabel(app.GlobalPermeabilityTab);
            app.phiEditField_2Label_5.Text = 'K_max';
            app.GlobalPermGaussianMaxEditField = uieditfield(app.GlobalPermeabilityTab, 'numeric');
            app.GlobalPermGaussianMaxEditField.Limits = [0 Inf];
            app.GlobalPermGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPermGaussianMaxEditFieldValueChanged, true);
            app.GlobalPermGaussianMaxEditField.Value = 2;
            app.phiEditField_2Label_6 = uilabel(app.GlobalPermeabilityTab);
            app.phiEditField_2Label_6.Text = 'Standard Deviation';
            app.GlobalPermGaussianStdEditField = uieditfield(app.GlobalPermeabilityTab, 'numeric');
            app.GlobalPermGaussianStdEditField.Limits = [0 Inf];
            app.GlobalPermGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPermGaussianStdEditFieldValueChanged, true);
            app.GlobalPermGaussianStdEditField.Value = 0.5;
            app.phiEditField_2Label_7 = uilabel(app.GlobalPermeabilityTab);
            app.phiEditField_2Label_7.Text = 'K_x';
            app.GlobalPermDirxEditField = uieditfield(app.GlobalPermeabilityTab, 'numeric');
            app.GlobalPermDirxEditField.Limits = [0 Inf];
            app.GlobalPermDirxEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPermDirxEditFieldValueChanged, true);
            app.GlobalPermDirxEditField.Value = 1;
            app.phiEditField_2Label_8 = uilabel(app.GlobalPermeabilityTab);
            app.phiEditField_2Label_8.Text = 'K_z';
            app.GlobalPermDirzEditField = uieditfield(app.GlobalPermeabilityTab, 'numeric');
            app.GlobalPermDirzEditField.Limits = [0 Inf];
            app.GlobalPermDirzEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalPermDirzEditFieldValueChanged, true);
            app.GlobalPermDirzEditField.Value = 1;
            app.GlobalPermUnitDropDown = uidropdown(app.GlobalPermeabilityTab);
            app.GlobalPermUnitDropDown.Items = {'mD'};
            app.GlobalPermUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalPermUnitDropDownValueChanged, true);
            app.GlobalPermUnitDropDown.Value = 'mD';
            app.GlobalPermGaussianMinUnitDropDown = uidropdown(app.GlobalPermeabilityTab);
            app.GlobalPermGaussianMinUnitDropDown.Items = {'mD'};
            app.GlobalPermGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalPermGaussianMinUnitDropDownValueChanged, true);
            app.GlobalPermGaussianMinUnitDropDown.Value = 'mD';
            app.GlobalPermGaussianMaxUnitDropDown = uidropdown(app.GlobalPermeabilityTab);
            app.GlobalPermGaussianMaxUnitDropDown.Items = {'mD'};
            app.GlobalPermGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalPermGaussianMaxUnitDropDownValueChanged, true);
            app.GlobalPermGaussianMaxUnitDropDown.Value = 'mD';
            app.GlobalPermDirxUnitDropDown = uidropdown(app.GlobalPermeabilityTab);
            app.GlobalPermDirxUnitDropDown.Items = {'mD'};
            app.GlobalPermDirxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalPermDirxUnitDropDownValueChanged, true);
            app.GlobalPermDirxUnitDropDown.Value = 'mD';
            app.GlobalPermDirzUnitDropDown = uidropdown(app.GlobalPermeabilityTab);
            app.GlobalPermDirzUnitDropDown.Items = {'mD'};
            app.GlobalPermDirzUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalPermDirzUnitDropDownValueChanged, true);
            app.GlobalPermDirzUnitDropDown.Value = 'mD';
            app.GlobalPermGaussianSingleCalculationCheckBox = uicheckbox(app.GlobalPermeabilityTab);
            app.GlobalPermGaussianSingleCalculationCheckBox.Enable = 'off';
            app.GlobalPermGaussianSingleCalculationCheckBox.Text = 'Single Calculation';
            app.GlobalPermDiryUnitDropDown = uidropdown(app.GlobalPermeabilityTab);
            app.GlobalPermDiryUnitDropDown.Items = {'mD'};
            app.GlobalPermDiryUnitDropDown.Value = 'mD';
            app.phiEditField_2Label_42 = uilabel(app.GlobalPermeabilityTab);
            app.phiEditField_2Label_42.Text = 'K_y';
            app.GlobalPermDiryEditField = uieditfield(app.GlobalPermeabilityTab, 'numeric');
            app.GlobalPermDiryEditField.Limits = [0 Inf];
            app.GlobalPermDiryEditField.Value = 1;
            app.LayerPermeabilityTab = uitab(app.TabGroup3);
            app.LayerPermeabilityTab.Title = 'Layer';
            app.LayerPermButtonGroup = uibuttongroup(app.LayerPermeabilityTab);
            app.LayerPermButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LayerPermButtonGroupSelectionChanged, true);
            app.LayerPermUniformButton = uiradiobutton(app.LayerPermButtonGroup);
            app.LayerPermUniformButton.Text = 'Uniform';
            app.LayerPermUniformButton.Value = true;
            app.LayerPermGaussianButton = uiradiobutton(app.LayerPermButtonGroup);
            app.LayerPermGaussianButton.Text = 'Gaussian';
            app.LayerPermDirectionalButton = uiradiobutton(app.LayerPermButtonGroup);
            app.LayerPermDirectionalButton.Text = 'Directional';
            app.phiEditField_2Label_31 = uilabel(app.LayerPermeabilityTab);
            app.phiEditField_2Label_31.Text = 'K';
            app.LayerPermEditField = uieditfield(app.LayerPermeabilityTab, 'numeric');
            app.LayerPermEditField.Limits = [0 Inf];
            app.LayerPermEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPermEditFieldValueChanged, true);
            app.LayerPermEditField.Value = 1;
            app.phiEditField_2Label_32 = uilabel(app.LayerPermeabilityTab);
            app.phiEditField_2Label_32.Text = 'K_min';
            app.LayerPermGaussianMinEditField = uieditfield(app.LayerPermeabilityTab, 'numeric');
            app.LayerPermGaussianMinEditField.Limits = [0 Inf];
            app.LayerPermGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPermGaussianMinEditFieldValueChanged, true);
            app.LayerPermGaussianMinEditField.Value = 1;
            app.phiEditField_2Label_33 = uilabel(app.LayerPermeabilityTab);
            app.phiEditField_2Label_33.Text = 'K_max';
            app.LayerPermGaussianMaxEditField = uieditfield(app.LayerPermeabilityTab, 'numeric');
            app.LayerPermGaussianMaxEditField.Limits = [0 Inf];
            app.LayerPermGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPermGaussianMaxEditFieldValueChanged, true);
            app.LayerPermGaussianMaxEditField.Value = 2;
            app.phiEditField_2Label_34 = uilabel(app.LayerPermeabilityTab);
            app.phiEditField_2Label_34.Text = 'Standard Deviation';
            app.LayerPermGaussianStdEditField = uieditfield(app.LayerPermeabilityTab, 'numeric');
            app.LayerPermGaussianStdEditField.Limits = [0 Inf];
            app.LayerPermGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPermGaussianStdEditFieldValueChanged, true);
            app.LayerPermGaussianStdEditField.Value = 0.5;
            app.phiEditField_2Label_35 = uilabel(app.LayerPermeabilityTab);
            app.phiEditField_2Label_35.Text = 'K_x';
            app.LayerPermDirxEditField = uieditfield(app.LayerPermeabilityTab, 'numeric');
            app.LayerPermDirxEditField.Limits = [0 Inf];
            app.LayerPermDirxEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPermDirxEditFieldValueChanged, true);
            app.LayerPermDirxEditField.Value = 1;
            app.phiEditField_2Label_36 = uilabel(app.LayerPermeabilityTab);
            app.phiEditField_2Label_36.Text = 'K_z';
            app.LayerPermDirzEditField = uieditfield(app.LayerPermeabilityTab, 'numeric');
            app.LayerPermDirzEditField.Limits = [0 Inf];
            app.LayerPermDirzEditField.ValueChangedFcn = createCallbackFcn(app, @LayerPermDirzEditFieldValueChanged, true);
            app.LayerPermDirzEditField.Value = 1;
            app.LayerPermUnitDropDown = uidropdown(app.LayerPermeabilityTab);
            app.LayerPermUnitDropDown.Items = {'mD'};
            app.LayerPermUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerPermUnitDropDownValueChanged, true);
            app.LayerPermUnitDropDown.Value = 'mD';
            app.LayerPermGaussianMinUnitDropDown = uidropdown(app.LayerPermeabilityTab);
            app.LayerPermGaussianMinUnitDropDown.Items = {'mD'};
            app.LayerPermGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerPermGaussianMinUnitDropDownValueChanged, true);
            app.LayerPermGaussianMinUnitDropDown.Value = 'mD';
            app.LayerPermGaussianMaxUnitDropDown = uidropdown(app.LayerPermeabilityTab);
            app.LayerPermGaussianMaxUnitDropDown.Items = {'mD'};
            app.LayerPermGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerPermGaussianMaxUnitDropDownValueChanged, true);
            app.LayerPermGaussianMaxUnitDropDown.Value = 'mD';
            app.LayerPermDirxUnitDropDown = uidropdown(app.LayerPermeabilityTab);
            app.LayerPermDirxUnitDropDown.Items = {'mD'};
            app.LayerPermDirxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerPermDirxUnitDropDownValueChanged, true);
            app.LayerPermDirxUnitDropDown.Value = 'mD';
            app.LayerPermDirzUnitDropDown = uidropdown(app.LayerPermeabilityTab);
            app.LayerPermDirzUnitDropDown.Items = {'mD'};
            app.LayerPermDirzUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerPermDirzUnitDropDownValueChanged, true);
            app.LayerPermDirzUnitDropDown.Value = 'mD';
            app.LayerPermGaussianSingleCalculationCheckBox = uicheckbox(app.LayerPermeabilityTab);
            app.LayerPermGaussianSingleCalculationCheckBox.Enable = 'off';
            app.LayerPermGaussianSingleCalculationCheckBox.Text = 'Single Calculation';
            app.LayerPermDiryUnitDropDown = uidropdown(app.LayerPermeabilityTab);
            app.LayerPermDiryUnitDropDown.Items = {'mD'};
            app.LayerPermDiryUnitDropDown.Value = 'mD';
            app.phiEditField_2Label_43 = uilabel(app.LayerPermeabilityTab);
            app.phiEditField_2Label_43.Text = 'K_y';
            app.LayerPermDiryEditField = uieditfield(app.LayerPermeabilityTab, 'numeric');
            app.LayerPermDiryEditField.Limits = [0 Inf];
            app.LayerPermDiryEditField.Value = 1;
            app.DensityTab = uitab(app.TabGroup4);
            app.DensityTab.Title = 'Density';
            app.RockDensityTabGroup = uitabgroup(app.DensityTab);
            app.GlobalDensityTab = uitab(app.RockDensityTabGroup);
            app.GlobalDensityTab.Title = 'Global';
            app.GlobalDensityButtonGroup = uibuttongroup(app.GlobalDensityTab);
            app.GlobalDensityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GlobalDensityButtonGroupSelectionChanged, true);
            app.GlobalDensityUniformButton = uiradiobutton(app.GlobalDensityButtonGroup);
            app.GlobalDensityUniformButton.Text = 'Uniform';
            app.GlobalDensityUniformButton.Value = true;
            app.GlobalDensityGaussianButton = uiradiobutton(app.GlobalDensityButtonGroup);
            app.GlobalDensityGaussianButton.Text = 'Random Gaussian';
            app.PorosityLabel_2 = uilabel(app.GlobalDensityTab);
            app.PorosityLabel_2.Text = 'Density';
            app.GlobalDensityEditField = uieditfield(app.GlobalDensityTab, 'numeric');
            app.GlobalDensityEditField.Limits = [0 Inf];
            app.GlobalDensityEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalDensityEditFieldValueChanged, true);
            app.GlobalDensityEditField.Value = 2.7;
            app.PorositymaxLabel_3 = uilabel(app.GlobalDensityTab);
            app.PorositymaxLabel_3.Text = 'Density (max)';
            app.GlobalDensityGaussianMaxEditField = uieditfield(app.GlobalDensityTab, 'numeric');
            app.GlobalDensityGaussianMaxEditField.Limits = [0 Inf];
            app.GlobalDensityGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalDensityGaussianMaxEditFieldValueChanged, true);
            app.GlobalDensityGaussianMaxEditField.Value = 2.75;
            app.PorosityminLabel_2 = uilabel(app.GlobalDensityTab);
            app.PorosityminLabel_2.Text = 'Density (min)';
            app.GlobalDensityGaussianMinEditField = uieditfield(app.GlobalDensityTab, 'numeric');
            app.GlobalDensityGaussianMinEditField.Limits = [0 Inf];
            app.GlobalDensityGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalDensityGaussianMinEditFieldValueChanged, true);
            app.GlobalDensityGaussianMinEditField.Value = 2.65;
            app.StandardDeviationEditFieldLabel_3 = uilabel(app.GlobalDensityTab);
            app.StandardDeviationEditFieldLabel_3.Text = 'Standard Deviation';
            app.GlobalDensityGaussianStdEditField = uieditfield(app.GlobalDensityTab, 'numeric');
            app.GlobalDensityGaussianStdEditField.Limits = [0 Inf];
            app.GlobalDensityGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalDensityGaussianStdEditFieldValueChanged, true);
            app.GlobalDensityGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.GlobalDensityGaussianStdEditField.Value = 0.5;
            app.GlobalDensityUnitDropDown = uidropdown(app.GlobalDensityTab);
            app.GlobalDensityUnitDropDown.Items = {'kg/m³', 'g/cm³'};
            app.GlobalDensityUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalDensityUnitDropDownValueChanged, true);
            app.GlobalDensityUnitDropDown.Value = 'g/cm³';
            app.GlobalDensityGaussianMinUnitDropDown = uidropdown(app.GlobalDensityTab);
            app.GlobalDensityGaussianMinUnitDropDown.Items = {'kg/m³', 'g/cm³'};
            app.GlobalDensityGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalDensityGaussianMinUnitDropDownValueChanged, true);
            app.GlobalDensityGaussianMinUnitDropDown.Value = 'g/cm³';
            app.GlobalDensityGaussianMaxUnitDropDown = uidropdown(app.GlobalDensityTab);
            app.GlobalDensityGaussianMaxUnitDropDown.Items = {'kg/m³', 'g/cm³'};
            app.GlobalDensityGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalDensityGaussianMaxUnitDropDownValueChanged, true);
            app.GlobalDensityGaussianMaxUnitDropDown.Value = 'g/cm³';
            app.SingleCalculationCheckBox_2 = uicheckbox(app.GlobalDensityTab);
            app.SingleCalculationCheckBox_2.Enable = 'off';
            app.SingleCalculationCheckBox_2.Text = 'Single Calculation';
            app.LayerDensityTab = uitab(app.RockDensityTabGroup);
            app.LayerDensityTab.Title = 'Layer';
            app.LayerDensityButtonGroup = uibuttongroup(app.LayerDensityTab);
            app.LayerDensityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LayerDensityButtonGroupSelectionChanged, true);
            app.LayerDensityUniformButton = uiradiobutton(app.LayerDensityButtonGroup);
            app.LayerDensityUniformButton.Text = 'Uniform';
            app.LayerDensityUniformButton.Value = true;
            app.LayerDensityGaussianButton = uiradiobutton(app.LayerDensityButtonGroup);
            app.LayerDensityGaussianButton.Text = 'Random Gaussian';
            app.phiEditField_2Label_39 = uilabel(app.LayerDensityTab);
            app.phiEditField_2Label_39.Text = 'Density';
            app.LayerDensityEditField = uieditfield(app.LayerDensityTab, 'numeric');
            app.LayerDensityEditField.Limits = [0 Inf];
            app.LayerDensityEditField.ValueChangedFcn = createCallbackFcn(app, @LayerDensityEditFieldValueChanged, true);
            app.LayerDensityEditField.Value = 2.7;
            app.PorositymaxLabel_4 = uilabel(app.LayerDensityTab);
            app.PorositymaxLabel_4.Text = 'Density (max)';
            app.LayerDensityGaussianMaxEditField = uieditfield(app.LayerDensityTab, 'numeric');
            app.LayerDensityGaussianMaxEditField.Limits = [0 Inf];
            app.LayerDensityGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LayerDensityGaussianMaxEditFieldValueChanged, true);
            app.LayerDensityGaussianMaxEditField.Value = 2.75;
            app.PorsityminLabel_2 = uilabel(app.LayerDensityTab);
            app.PorsityminLabel_2.Text = 'Density (min)';
            app.LayerDensityGaussianMinEditField = uieditfield(app.LayerDensityTab, 'numeric');
            app.LayerDensityGaussianMinEditField.Limits = [0 Inf];
            app.LayerDensityGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @LayerDensityGaussianMinEditFieldValueChanged, true);
            app.LayerDensityGaussianMinEditField.Value = 2.65;
            app.StandardDeviationEditFieldLabel_4 = uilabel(app.LayerDensityTab);
            app.StandardDeviationEditFieldLabel_4.Text = 'Standard Deviation';
            app.LayerDensityGaussianStdEditField = uieditfield(app.LayerDensityTab, 'numeric');
            app.LayerDensityGaussianStdEditField.Limits = [0 Inf];
            app.LayerDensityGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @LayerDensityGaussianStdEditFieldValueChanged, true);
            app.LayerDensityGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.LayerDensityGaussianStdEditField.Value = 0.5;
            app.LayerDensityUnitDropDown = uidropdown(app.LayerDensityTab);
            app.LayerDensityUnitDropDown.Items = {'kg/m³', 'g/cm³'};
            app.LayerDensityUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerDensityUnitDropDownValueChanged, true);
            app.LayerDensityUnitDropDown.Value = 'g/cm³';
            app.LayerDensityGaussianMinUnitDropDown = uidropdown(app.LayerDensityTab);
            app.LayerDensityGaussianMinUnitDropDown.Items = {'kg/m³', 'g/cm³'};
            app.LayerDensityGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerDensityGaussianMinUnitDropDownValueChanged, true);
            app.LayerDensityGaussianMinUnitDropDown.Value = 'g/cm³';
            app.LayerDensityGaussianMaxUnitDropDown = uidropdown(app.LayerDensityTab);
            app.LayerDensityGaussianMaxUnitDropDown.Items = {'kg/m³', 'g/cm³'};
            app.LayerDensityGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerDensityGaussianMaxUnitDropDownValueChanged, true);
            app.LayerDensityGaussianMaxUnitDropDown.Value = 'g/cm³';
            app.SingleCalculationCheckBox_10 = uicheckbox(app.LayerDensityTab);
            app.SingleCalculationCheckBox_10.Enable = 'off';
            app.SingleCalculationCheckBox_10.Text = 'Single Calculation';
            app.ThermalConductivityTab = uitab(app.TabGroup4);
            app.ThermalConductivityTab.Title = 'Thermal Conductivity';
            app.RockCondTabGroup = uitabgroup(app.ThermalConductivityTab);
            app.GlobalCondTab = uitab(app.RockCondTabGroup);
            app.GlobalCondTab.Title = 'Global';
            app.GlobalCondButtonGroup = uibuttongroup(app.GlobalCondTab);
            app.GlobalCondButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GlobalCondButtonGroupSelectionChanged, true);
            app.GlobalCondUniformButton = uiradiobutton(app.GlobalCondButtonGroup);
            app.GlobalCondUniformButton.Text = 'Uniform';
            app.GlobalCondUniformButton.Value = true;
            app.GlobalCondGaussianButton = uiradiobutton(app.GlobalCondButtonGroup);
            app.GlobalCondGaussianButton.Text = 'Random Gaussian';
            app.PorosityLabel_3 = uilabel(app.GlobalCondTab);
            app.PorosityLabel_3.Text = 'Conductivity';
            app.GlobalCondEditField = uieditfield(app.GlobalCondTab, 'numeric');
            app.GlobalCondEditField.Limits = [0 Inf];
            app.GlobalCondEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalCondEditFieldValueChanged, true);
            app.GlobalCondEditField.Value = 2;
            app.PorositymaxLabel_5 = uilabel(app.GlobalCondTab);
            app.PorositymaxLabel_5.Text = 'Conductivity (max)';
            app.GlobalCondGaussianMaxEditField = uieditfield(app.GlobalCondTab, 'numeric');
            app.GlobalCondGaussianMaxEditField.Limits = [0 Inf];
            app.GlobalCondGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalCondGaussianMaxEditFieldValueChanged, true);
            app.GlobalCondGaussianMaxEditField.Value = 2.5;
            app.PorosityminLabel_3 = uilabel(app.GlobalCondTab);
            app.PorosityminLabel_3.Text = 'Conductivity (min)';
            app.GlobalCondGaussianMinEditField = uieditfield(app.GlobalCondTab, 'numeric');
            app.GlobalCondGaussianMinEditField.Limits = [0 Inf];
            app.GlobalCondGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalCondGaussianMinEditFieldValueChanged, true);
            app.GlobalCondGaussianMinEditField.Value = 1.5;
            app.StandardDeviationEditFieldLabel_5 = uilabel(app.GlobalCondTab);
            app.StandardDeviationEditFieldLabel_5.Text = 'Standard Deviation';
            app.GlobalCondGaussianStdEditField = uieditfield(app.GlobalCondTab, 'numeric');
            app.GlobalCondGaussianStdEditField.Limits = [0 Inf];
            app.GlobalCondGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalCondGaussianStdEditFieldValueChanged, true);
            app.GlobalCondGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.GlobalCondGaussianStdEditField.Value = 0.5;
            app.GlobalCondUnitDropDown = uidropdown(app.GlobalCondTab);
            app.GlobalCondUnitDropDown.Items = {'W/mK'};
            app.GlobalCondUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalCondUnitDropDownValueChanged, true);
            app.GlobalCondUnitDropDown.Value = 'W/mK';
            app.GlobalCondGaussianMinUnitDropDown = uidropdown(app.GlobalCondTab);
            app.GlobalCondGaussianMinUnitDropDown.Items = {'W/mK'};
            app.GlobalCondGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalCondGaussianMinUnitDropDownValueChanged, true);
            app.GlobalCondGaussianMinUnitDropDown.Value = 'W/mK';
            app.GlobalCondGaussianMaxUnitDropDown = uidropdown(app.GlobalCondTab);
            app.GlobalCondGaussianMaxUnitDropDown.Items = {'W/mK'};
            app.GlobalCondGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalCondGaussianMaxUnitDropDownValueChanged, true);
            app.GlobalCondGaussianMaxUnitDropDown.Value = 'W/mK';
            app.SingleCalculationCheckBox_3 = uicheckbox(app.GlobalCondTab);
            app.SingleCalculationCheckBox_3.Enable = 'off';
            app.SingleCalculationCheckBox_3.Text = 'Single Calculation';
            app.LayerCondTab = uitab(app.RockCondTabGroup);
            app.LayerCondTab.Title = 'Layer';
            app.LayerCondButtonGroup = uibuttongroup(app.LayerCondTab);
            app.LayerCondButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LayerCondButtonGroupSelectionChanged, true);
            app.LayerCondUniformButton = uiradiobutton(app.LayerCondButtonGroup);
            app.LayerCondUniformButton.Text = 'Uniform';
            app.LayerCondUniformButton.Value = true;
            app.LayerCondGaussianButton = uiradiobutton(app.LayerCondButtonGroup);
            app.LayerCondGaussianButton.Text = 'Random Gaussian';
            app.phiEditField_2Label_40 = uilabel(app.LayerCondTab);
            app.phiEditField_2Label_40.Text = 'Conductivity';
            app.LayerCondEditField = uieditfield(app.LayerCondTab, 'numeric');
            app.LayerCondEditField.Limits = [0 Inf];
            app.LayerCondEditField.ValueChangedFcn = createCallbackFcn(app, @LayerCondEditFieldValueChanged, true);
            app.LayerCondEditField.Value = 2;
            app.PorositymaxLabel_6 = uilabel(app.LayerCondTab);
            app.PorositymaxLabel_6.Text = 'Conductivity (max)';
            app.LayerCondGaussianMaxEditField = uieditfield(app.LayerCondTab, 'numeric');
            app.LayerCondGaussianMaxEditField.Limits = [0 Inf];
            app.LayerCondGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LayerCondGaussianMaxEditFieldValueChanged, true);
            app.LayerCondGaussianMaxEditField.Value = 2.5;
            app.PorsityminLabel_3 = uilabel(app.LayerCondTab);
            app.PorsityminLabel_3.Text = 'Conductivity (min)';
            app.LayerCondGaussianMinEditField = uieditfield(app.LayerCondTab, 'numeric');
            app.LayerCondGaussianMinEditField.Limits = [0 Inf];
            app.LayerCondGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @LayerCondGaussianMinEditFieldValueChanged, true);
            app.LayerCondGaussianMinEditField.Value = 1.5;
            app.StandardDeviationEditFieldLabel_6 = uilabel(app.LayerCondTab);
            app.StandardDeviationEditFieldLabel_6.Text = 'Standard Deviation';
            app.LayerCondGaussianStdEditField = uieditfield(app.LayerCondTab, 'numeric');
            app.LayerCondGaussianStdEditField.Limits = [0 Inf];
            app.LayerCondGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @LayerCondGaussianStdEditFieldValueChanged, true);
            app.LayerCondGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.LayerCondGaussianStdEditField.Value = 0.5;
            app.LayerCondUnitDropDown = uidropdown(app.LayerCondTab);
            app.LayerCondUnitDropDown.Items = {'W/mK'};
            app.LayerCondUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerCondUnitDropDownValueChanged, true);
            app.LayerCondUnitDropDown.Value = 'W/mK';
            app.LayerCondGaussianMinUnitDropDown = uidropdown(app.LayerCondTab);
            app.LayerCondGaussianMinUnitDropDown.Items = {'W/mK'};
            app.LayerCondGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerCondGaussianMinUnitDropDownValueChanged, true);
            app.LayerCondGaussianMinUnitDropDown.Value = 'W/mK';
            app.LayerCondGaussianMaxUnitDropDown = uidropdown(app.LayerCondTab);
            app.LayerCondGaussianMaxUnitDropDown.Items = {'W/mK'};
            app.LayerCondGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerCondGaussianMaxUnitDropDownValueChanged, true);
            app.LayerCondGaussianMaxUnitDropDown.Value = 'W/mK';
            app.SingleCalculationCheckBox_9 = uicheckbox(app.LayerCondTab);
            app.SingleCalculationCheckBox_9.Enable = 'off';
            app.SingleCalculationCheckBox_9.Text = 'Single Calculation';
            app.SpecificHeatCapacityTab = uitab(app.TabGroup4);
            app.SpecificHeatCapacityTab.Title = 'Specific Heat Capacity';
            app.RockHeatTabGroup = uitabgroup(app.SpecificHeatCapacityTab);
            app.GlobalHeatTab = uitab(app.RockHeatTabGroup);
            app.GlobalHeatTab.Title = 'Global';
            app.GlobalHeatButtonGroup = uibuttongroup(app.GlobalHeatTab);
            app.GlobalHeatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GlobalHeatButtonGroupSelectionChanged, true);
            app.GlobalHeatUniformButton = uiradiobutton(app.GlobalHeatButtonGroup);
            app.GlobalHeatUniformButton.Text = 'Uniform';
            app.GlobalHeatUniformButton.Value = true;
            app.GlobalHeatGaussianButton = uiradiobutton(app.GlobalHeatButtonGroup);
            app.GlobalHeatGaussianButton.Text = 'Random Gaussian';
            app.PorosityLabel_4 = uilabel(app.GlobalHeatTab);
            app.PorosityLabel_4.Text = 'Heat Capacity';
            app.GlobalHeatEditField = uieditfield(app.GlobalHeatTab, 'numeric');
            app.GlobalHeatEditField.Limits = [0 Inf];
            app.GlobalHeatEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalHeatEditFieldValueChanged, true);
            app.GlobalHeatEditField.Value = 1000;
            app.PorositymaxLabel_7 = uilabel(app.GlobalHeatTab);
            app.PorositymaxLabel_7.Text = 'Heat Capacity (max)';
            app.GlobalHeatGaussianMaxEditField = uieditfield(app.GlobalHeatTab, 'numeric');
            app.GlobalHeatGaussianMaxEditField.Limits = [0 Inf];
            app.GlobalHeatGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalHeatGaussianMaxEditFieldValueChanged, true);
            app.GlobalHeatGaussianMaxEditField.Value = 1050;
            app.PorosityminLabel_4 = uilabel(app.GlobalHeatTab);
            app.PorosityminLabel_4.Text = 'Heat Capacity (min)';
            app.GlobalHeatGaussianMinEditField = uieditfield(app.GlobalHeatTab, 'numeric');
            app.GlobalHeatGaussianMinEditField.Limits = [0 Inf];
            app.GlobalHeatGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalHeatGaussianMinEditFieldValueChanged, true);
            app.GlobalHeatGaussianMinEditField.Value = 950;
            app.StandardDeviationEditFieldLabel_7 = uilabel(app.GlobalHeatTab);
            app.StandardDeviationEditFieldLabel_7.Text = 'Standard Deviation';
            app.GlobalHeatGaussianStdEditField = uieditfield(app.GlobalHeatTab, 'numeric');
            app.GlobalHeatGaussianStdEditField.Limits = [0 Inf];
            app.GlobalHeatGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @GlobalHeatGaussianStdEditFieldValueChanged, true);
            app.GlobalHeatGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.GlobalHeatGaussianStdEditField.Value = 0.5;
            app.GlobalHeatUnitDropDown = uidropdown(app.GlobalHeatTab);
            app.GlobalHeatUnitDropDown.Items = {'J/kgK'};
            app.GlobalHeatUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalHeatUnitDropDownValueChanged, true);
            app.GlobalHeatUnitDropDown.Value = 'J/kgK';
            app.GlobalHeatGaussianMinUnitDropDown = uidropdown(app.GlobalHeatTab);
            app.GlobalHeatGaussianMinUnitDropDown.Items = {'J/kgK'};
            app.GlobalHeatGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalHeatGaussianMinUnitDropDownValueChanged, true);
            app.GlobalHeatGaussianMinUnitDropDown.Value = 'J/kgK';
            app.GlobalHeatGaussianMaxUnitDropDown = uidropdown(app.GlobalHeatTab);
            app.GlobalHeatGaussianMaxUnitDropDown.Items = {'J/kgK'};

            app.GlobalHeatGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @GlobalHeatGaussianMaxUnitDropDownValueChanged, true);
            app.GlobalHeatGaussianMaxUnitDropDown.Value = 'J/kgK';
            app.SingleCalculationCheckBox_4 = uicheckbox(app.GlobalHeatTab);
            app.SingleCalculationCheckBox_4.Enable = 'off';
            app.SingleCalculationCheckBox_4.Text = 'Single Calculation';
            app.LayerHeatTab = uitab(app.RockHeatTabGroup);
            app.LayerHeatTab.Title = 'Layer';
            app.LayerHeatButtonGroup = uibuttongroup(app.LayerHeatTab);
            app.LayerHeatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LayerHeatButtonGroupSelectionChanged, true);
            app.LayerHeatUniformButton = uiradiobutton(app.LayerHeatButtonGroup);
            app.LayerHeatUniformButton.Text = 'Uniform';
            app.LayerHeatUniformButton.Value = true;
            app.LayerHeatGaussianButton = uiradiobutton(app.LayerHeatButtonGroup);
            app.LayerHeatGaussianButton.Text = 'Random Gaussian';
            app.phiEditField_2Label_41 = uilabel(app.LayerHeatTab);
            app.phiEditField_2Label_41.Text = 'Heat Capacity';
            app.LayerHeatEditField = uieditfield(app.LayerHeatTab, 'numeric');
            app.LayerHeatEditField.Limits = [0 Inf];
            app.LayerHeatEditField.ValueChangedFcn = createCallbackFcn(app, @LayerHeatEditFieldValueChanged, true);
            app.LayerHeatEditField.Value = 1000;
            app.PorositymaxLabel_8 = uilabel(app.LayerHeatTab);
            app.PorositymaxLabel_8.Text = 'Heat Capacity (max)';
            app.LayerHeatGaussianMaxEditField = uieditfield(app.LayerHeatTab, 'numeric');
            app.LayerHeatGaussianMaxEditField.Limits = [0 Inf];
            app.LayerHeatGaussianMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LayerHeatGaussianMaxEditFieldValueChanged, true);
            app.LayerHeatGaussianMaxEditField.Value = 1050;
            app.PorsityminLabel_4 = uilabel(app.LayerHeatTab);
            app.PorsityminLabel_4.Text = 'Heat Capacity (min)';
            app.LayerHeatGaussianMinEditField = uieditfield(app.LayerHeatTab, 'numeric');
            app.LayerHeatGaussianMinEditField.Limits = [0 Inf];
            app.LayerHeatGaussianMinEditField.ValueChangedFcn = createCallbackFcn(app, @LayerHeatGaussianMinEditFieldValueChanged, true);
            app.LayerHeatGaussianMinEditField.Value = 950;
            app.StandardDeviationEditFieldLabel_8 = uilabel(app.LayerHeatTab);
            app.StandardDeviationEditFieldLabel_8.Text = 'Standard Deviation';
            app.LayerHeatGaussianStdEditField = uieditfield(app.LayerHeatTab, 'numeric');
            app.LayerHeatGaussianStdEditField.Limits = [0 Inf];
            app.LayerHeatGaussianStdEditField.ValueChangedFcn = createCallbackFcn(app, @LayerHeatGaussianStdEditFieldValueChanged, true);
            app.LayerHeatGaussianStdEditField.Tooltip = {'Standard deviation for Gaussian filter.'};
            app.LayerHeatGaussianStdEditField.Value = 0.5;
            app.LayerHeatUnitDropDown = uidropdown(app.LayerHeatTab);
            app.LayerHeatUnitDropDown.Items = {'J/kgK'};
            app.LayerHeatUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerHeatUnitDropDownValueChanged, true);
            app.LayerHeatUnitDropDown.Value = 'J/kgK';
            app.LayerHeatGaussianMinUnitDropDown = uidropdown(app.LayerHeatTab);
            app.LayerHeatGaussianMinUnitDropDown.Items = {'J/kgK'};
            app.LayerHeatGaussianMinUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerHeatGaussianMinUnitDropDownValueChanged, true);
            app.LayerHeatGaussianMinUnitDropDown.Value = 'J/kgK';
            app.LayerHeatGaussianMaxUnitDropDown = uidropdown(app.LayerHeatTab);
            app.LayerHeatGaussianMaxUnitDropDown.Items = {'J/kgK'};
            app.LayerHeatGaussianMaxUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerHeatGaussianMaxUnitDropDownValueChanged, true);
            app.LayerHeatGaussianMaxUnitDropDown.Value = 'J/kgK';
            app.SingleCalculationCheckBox_8 = uicheckbox(app.LayerHeatTab);
            app.SingleCalculationCheckBox_8.Enable = 'off';
            app.SingleCalculationCheckBox_8.Text = 'Single Calculation';
            app.TotalPVUnitLabel = uilabel(app.RockTab);
            app.TotalPVUnitLabel.Text = 'm³';
            app.PoreVolumeEditFieldLabel = uilabel(app.RockTab);
            app.PoreVolumeEditFieldLabel.Text = 'Pore Volume';
            app.TotalPVEditField = uieditfield(app.RockTab, 'text');
            app.TotalPVEditField.Editable = 'off';
            app.FluidTab = uitab(app.GlobalTabGroup);
            app.FluidTab.Title = 'Fluid';
            app.GravityPanel = uipanel(app.FluidTab);
            app.GravityPanel.Title = 'Gravity';
            app.FluidGravityxEditField = uieditfield(app.GravityPanel, 'numeric');
            app.FluidGravityxEditField.Limits = [0 Inf];
            app.xLabel_24 = uilabel(app.GravityPanel);
            app.xLabel_24.Text = 'gx';
            app.FluidGravityyEditField = uieditfield(app.GravityPanel, 'numeric');
            app.FluidGravityyEditField.Limits = [0 Inf];
            app.xLabel_25 = uilabel(app.GravityPanel);
            app.xLabel_25.Text = 'gy';
            app.FluidGravityzEditField = uieditfield(app.GravityPanel, 'numeric');
            app.FluidGravityzEditField.Limits = [0 Inf];
            app.FluidGravityzEditField.Value = 9.81;
            app.xLabel_26 = uilabel(app.GravityPanel);
            app.xLabel_26.Text = 'gz';
            app.FluidGravityCheckBox = uicheckbox(app.GravityPanel);
            app.FluidGravityCheckBox.ValueChangedFcn = createCallbackFcn(app, @FluidGravityCheckBoxValueChanged, true);
            app.FluidGravityCheckBox.Text = 'Enabled';
            app.FluidGravityCheckBox.Value = true;
            app.FluidGravityxUnitDropDown = uidropdown(app.GravityPanel);
            app.FluidGravityxUnitDropDown.Items = {'m/s²'};
            app.FluidGravityxUnitDropDown.Value = 'm/s²';
            app.FluidGravityyUnitDropDown = uidropdown(app.GravityPanel);
            app.FluidGravityyUnitDropDown.Items = {'m/s²'};
            app.FluidGravityyUnitDropDown.Value = 'm/s²';
            app.FluidGravityzUnitDropDown = uidropdown(app.GravityPanel);
            app.FluidGravityzUnitDropDown.Items = {'m/s²'};
            app.FluidGravityzUnitDropDown.Value = 'm/s²';
            app.TypePanel = uipanel(app.FluidTab);
            app.TypePanel.Title = 'Type';
            app.FluidTypeButtonGroup = uibuttongroup(app.TypePanel);
            app.FluidTypeWaterButton = uiradiobutton(app.FluidTypeButtonGroup);
            app.FluidTypeWaterButton.Text = 'Water';
            app.FluidTypeWaterButton.Value = true;
            app.FluidTypeBrineButton = uiradiobutton(app.FluidTypeButtonGroup);
            app.FluidTypeBrineButton.Enable = 'off';
            app.FluidTypeBrineButton.Text = 'Brine';
            app.FluidTypeCO2Button = uiradiobutton(app.FluidTypeButtonGroup);
            app.FluidTypeCO2Button.Enable = 'off';
            app.FluidTypeCO2Button.Text = 'CO2';
            app.ThermalPropertiesPanel = uipanel(app.FluidTab);
            app.ThermalPropertiesPanel.Title = 'Thermal Properties';
            app.FluidHeatUnitDropDown = uidropdown(app.ThermalPropertiesPanel);
            app.FluidHeatUnitDropDown.Items = {'J/kgK'};
            app.FluidHeatUnitDropDown.Value = 'J/kgK';
            app.FluidCondUnitDropDown = uidropdown(app.ThermalPropertiesPanel);
            app.FluidCondUnitDropDown.Items = {'W/mK'};
            app.FluidCondUnitDropDown.Value = 'W/mK';
            app.HeatcapacityEditFieldLabel = uilabel(app.ThermalPropertiesPanel);
            app.HeatcapacityEditFieldLabel.Text = 'Heat capacity';
            app.FluidHeatEditField = uieditfield(app.ThermalPropertiesPanel, 'numeric');
            app.FluidHeatEditField.Limits = [0 Inf];
            app.FluidHeatEditField.Value = 4182;
            app.ThermalconductivityEditFieldLabel = uilabel(app.ThermalPropertiesPanel);
            app.ThermalconductivityEditFieldLabel.Text = 'Thermal conductivity';
            app.FluidCondEditField = uieditfield(app.ThermalPropertiesPanel, 'numeric');
            app.FluidCondEditField.Limits = [0 Inf];
            app.FluidCondEditField.Value = 0.598;
            app.GeneralPropertiesPanel = uipanel(app.FluidTab);
            app.GeneralPropertiesPanel.Title = 'General Properties';
            app.FluidDensityUnitDropDown = uidropdown(app.GeneralPropertiesPanel);
            app.FluidDensityUnitDropDown.Items = {'kg/m³', 'g/cm³', 'ppg'};
            app.FluidDensityUnitDropDown.Value = 'kg/m³';
            app.FluidViscosityUnitDropDown = uidropdown(app.GeneralPropertiesPanel);
            app.FluidViscosityUnitDropDown.Items = {'cP', 'Pas'};
            app.FluidViscosityUnitDropDown.Value = 'cP';
            app.DensityEditFieldLabel = uilabel(app.GeneralPropertiesPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.FluidDensityEditField = uieditfield(app.GeneralPropertiesPanel, 'numeric');
            app.FluidDensityEditField.Limits = [0 Inf];
            app.FluidDensityEditField.Value = 1000;
            app.ViscosityEditFieldLabel = uilabel(app.GeneralPropertiesPanel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity';
            app.FluidViscosityEditField = uieditfield(app.GeneralPropertiesPanel, 'numeric');
            app.FluidViscosityEditField.Limits = [0 Inf];
            app.FluidViscosityEditField.Value = 1;
            app.FluidEOSCheckBox = uicheckbox(app.GeneralPropertiesPanel);
            app.FluidEOSCheckBox.ValueChangedFcn = createCallbackFcn(app, @FluidEOSCheckBoxValueChanged, true);
            app.FluidEOSCheckBox.Text = 'Use EOS (Spivey et al., 2004)';
            app.BCsTab = uitab(app.GlobalTabGroup);
            app.BCsTab.Title = 'BCs';
            app.BCxMinButtonGroup = uibuttongroup(app.BCsTab);
            app.BCxMinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCxMinButtonGroupSelectionChanged, true);
            app.BCxMinPressureButton = uiradiobutton(app.BCxMinButtonGroup);
            app.BCxMinPressureButton.Text = 'Pressure';
            app.BCxMinPressureButton.Value = true;
            app.BCxMinFluxButton = uiradiobutton(app.BCxMinButtonGroup);
            app.BCxMinFluxButton.Text = 'Flux';
            app.BCxMaxButtonGroup = uibuttongroup(app.BCsTab);
            app.BCxMaxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCxMaxButtonGroupSelectionChanged, true);
            app.BCxMaxFluxButton = uiradiobutton(app.BCxMaxButtonGroup);
            app.BCxMaxFluxButton.Text = 'Flux';
            app.BCxMaxPressureButton = uiradiobutton(app.BCxMaxButtonGroup);
            app.BCxMaxPressureButton.Text = 'Pressure';
            app.BCxMaxPressureButton.Value = true;
            app.BCyMinButtonGroup = uibuttongroup(app.BCsTab);
            app.BCyMinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCyMinButtonGroupSelectionChanged, true);
            app.BCyMinPressureButton = uiradiobutton(app.BCyMinButtonGroup);
            app.BCyMinPressureButton.Text = 'Pressure';
            app.BCyMinPressureButton.Value = true;
            app.BCyMinFluxButton = uiradiobutton(app.BCyMinButtonGroup);
            app.BCyMinFluxButton.Text = 'Flux';
            app.BCyMaxButtonGroup = uibuttongroup(app.BCsTab);
            app.BCyMaxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCyMaxButtonGroupSelectionChanged, true);
            app.BCyMaxPressureButton = uiradiobutton(app.BCyMaxButtonGroup);
            app.BCyMaxPressureButton.Text = 'Pressure';
            app.BCyMaxPressureButton.Value = true;
            app.BCyMaxFluxButton = uiradiobutton(app.BCyMaxButtonGroup);
            app.BCyMaxFluxButton.Text = 'Flux';
            app.BCzMinButtonGroup = uibuttongroup(app.BCsTab);
            app.BCzMinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCzMinButtonGroupSelectionChanged, true);
            app.BCzMinPressureButton = uiradiobutton(app.BCzMinButtonGroup);
            app.BCzMinPressureButton.Text = 'Pressure';
            app.BCzMinPressureButton.Value = true;
            app.BCzMinFluxButton = uiradiobutton(app.BCzMinButtonGroup);
            app.BCzMinFluxButton.Text = 'Flux';
            app.BCzMaxButtonGroup = uibuttongroup(app.BCsTab);
            app.BCzMaxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCzMaxButtonGroupSelectionChanged, true);
            app.BCzMaxPressureButton = uiradiobutton(app.BCzMaxButtonGroup);
            app.BCzMaxPressureButton.Text = 'Pressure';
            app.BCzMaxPressureButton.Value = true;
            app.BCzMaxFluxButton = uiradiobutton(app.BCzMaxButtonGroup);
            app.BCzMaxFluxButton.Text = 'Flux';
            app.BCzMaxFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMaxFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMaxFluxUnitDropDown.Items = {'m³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.BCzMaxFluxUnitDropDown.Value = 'm³/h';
            app.BCzMaxPressureEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMaxPressureEditField.Limits = [0 Inf];
            app.BCzMaxPressureUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMaxPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.BCzMaxPressureUnitDropDown.Value = 'bara';
            app.BCzMaxCheckBox = uicheckbox(app.BCsTab);
            app.BCzMaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCzMaxCheckBoxValueChanged, true);
            app.BCzMaxCheckBox.Text = 'z-Max';
            app.BCzMinCheckBox = uicheckbox(app.BCsTab);
            app.BCzMinCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCzMinCheckBoxValueChanged, true);
            app.BCzMinCheckBox.Text = 'z-Min';
            app.BCyMinCheckBox = uicheckbox(app.BCsTab);
            app.BCyMinCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCyMinCheckBoxValueChanged, true);
            app.BCyMinCheckBox.Text = 'y-Min';
            app.BCyMaxCheckBox = uicheckbox(app.BCsTab);
            app.BCyMaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCyMaxCheckBoxValueChanged, true);
            app.BCyMaxCheckBox.Text = 'y-Max';
            app.BCxMaxCheckBox = uicheckbox(app.BCsTab);
            app.BCxMaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCxMaxCheckBoxValueChanged, true);
            app.BCxMaxCheckBox.Text = 'x-Max';
            app.BCxMinCheckBox = uicheckbox(app.BCsTab);
            app.BCxMinCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCxMinCheckBoxValueChanged, true);
            app.BCxMinCheckBox.Text = 'x-Min';
            app.BCzMinPressureEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMinPressureEditField.Limits = [0 Inf];
            app.BCzMinPressureUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMinPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.BCzMinPressureUnitDropDown.Value = 'bara';
            app.BCzMinFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMinFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMinFluxUnitDropDown.Items = {'m³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.BCzMinFluxUnitDropDown.Value = 'm³/h';
            app.BCyMaxFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMaxFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMaxFluxUnitDropDown.Items = {'m³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.BCyMaxFluxUnitDropDown.Value = 'm³/h';
            app.BCyMaxPressureEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMaxPressureEditField.Limits = [0 Inf];
            app.BCyMaxPressureUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMaxPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.BCyMaxPressureUnitDropDown.Value = 'bara';
            app.BCyMinPressureEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMinPressureEditField.Limits = [0 Inf];
            app.BCyMinPressureUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMinPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.BCyMinPressureUnitDropDown.Value = 'bara';
            app.BCyMinFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMinFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMinFluxUnitDropDown.Items = {'m³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.BCyMinFluxUnitDropDown.Value = 'm³/h';
            app.BCxMaxPressureEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMaxPressureEditField.Limits = [0 Inf];
            app.BCxMaxPressureUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMaxPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.BCxMaxPressureUnitDropDown.Value = 'bara';
            app.BCxMaxFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMaxFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMaxFluxUnitDropDown.Items = {'m³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.BCxMaxFluxUnitDropDown.Value = 'm³/h';
            app.BCxMinPressureEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMinPressureEditField.Limits = [0 Inf];
            app.BCxMinPressureUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMinPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.BCxMinPressureUnitDropDown.Value = 'bara';
            app.BCxMinFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMinFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMinFluxUnitDropDown.Items = {'m³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.BCxMinFluxUnitDropDown.Value = 'm³/h';
            app.BCzMaxHeatFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMaxHeatFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMaxHeatFluxUnitDropDown.Items = {'W/m²'};
            app.BCzMaxHeatFluxUnitDropDown.Value = 'W/m²';
            app.BCzMaxTempEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMaxTempEditField.Limits = [-273.15 Inf];
            app.BCzMaxTempUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMaxTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.BCzMaxTempUnitDropDown.Value = '°C';
            app.BCzMinTempEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMinTempEditField.Limits = [-273.15 Inf];
            app.BCzMinTempUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMinTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.BCzMinTempUnitDropDown.Value = '°C';
            app.BCzMinHeatFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCzMinHeatFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCzMinHeatFluxUnitDropDown.Items = {'W/m²'};
            app.BCzMinHeatFluxUnitDropDown.Value = 'W/m²';
            app.BCyMaxHeatFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMaxHeatFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMaxHeatFluxUnitDropDown.Items = {'W/m²'};
            app.BCyMaxHeatFluxUnitDropDown.Value = 'W/m²';
            app.BCyMaxTempEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMaxTempEditField.Limits = [-273.15 Inf];
            app.BCyMaxTempUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMaxTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.BCyMaxTempUnitDropDown.Value = '°C';
            app.BCyMinTempEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMinTempEditField.Limits = [-273.15 Inf];
            app.BCyMinTempUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMinTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.BCyMinTempUnitDropDown.Value = '°C';
            app.BCyMinHeatFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCyMinHeatFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCyMinHeatFluxUnitDropDown.Items = {'W/m²'};
            app.BCyMinHeatFluxUnitDropDown.Value = 'W/m²';
            app.BCxMaxTempEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMaxTempEditField.Limits = [-273.15 Inf];
            app.BCxMaxTempUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMaxTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.BCxMaxTempUnitDropDown.Value = '°C';
            app.BCxMaxHeatFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMaxHeatFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMaxHeatFluxUnitDropDown.Items = {'W/m²'};
            app.BCxMaxHeatFluxUnitDropDown.Value = 'W/m²';
            app.BCxMinTempEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMinTempEditField.Limits = [-273.15 Inf];
            app.BCxMinTempUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMinTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.BCxMinTempUnitDropDown.Value = '°C';
            app.BCxMinHeatFluxEditField = uieditfield(app.BCsTab, 'numeric');
            app.BCxMinHeatFluxUnitDropDown = uidropdown(app.BCsTab);
            app.BCxMinHeatFluxUnitDropDown.Items = {'W/m²'};
            app.BCxMinHeatFluxUnitDropDown.Value = 'W/m²';
            app.BCVisualizationPanel = uipanel(app.BCsTab);
            app.BCVisualizationPanel.Title = 'Visualization';
            app.BCVisualizationButtonGroup = uibuttongroup(app.BCVisualizationPanel);
            app.BCVisualizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCVisualizationButtonGroupSelectionChanged, true);
            app.BCVisualizationHydraulicButton = uiradiobutton(app.BCVisualizationButtonGroup);
            app.BCVisualizationHydraulicButton.Text = 'Hydraulic';
            app.BCVisualizationHydraulicButton.Value = true;
            app.BCVisualizationThermButton = uiradiobutton(app.BCVisualizationButtonGroup);
            app.BCVisualizationThermButton.Text = 'Thermal';
            app.BCxMinTempLabel = uilabel(app.BCsTab);
            app.BCxMinTempLabel.Text = 'Temperature';
            app.BCxMinHeatFluxLabel = uilabel(app.BCsTab);
            app.BCxMinHeatFluxLabel.Text = 'Heat Flux';
            app.BCxMaxTempLabel = uilabel(app.BCsTab);
            app.BCxMaxTempLabel.Text = 'Temperature';
            app.BCxMaxHeatFluxLabel = uilabel(app.BCsTab);
            app.BCxMaxHeatFluxLabel.Text = 'Heat Flux';
            app.BCyMinTempLabel = uilabel(app.BCsTab);
            app.BCyMinTempLabel.Text = 'Temperature';
            app.BCyMinHeatFluxLabel = uilabel(app.BCsTab);
            app.BCyMinHeatFluxLabel.Text = 'Heat Flux';
            app.BCyMaxTempLabel = uilabel(app.BCsTab);
            app.BCyMaxTempLabel.Text = 'Temperature';
            app.BCyMaxHeatFluxLabel = uilabel(app.BCsTab);
            app.BCyMaxHeatFluxLabel.Text = 'Heat Flux';
            app.BCzMinTempLabel = uilabel(app.BCsTab);
            app.BCzMinTempLabel.Text = 'Temperature';
            app.BCzMinHeatFluxLabel = uilabel(app.BCsTab);
            app.BCzMinHeatFluxLabel.Text = 'Heat Flux';
            app.BCzMaxTempLabel = uilabel(app.BCsTab);
            app.BCzMaxTempLabel.Text = 'Temperature';
            app.BCzMaxHeatFluxLabel = uilabel(app.BCsTab);
            app.BCzMaxHeatFluxLabel.Text = 'Heat Flux';
            app.BCThermalBCPanel = uipanel(app.BCsTab);
            app.BCThermalBCPanel.Title = 'Thermal BC';
            app.BCThermalButtonGroup = uibuttongroup(app.BCThermalBCPanel);
            app.BCThermalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BCThermalButtonGroupSelectionChanged, true);
            app.BCThermalTempButton = uiradiobutton(app.BCThermalButtonGroup);
            app.BCThermalTempButton.Text = 'Temperature';
            app.BCThermalTempButton.Value = true;
            app.BCThermalHeatFluxButton = uiradiobutton(app.BCThermalButtonGroup);
            app.BCThermalHeatFluxButton.Text = 'Heat Flux';
            app.ReservoirTab = uitab(app.GlobalTabGroup);
            app.ReservoirTab.Title = 'Reservoir';
            app.InitialConditionsPanel = uipanel(app.ReservoirTab);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.ReservoirInitPressureUnitDropDown = uidropdown(app.InitialConditionsPanel);
            app.ReservoirInitPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.ReservoirInitPressureUnitDropDown.Value = 'bara';
            app.ReservoirInitTempUnitDropDown = uidropdown(app.InitialConditionsPanel);
            app.ReservoirInitTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.ReservoirInitTempUnitDropDown.Value = '°C';
            app.ReservoirPressureEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.ReservoirPressureEditFieldLabel.Text = 'Reservoir Pressure';
            app.ReservoirInitPressureEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.ReservoirTemperatureEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.ReservoirTemperatureEditFieldLabel.Text = 'Reservoir Temperature';
            app.ReservoirInitTempEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.InitializeWithEquilibriumCheckBox = uicheckbox(app.InitialConditionsPanel);
            app.InitializeWithEquilibriumCheckBox.ValueChangedFcn = createCallbackFcn(app, @InitializeWithEquilibriumCheckBoxValueChanged, true);
            app.InitializeWithEquilibriumCheckBox.Text = 'Initialize with undisturbed equilibrium';
            app.InitializeWithEquilibriumCheckBox.Value = true;
            app.EqualizationtimeLabel = uilabel(app.InitialConditionsPanel);
            app.EqualizationtimeLabel.Text = 'Equalization time';
            app.EqualizationTimeEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.EqualizationTimeUnitDropDown = uidropdown(app.InitialConditionsPanel);
            app.EqualizationTimeUnitDropDown.Items = {'s', 'min', 'h', 'd', 'a'};
            app.EqualizationTimeUnitDropDown.Value = 'a';
            app.WellsTab = uitab(app.GlobalTabGroup);
            app.WellsTab.Title = 'Wells';
            app.WellsInjectorPanel = uipanel(app.WellsTab);
            app.WellsInjectorPanel.Title = 'Injector';
            app.WellNameEditFieldLabel = uilabel(app.WellsInjectorPanel);
            app.WellNameEditFieldLabel.Text = 'Well Name';
            app.InjWellNameEditField = uieditfield(app.WellsInjectorPanel, 'text');
            app.InjWellNameEditField.ValueChangedFcn = createCallbackFcn(app, @InjWellNameEditFieldValueChanged, true);
            app.InjWellNameEditField.Value = 'Inj';
            app.RadiusEditFieldLabel = uilabel(app.WellsInjectorPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.InjWellRadiusEditField = uieditfield(app.WellsInjectorPanel, 'numeric');
            app.InjWellRadiusEditField.Value = 70;
            app.InjWellRadiusUnitDropDown = uidropdown(app.WellsInjectorPanel);
            app.InjWellRadiusUnitDropDown.Items = {'mm', 'inch'};
            app.InjWellRadiusUnitDropDown.Value = 'mm';
            app.InjWellControlButtonGroup = uibuttongroup(app.WellsInjectorPanel);
            app.InjWellControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InjWellControlButtonGroupSelectionChanged, true);
            app.InjWellConstantRateButton = uiradiobutton(app.InjWellControlButtonGroup);
            app.InjWellConstantRateButton.Text = 'Constant Rate';
            app.InjWellConstantRateButton.Value = true;
            app.InjWellConstantBHPButton = uiradiobutton(app.InjWellControlButtonGroup);
            app.InjWellConstantBHPButton.Text = 'Constant BHP';
            app.InjWellRateEditField = uieditfield(app.WellsInjectorPanel, 'numeric');
            app.InjWellRateEditField.Limits = [0 Inf];
            app.InjWellRateEditField.Value = 1;
            app.InjWellBHPEditField = uieditfield(app.WellsInjectorPanel, 'numeric');
            app.InjWellBHPEditField.Value = 100;
            app.InjWellRateUnitDropDown = uidropdown(app.WellsInjectorPanel);
            app.InjWellRateUnitDropDown.Items = {'m³/s', 'm³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.InjWellRateUnitDropDown.Value = 'm³/h';
            app.InjWellBHPUnitDropDown = uidropdown(app.WellsInjectorPanel);
            app.InjWellBHPUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.InjWellBHPUnitDropDown.Value = 'bara';
            app.BottomHolePointLabel = uilabel(app.WellsInjectorPanel);
            app.BottomHolePointLabel.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.BottomHolePointLabel.Text = 'Bottom Hole Point';
            app.xLabel_18 = uilabel(app.WellsInjectorPanel);
            app.xLabel_18.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.xLabel_18.Text = 'x';
            app.InjWellPointxEditField = uieditfield(app.WellsInjectorPanel, 'numeric');
            app.InjWellPointxEditField.Limits = [0 Inf];
            app.InjWellPointxEditField.ValueChangedFcn = createCallbackFcn(app, @InjWellPointxEditFieldValueChanged, true);
            app.InjWellPointxEditField.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.InjWellPointxEditField.Value = 1;
            app.xLabel_19 = uilabel(app.WellsInjectorPanel);
            app.xLabel_19.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.xLabel_19.Text = 'z';
            app.InjWellPointzEditField = uieditfield(app.WellsInjectorPanel, 'numeric');
            app.InjWellPointzEditField.Limits = [0 Inf];
            app.InjWellPointzEditField.ValueChangedFcn = createCallbackFcn(app, @InjWellPointzEditFieldValueChanged, true);
            app.InjWellPointzEditField.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.InjWellPointzEditField.Value = 10;
            app.xLabel_20 = uilabel(app.WellsInjectorPanel);
            app.xLabel_20.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.xLabel_20.Text = 'y';
            app.InjWellPointyEditField = uieditfield(app.WellsInjectorPanel, 'numeric');
            app.InjWellPointyEditField.Limits = [0 Inf];
            app.InjWellPointyEditField.ValueChangedFcn = createCallbackFcn(app, @InjWellPointyEditFieldValueChanged, true);
            app.InjWellPointyEditField.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.InjWellPointyEditField.Value = 1;
            app.InjWellTempUnitDropDown = uidropdown(app.WellsInjectorPanel);
            app.InjWellTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.InjWellTempUnitDropDown.Value = '°C';
            app.FluidTemperatureLabel = uilabel(app.WellsInjectorPanel);
            app.FluidTemperatureLabel.Text = 'Fluid Temperature';
            app.InjWellTempEditField = uieditfield(app.WellsInjectorPanel, 'numeric');
            app.ControlTypeLabel = uilabel(app.WellsInjectorPanel);
            app.ControlTypeLabel.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.ControlTypeLabel.Text = 'Control Type';
            app.WellsProducerPanel = uipanel(app.WellsTab);
            app.WellsProducerPanel.Title = 'Producer';
            app.ProdWellRadiusUnitDropDown = uidropdown(app.WellsProducerPanel);
            app.ProdWellRadiusUnitDropDown.Items = {'mm', 'inch'};
            app.ProdWellRadiusUnitDropDown.Value = 'mm';
            app.ProdWellControlButtonGroup = uibuttongroup(app.WellsProducerPanel);
            app.ProdWellControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProdWellControlButtonGroupSelectionChanged, true);
            app.ProdWellConstantRateButton = uiradiobutton(app.ProdWellControlButtonGroup);
            app.ProdWellConstantRateButton.Text = 'Constant Rate';
            app.ProdWellConstantBHPButton = uiradiobutton(app.ProdWellControlButtonGroup);
            app.ProdWellConstantBHPButton.Text = 'Constant BHP';
            app.ProdWellConstantBHPButton.Value = true;
            app.ProdWellRateUnitDropDown = uidropdown(app.WellsProducerPanel);
            app.ProdWellRateUnitDropDown.Items = {'m³/s', 'm³/h', 'm³/day', 'STB/h', 'STB/day', 'kg/s', 'kg/day'};
            app.ProdWellRateUnitDropDown.Value = 'm³/h';
            app.ProdWellBHPUnitDropDown = uidropdown(app.WellsProducerPanel);
            app.ProdWellBHPUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.ProdWellBHPUnitDropDown.Value = 'bara';
            app.WellNameEditField_2Label = uilabel(app.WellsProducerPanel);
            app.WellNameEditField_2Label.Text = 'Well Name';
            app.ProdWellNameEditField = uieditfield(app.WellsProducerPanel, 'text');
            app.ProdWellNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProdWellNameEditFieldValueChanged, true);
            app.ProdWellNameEditField.Value = 'Prod';
            app.RadiusEditField_2Label = uilabel(app.WellsProducerPanel);
            app.RadiusEditField_2Label.Text = 'Radius';
            app.ProdWellRadiusEditField = uieditfield(app.WellsProducerPanel, 'numeric');
            app.ProdWellRadiusEditField.Value = 70;
            app.ProdWellRateEditField = uieditfield(app.WellsProducerPanel, 'numeric');
            app.ProdWellRateEditField.Limits = [0 Inf];
            app.ProdWellRateEditField.Value = 1;
            app.ProdWellBHPEditField = uieditfield(app.WellsProducerPanel, 'numeric');
            app.ProdWellBHPEditField.Value = 100;
            app.up = uilabel(app.WellsProducerPanel);
            app.up.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.up.Text = 'Bottom Hole Point';
            app.xLabel_21 = uilabel(app.WellsProducerPanel);
            app.xLabel_21.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.xLabel_21.Text = 'x';
            app.ProdWellPointxEditField = uieditfield(app.WellsProducerPanel, 'numeric');
            app.ProdWellPointxEditField.Limits = [0 Inf];
            app.ProdWellPointxEditField.ValueChangedFcn = createCallbackFcn(app, @ProdWellPointxEditFieldValueChanged, true);
            app.ProdWellPointxEditField.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.ProdWellPointxEditField.Value = 10;
            app.xLabel_22 = uilabel(app.WellsProducerPanel);
            app.xLabel_22.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.xLabel_22.Text = 'z';
            app.ProdWellPointzEditField = uieditfield(app.WellsProducerPanel, 'numeric');
            app.ProdWellPointzEditField.Limits = [0 Inf];
            app.ProdWellPointzEditField.ValueChangedFcn = createCallbackFcn(app, @ProdWellPointzEditFieldValueChanged, true);
            app.ProdWellPointzEditField.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.ProdWellPointzEditField.Value = 10;
            app.xLabel_23 = uilabel(app.WellsProducerPanel);
            app.xLabel_23.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.xLabel_23.Text = 'y';
            app.ProdWellPointyEditField = uieditfield(app.WellsProducerPanel, 'numeric');
            app.ProdWellPointyEditField.Limits = [0 Inf];
            app.ProdWellPointyEditField.ValueChangedFcn = createCallbackFcn(app, @ProdWellPointyEditFieldValueChanged, true);
            app.ProdWellPointyEditField.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.ProdWellPointyEditField.Value = 10;
            app.ControlTypeLabel_2 = uilabel(app.WellsProducerPanel);
            app.ControlTypeLabel_2.Tooltip = {'Cartesian cell coordinates of well setting point.'};
            app.ControlTypeLabel_2.Text = 'Control Type';
            app.SimulationTab = uitab(app.GlobalTabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.PlausibilityLimitsPanel = uipanel(app.SimulationTab);
            app.PlausibilityLimitsPanel.Title = 'Plausibility Limits';
            app.MinReservoirPressureUnitDropDown = uidropdown(app.PlausibilityLimitsPanel);
            app.MinReservoirPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.MinReservoirPressureUnitDropDown.Value = 'bara';
            app.MinReservoirTempUnitDropDown = uidropdown(app.PlausibilityLimitsPanel);
            app.MinReservoirTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.MinReservoirTempUnitDropDown.Value = 'K';
            app.MinReservoirPressureEditFieldLabel = uilabel(app.PlausibilityLimitsPanel);
            app.MinReservoirPressureEditFieldLabel.Text = 'Min. Reservoir Pressure';
            app.MinReservoirPressureEditField = uieditfield(app.PlausibilityLimitsPanel, 'numeric');
            app.MinReservoirPressureEditField.Limits = [0 Inf];
            app.MinReservoirTemperatureEditFieldLabel = uilabel(app.PlausibilityLimitsPanel);
            app.MinReservoirTemperatureEditFieldLabel.Text = 'Min. Reservoir Temperature';
            app.MinReservoirTempEditField = uieditfield(app.PlausibilityLimitsPanel, 'numeric');
            app.MaxReservoirPressureUnitDropDown = uidropdown(app.PlausibilityLimitsPanel);
            app.MaxReservoirPressureUnitDropDown.Items = {'bara', 'Pa', 'MPa', 'psi'};
            app.MaxReservoirPressureUnitDropDown.Value = 'bara';
            app.MaxReservoirPressureEditField_2Label = uilabel(app.PlausibilityLimitsPanel);
            app.MaxReservoirPressureEditField_2Label.Text = 'Max. Reservoir Pressure';
            app.MaxReservoirPressureEditField = uieditfield(app.PlausibilityLimitsPanel, 'numeric');
            app.MaxReservoirPressureEditField.Value = Inf;
            app.MaxReservoirTempUnitDropDown = uidropdown(app.PlausibilityLimitsPanel);
            app.MaxReservoirTempUnitDropDown.Items = {'K', '°C', '°F'};
            app.MaxReservoirTempUnitDropDown.Value = '°C';
            app.MaxReservoirTemperatureEditFieldLabel = uilabel(app.PlausibilityLimitsPanel);
            app.MaxReservoirTemperatureEditFieldLabel.Text = 'Max. Reservoir Temperature';
            app.MaxReservoirTempEditField = uieditfield(app.PlausibilityLimitsPanel, 'numeric');
            app.MaxReservoirTempEditField.Value = Inf;
            app.TimestepsPanel = uipanel(app.SimulationTab);
            app.TimestepsPanel.Title = 'Timesteps';
            app.TimestepTypeButtonGroup = uibuttongroup(app.TimestepsPanel);
            app.FixedTimestepsButton = uiradiobutton(app.TimestepTypeButtonGroup);
            app.FixedTimestepsButton.Text = 'Fixed Timesteps';
            app.FixedTimestepsButton.Value = true;
            app.GeometricRampupButton = uiradiobutton(app.TimestepTypeButtonGroup);
            app.GeometricRampupButton.Text = 'Geometric Rampup';
            app.RampupStepsLabel_2 = uilabel(app.TimestepsPanel);
            app.RampupStepsLabel_2.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.TimestepsPanel, 'numeric');
            app.EndTimeEditField.Limits = [0 Inf];
            app.RampupStepsLabel_3 = uilabel(app.TimestepsPanel);
            app.RampupStepsLabel_3.Text = 'Default Timestep';
            app.TimestepEditField = uieditfield(app.TimestepsPanel, 'numeric');
            app.TimestepEditField.Limits = [0 Inf];
            app.EndTimeUnitDropDown = uidropdown(app.TimestepsPanel);
            app.EndTimeUnitDropDown.Items = {'s', 'min', 'h', 'd', 'a'};
            app.EndTimeUnitDropDown.Value = 'd';
            app.TimestepUnitDropDown = uidropdown(app.TimestepsPanel);
            app.TimestepUnitDropDown.Items = {'s', 'min', 'h', 'd', 'a'};
            app.TimestepUnitDropDown.Value = 'd';
            app.RampupStepsLabel = uilabel(app.SimulationTab);
            app.RampupStepsLabel.Text = 'Rampup Steps';
            app.RampupStepsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.RampupStepsEditField.Limits = [1 Inf];
            app.RampupStepsEditField.Value = 1;
            app.Panel = uipanel(app.GraphicalTaskBuilderUIFigure);
            app.SaveandExitButton = uibutton(app.Panel, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.QuitButton = uibutton(app.Panel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.InfoLabel = uilabel(app.Panel);
            app.InfoLabel.Text = {'Version X.X'; 'Copyright (C) XXXX Author'};
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.Text = 'Reset';
            app.Panel_2 = uipanel(app.GraphicalTaskBuilderUIFigure);
            app.ModelPreviewAxes = uiaxes(app.Panel_2);
            title(app.ModelPreviewAxes, 'Model Preview')
            xlabel(app.ModelPreviewAxes, 'X')
            ylabel(app.ModelPreviewAxes, 'Y')
            zlabel(app.ModelPreviewAxes, 'Z')
            app.PreviewCheckBox = uicheckbox(app.Panel_2);
            app.PreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewCheckBoxValueChanged, true);
            app.PreviewCheckBox.Text = 'Live Preview';
            app.PreviewCheckBox.Value = true;
            app.ModelPanel = uipanel(app.Panel_2);
            app.ModelPanel.Title = 'Model';
            app.TotalcellsLabel = uilabel(app.ModelPanel);
            app.TotalcellsLabel.Text = 'Total cells';
            app.TotelCellsEditField = uieditfield(app.ModelPanel, 'numeric');
            app.TotelCellsEditField.Editable = 'off';
            app.GridsizewarningLabel_4 = uilabel(app.ModelPanel);
            app.GridsizewarningLabel_4.Text = 'Layer enabled';
            app.LayerEnabledLamp = uilamp(app.ModelPanel);
            app.GridsizewarningLabel_5 = uilabel(app.ModelPanel);
            app.GridsizewarningLabel_5.Text = 'Default BCs';
            app.BCsEnabledLamp = uilamp(app.ModelPanel);
            app.GridsizewarningLabel_6 = uilabel(app.ModelPanel);
            app.GridsizewarningLabel_6.Text = 'Gravity';
            app.GravityEnabledLamp = uilamp(app.ModelPanel);
            app.GridsizewarningLabel_7 = uilabel(app.ModelPanel);
            app.GridsizewarningLabel_7.Text = 'EOS';
            app.EOSEnabledLamp = uilamp(app.ModelPanel);
            app.GridsizewarningLabel_8 = uilabel(app.ModelPanel);
            app.GridsizewarningLabel_8.Text = 'Initial Equilibrium';
            app.InitialEquilibriumEnabledLamp = uilamp(app.ModelPanel);
            app.WarningsPanel = uipanel(app.Panel_2);
            app.WarningsPanel.Title = 'Warnings';
            app.GridsizewarningLabel = uilabel(app.WarningsPanel);
            app.GridsizewarningLabel.Text = 'Grid size warning';
            app.GridSizeWarningLamp = uilamp(app.WarningsPanel);
            app.GridSizeWarningLamp.Tooltip = {'Depending on the system building previews and simulating models takes a long time for a large amount of grid cells.'};
            app.GridsizewarningLabel_2 = uilabel(app.WarningsPanel);
            app.GridsizewarningLabel_2.Text = 'Overwriting Task warning';
            app.OverwritingTaskWarningLamp = uilamp(app.WarningsPanel);
            % Show the figure after all components are created
            app.GraphicalTaskBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaskBuilder(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GraphicalTaskBuilderUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.GraphicalTaskBuilderUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphicalTaskBuilderUIFigure)
        end
    end
end